classdef MuhammedAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NonlinearOpticalZScanDataAnalysisUIFigure  matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        TabGroup                      matlab.ui.container.TabGroup
        Input1                        matlab.ui.container.Tab
        GridLayout_1                  matlab.ui.container.GridLayout
        FitCheckBox_1                 matlab.ui.control.CheckBox
        FitDataSetLabel_1             matlab.ui.control.Label
        ExcellExportButton_1          matlab.ui.control.Button
        GetthescatteringandfittingLabel_1  matlab.ui.control.Label
        NormalisedCheckBox_1          matlab.ui.control.CheckBox
        NormalisedLabel_1             matlab.ui.control.Label
        XvalueforMLabel               matlab.ui.control.Label
        DataSetterField3_1            matlab.ui.control.NumericEditField
        EditDataSetButton_1           matlab.ui.control.Button
        DatabetweenLabel_1            matlab.ui.control.Label
        zLabel_1                      matlab.ui.control.Label
        DataSetterField2_1            matlab.ui.control.NumericEditField
        DataSetterField1_1            matlab.ui.control.NumericEditField
        ExcellImportButton_1          matlab.ui.control.Button
        OriginalDataSetLabel_1        matlab.ui.control.Label
        dataTable_1                   matlab.ui.control.Table
        dataTableFit_1                matlab.ui.control.Table
        dataTableEdited_1             matlab.ui.control.Table
        EditedDataSetLabel_1          matlab.ui.control.Label
        Panel_1                       matlab.ui.container.Panel
        GridLayout3_1                 matlab.ui.container.GridLayout
        Units_ListBox_1               matlab.ui.control.ListBox
        UnitsoftheparametersListBoxLabel_2  matlab.ui.control.Label
        TabGroup2_1                   matlab.ui.container.TabGroup
        OpenApertureZScanTab_1        matlab.ui.container.Tab
        GridLayout5_1                 matlab.ui.container.GridLayout
        CheckBoxOA1_1                 matlab.ui.control.CheckBox
        CheckBoxOA2_1                 matlab.ui.control.CheckBox
        CheckBoxOA3_1                 matlab.ui.control.CheckBox
        CheckBoxOA4_1                 matlab.ui.control.CheckBox
        CheckBoxOA5_1                 matlab.ui.control.CheckBox
        CheckBoxOA6_1                 matlab.ui.control.CheckBox
        CheckBoxOA7_1                 matlab.ui.control.CheckBox
        CheckBoxOA8_1                 matlab.ui.control.CheckBox
        EditField_TTM_OA_1            matlab.ui.control.NumericEditField
        EditField_Leff_OA_1           matlab.ui.control.NumericEditField
        Label_5                       matlab.ui.control.Label
        EditField_ITILB_OA_1          matlab.ui.control.NumericEditField
        iLabel                        matlab.ui.control.Label
        EditField_RL_OA_1             matlab.ui.control.NumericEditField
        ZLabel                        matlab.ui.control.Label
        EditField_NAC_OA_1            matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        EditField_W0_OA_1             matlab.ui.control.NumericEditField
        Label_4                       matlab.ui.control.Label
        EditField_alpha_OA_1          matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        EditField_LAMBDA_OA_1         matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        LLabel                        matlab.ui.control.Label
        ClosedApertureZScanTab_1      matlab.ui.container.Tab
        GridLayout6_1                 matlab.ui.container.GridLayout
        CheckBoxCA6_6                 matlab.ui.control.CheckBox
        EditField_D_CA_1              matlab.ui.control.NumericEditField
        Label_86                      matlab.ui.control.Label
        ZLabel_16                     matlab.ui.control.Label
        Label_77                      matlab.ui.control.Label
        CheckBoxCA2_1                 matlab.ui.control.CheckBox
        CheckBoxCA4_1                 matlab.ui.control.CheckBox
        CheckBoxCA6_1                 matlab.ui.control.CheckBox
        EditField_LAMBDA_CA_1         matlab.ui.control.NumericEditField
        EditField_W0_CA_1             matlab.ui.control.NumericEditField
        EditField_RL_CA_1             matlab.ui.control.NumericEditField
        Label_76                      matlab.ui.control.Label
        TwoPhotonAbsorptionTab_1      matlab.ui.container.Tab
        GridLayout7_1                 matlab.ui.container.GridLayout
        ZLabel_2                      matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        iLabel_2                      matlab.ui.control.Label
        EditField_TTM_TPA_1           matlab.ui.control.NumericEditField
        LLabel_2                      matlab.ui.control.Label
        EditField_LAMBDA_TPA_1        matlab.ui.control.NumericEditField
        Label_8                       matlab.ui.control.Label
        EditField_alpha_TPA_1         matlab.ui.control.NumericEditField
        EditField_W0_TPA_1            matlab.ui.control.NumericEditField
        EditField_Leff_TPA_1          matlab.ui.control.NumericEditField
        EditField_RL_TPA_1            matlab.ui.control.NumericEditField
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        EditField_ITILB_TPA_1         matlab.ui.control.NumericEditField
        EditField_NAC_TPA_1           matlab.ui.control.NumericEditField
        CheckBoxTPA1_1                matlab.ui.control.CheckBox
        CheckBoxTPA2_1                matlab.ui.control.CheckBox
        CheckBoxTPA3_1                matlab.ui.control.CheckBox
        CheckBoxTPA4_1                matlab.ui.control.CheckBox
        CheckBoxTPA5_1                matlab.ui.control.CheckBox
        CheckBoxTPA6_1                matlab.ui.control.CheckBox
        CheckBoxTPA7_1                matlab.ui.control.CheckBox
        CheckBoxTPA8_1                matlab.ui.control.CheckBox
        ThreePhotonAbsorptionTab_1    matlab.ui.container.Tab
        GridLayout8_1                 matlab.ui.container.GridLayout
        EditField_TTM_ThPA_1          matlab.ui.control.NumericEditField
        LLabel_3                      matlab.ui.control.Label
        EditField_LAMBDA_ThPA_1       matlab.ui.control.NumericEditField
        EditField_alpha_ThPA_1        matlab.ui.control.NumericEditField
        EditField_W0_ThPA_1           matlab.ui.control.NumericEditField
        EditField_Leff_ThPA_1         matlab.ui.control.NumericEditField
        EditField_RL_ThPA_1           matlab.ui.control.NumericEditField
        EditField_ITILB_ThPA_1        matlab.ui.control.NumericEditField
        EditField_NAC_ThPA_1          matlab.ui.control.NumericEditField
        Label_15                      matlab.ui.control.Label
        iLabel_3                      matlab.ui.control.Label
        ZLabel_3                      matlab.ui.control.Label
        Label_14                      matlab.ui.control.Label
        Label_13                      matlab.ui.control.Label
        Label_12                      matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        CheckBoxThPA1_1               matlab.ui.control.CheckBox
        CheckBoxThPA2_1               matlab.ui.control.CheckBox
        CheckBoxThPA3_1               matlab.ui.control.CheckBox
        CheckBoxThPA4_1               matlab.ui.control.CheckBox
        CheckBoxThPA5_1               matlab.ui.control.CheckBox
        CheckBoxThPA6_1               matlab.ui.control.CheckBox
        CheckBoxThPA7_1               matlab.ui.control.CheckBox
        CheckBoxThPA8_1               matlab.ui.control.CheckBox
        DevelopedbyMuhammedBektaolu2024Button_1  matlab.ui.control.Button
        ParametersForFittingLabel_1   matlab.ui.control.Label
        FittingmethodListBoxLabel_1   matlab.ui.control.Label
        fittingMethods_1              matlab.ui.control.ListBox
        Panel_2                       matlab.ui.container.Panel
        GridLayout4_1                 matlab.ui.container.GridLayout
        RsquareValueEditField_1       matlab.ui.control.NumericEditField
        RsquareValueEditFieldLabel    matlab.ui.control.Label
        graphEdited_1                 matlab.ui.control.UIAxes
        graphOriginal_1               matlab.ui.control.UIAxes
        Input2                        matlab.ui.container.Tab
        GridLayout_2                  matlab.ui.container.GridLayout
        FitCheckBox_2                 matlab.ui.control.CheckBox
        FitDataSetLabel_2             matlab.ui.control.Label
        ExcellExportButton_2          matlab.ui.control.Button
        GetthescatteringandfittingLabel_2  matlab.ui.control.Label
        NormalisedCheckBox_2          matlab.ui.control.CheckBox
        NormalisedLabel_2             matlab.ui.control.Label
        XvalueforMLabel_2             matlab.ui.control.Label
        DataSetterField3_2            matlab.ui.control.NumericEditField
        EditDataSetButton_2           matlab.ui.control.Button
        DatabetweenLabel_2            matlab.ui.control.Label
        zLabel_2                      matlab.ui.control.Label
        DataSetterField2_2            matlab.ui.control.NumericEditField
        DataSetterField1_2            matlab.ui.control.NumericEditField
        ExcellImportButton_2          matlab.ui.control.Button
        OriginalDataSetLabel_2        matlab.ui.control.Label
        dataTable_2                   matlab.ui.control.Table
        dataTableFit_2                matlab.ui.control.Table
        dataTableEdited_2             matlab.ui.control.Table
        EditedDataSetLabel_2          matlab.ui.control.Label
        Panel_4                       matlab.ui.container.Panel
        GridLayout3_2                 matlab.ui.container.GridLayout
        Units_ListBox_2               matlab.ui.control.ListBox
        UnitsoftheparametersListBoxLabel  matlab.ui.control.Label
        TabGroup2_2                   matlab.ui.container.TabGroup
        OpenApertureZScanTab_2        matlab.ui.container.Tab
        GridLayout5_2                 matlab.ui.container.GridLayout
        CheckBoxOA1_2                 matlab.ui.control.CheckBox
        CheckBoxOA2_2                 matlab.ui.control.CheckBox
        CheckBoxOA3_2                 matlab.ui.control.CheckBox
        CheckBoxOA4_2                 matlab.ui.control.CheckBox
        CheckBoxOA5_2                 matlab.ui.control.CheckBox
        CheckBoxOA6_2                 matlab.ui.control.CheckBox
        CheckBoxOA7_2                 matlab.ui.control.CheckBox
        CheckBoxOA8_2                 matlab.ui.control.CheckBox
        EditField_Leff_OA_2           matlab.ui.control.NumericEditField
        Label_20                      matlab.ui.control.Label
        EditField_ITILB_OA_2          matlab.ui.control.NumericEditField
        iLabel_4                      matlab.ui.control.Label
        EditField_RL_OA_2             matlab.ui.control.NumericEditField
        ZLabel_4                      matlab.ui.control.Label
        EditField_NAC_OA_2            matlab.ui.control.NumericEditField
        Label_19                      matlab.ui.control.Label
        EditField_W0_OA_2             matlab.ui.control.NumericEditField
        Label_18                      matlab.ui.control.Label
        EditField_alpha_OA_2          matlab.ui.control.NumericEditField
        Label_17                      matlab.ui.control.Label
        EditField_LAMBDA_OA_2         matlab.ui.control.NumericEditField
        Label_16                      matlab.ui.control.Label
        EditField_TTM_OA_2            matlab.ui.control.NumericEditField
        LLabel_4                      matlab.ui.control.Label
        ClosedApertureZScanTab_2      matlab.ui.container.Tab
        GridLayout6_2                 matlab.ui.container.GridLayout
        CheckBoxCA2_2                 matlab.ui.control.CheckBox
        CheckBoxCA4_2                 matlab.ui.control.CheckBox
        CheckBoxCA6_2                 matlab.ui.control.CheckBox
        EditField_LAMBDA_CA_2         matlab.ui.control.NumericEditField
        Label_78                      matlab.ui.control.Label
        ZLabel_17                     matlab.ui.control.Label
        EditField_W0_CA_2             matlab.ui.control.NumericEditField
        EditField_RL_CA_2             matlab.ui.control.NumericEditField
        Label_79                      matlab.ui.control.Label
        TwoPhotonAbsorptionTab_2      matlab.ui.container.Tab
        GridLayout7_2                 matlab.ui.container.GridLayout
        EditField_TTM_TPA_2           matlab.ui.control.NumericEditField
        LLabel_5                      matlab.ui.control.Label
        Label_25                      matlab.ui.control.Label
        EditField_LAMBDA_TPA_2        matlab.ui.control.NumericEditField
        Label_24                      matlab.ui.control.Label
        EditField_W0_TPA_2            matlab.ui.control.NumericEditField
        ZLabel_5                      matlab.ui.control.Label
        EditField_RL_TPA_2            matlab.ui.control.NumericEditField
        EditField_alpha_TPA_2         matlab.ui.control.NumericEditField
        Label_23                      matlab.ui.control.Label
        EditField_Leff_TPA_2          matlab.ui.control.NumericEditField
        Label_22                      matlab.ui.control.Label
        CheckBoxTPA1_2                matlab.ui.control.CheckBox
        CheckBoxTPA2_2                matlab.ui.control.CheckBox
        Label_21                      matlab.ui.control.Label
        EditField_NAC_TPA_2           matlab.ui.control.NumericEditField
        iLabel_5                      matlab.ui.control.Label
        EditField_ITILB_TPA_2         matlab.ui.control.NumericEditField
        CheckBoxTPA3_2                matlab.ui.control.CheckBox
        CheckBoxTPA4_2                matlab.ui.control.CheckBox
        CheckBoxTPA5_2                matlab.ui.control.CheckBox
        CheckBoxTPA6_2                matlab.ui.control.CheckBox
        CheckBoxTPA7_2                matlab.ui.control.CheckBox
        CheckBoxTPA8_2                matlab.ui.control.CheckBox
        ThreePhotonAbsorptionTab_2    matlab.ui.container.Tab
        GridLayout8_2                 matlab.ui.container.GridLayout
        EditField_TTM_ThPA_2          matlab.ui.control.NumericEditField
        LLabel_6                      matlab.ui.control.Label
        EditField_NAC_ThPA_2          matlab.ui.control.NumericEditField
        Label_30                      matlab.ui.control.Label
        EditField_ITILB_ThPA_2        matlab.ui.control.NumericEditField
        iLabel_6                      matlab.ui.control.Label
        EditField_RL_ThPA_2           matlab.ui.control.NumericEditField
        ZLabel_6                      matlab.ui.control.Label
        EditField_W0_ThPA_2           matlab.ui.control.NumericEditField
        Label_29                      matlab.ui.control.Label
        EditField_LAMBDA_ThPA_2       matlab.ui.control.NumericEditField
        Label_28                      matlab.ui.control.Label
        EditField_Leff_ThPA_2         matlab.ui.control.NumericEditField
        Label_27                      matlab.ui.control.Label
        EditField_alpha_ThPA_2        matlab.ui.control.NumericEditField
        Label_26                      matlab.ui.control.Label
        CheckBoxThPA1_2               matlab.ui.control.CheckBox
        CheckBoxThPA2_2               matlab.ui.control.CheckBox
        CheckBoxThPA3_2               matlab.ui.control.CheckBox
        CheckBoxThPA4_2               matlab.ui.control.CheckBox
        CheckBoxThPA5_2               matlab.ui.control.CheckBox
        CheckBoxThPA6_2               matlab.ui.control.CheckBox
        CheckBoxThPA7_2               matlab.ui.control.CheckBox
        CheckBoxThPA8_2               matlab.ui.control.CheckBox
        FittingmethodListBoxLabel_2   matlab.ui.control.Label
        fittingMethods_2              matlab.ui.control.ListBox
        ParametersForFittingLabel_2   matlab.ui.control.Label
        DevelopedbyMuhammedBektaolu2024Button_2  matlab.ui.control.Button
        Panel_3                       matlab.ui.container.Panel
        GridLayout4_2                 matlab.ui.container.GridLayout
        RsquareValueEditField_2       matlab.ui.control.NumericEditField
        RsquareValueEditField_2Label  matlab.ui.control.Label
        graphOriginal_2               matlab.ui.control.UIAxes
        graphEdited_2                 matlab.ui.control.UIAxes
        Input3                        matlab.ui.container.Tab
        GridLayout_3                  matlab.ui.container.GridLayout
        FitCheckBox_3                 matlab.ui.control.CheckBox
        FitDataSetLabel_3             matlab.ui.control.Label
        ExcellExportButton_3          matlab.ui.control.Button
        GetthescatteringandfittingLabel_3  matlab.ui.control.Label
        NormalisedCheckBox_3          matlab.ui.control.CheckBox
        NormalisedLabel_3             matlab.ui.control.Label
        XvalueforMLabel_3             matlab.ui.control.Label
        DataSetterField3_3            matlab.ui.control.NumericEditField
        EditDataSetButton_3           matlab.ui.control.Button
        DatabetweenLabel_3            matlab.ui.control.Label
        zLabel_3                      matlab.ui.control.Label
        DataSetterField2_3            matlab.ui.control.NumericEditField
        DataSetterField1_3            matlab.ui.control.NumericEditField
        ExcellImportButton_3          matlab.ui.control.Button
        OriginalDataSetLabel_3        matlab.ui.control.Label
        dataTable_3                   matlab.ui.control.Table
        dataTableFit_3                matlab.ui.control.Table
        dataTableEdited_3             matlab.ui.control.Table
        EditedDataSetLabel_3          matlab.ui.control.Label
        Panel_6                       matlab.ui.container.Panel
        GridLayout3_3                 matlab.ui.container.GridLayout
        Units_ListBox_3               matlab.ui.control.ListBox
        UnitsoftheparametersListBoxLabel_3  matlab.ui.control.Label
        TabGroup2_3                   matlab.ui.container.TabGroup
        OpenApertureZScanTab_3        matlab.ui.container.Tab
        GridLayout5_3                 matlab.ui.container.GridLayout
        CheckBoxOA1_3                 matlab.ui.control.CheckBox
        CheckBoxOA2_3                 matlab.ui.control.CheckBox
        CheckBoxOA3_3                 matlab.ui.control.CheckBox
        CheckBoxOA4_3                 matlab.ui.control.CheckBox
        CheckBoxOA5_3                 matlab.ui.control.CheckBox
        CheckBoxOA6_3                 matlab.ui.control.CheckBox
        CheckBoxOA7_3                 matlab.ui.control.CheckBox
        CheckBoxOA8_3                 matlab.ui.control.CheckBox
        EditField_Leff_OA_3           matlab.ui.control.NumericEditField
        Label_35                      matlab.ui.control.Label
        EditField_ITILB_OA_3          matlab.ui.control.NumericEditField
        iLabel_7                      matlab.ui.control.Label
        EditField_RL_OA_3             matlab.ui.control.NumericEditField
        ZLabel_7                      matlab.ui.control.Label
        EditField_NAC_OA_3            matlab.ui.control.NumericEditField
        Label_34                      matlab.ui.control.Label
        EditField_W0_OA_3             matlab.ui.control.NumericEditField
        Label_33                      matlab.ui.control.Label
        EditField_alpha_OA_3          matlab.ui.control.NumericEditField
        Label_32                      matlab.ui.control.Label
        EditField_LAMBDA_OA_3         matlab.ui.control.NumericEditField
        Label_31                      matlab.ui.control.Label
        EditField_TTM_OA_3            matlab.ui.control.NumericEditField
        LLabel_7                      matlab.ui.control.Label
        ClosedApertureZScanTab_3      matlab.ui.container.Tab
        GridLayout6_3                 matlab.ui.container.GridLayout
        CheckBoxCA2_3                 matlab.ui.control.CheckBox
        CheckBoxCA4_3                 matlab.ui.control.CheckBox
        CheckBoxCA6_3                 matlab.ui.control.CheckBox
        ZLabel_18                     matlab.ui.control.Label
        EditField_LAMBDA_CA_3         matlab.ui.control.NumericEditField
        EditField_W0_CA_3             matlab.ui.control.NumericEditField
        Label_81                      matlab.ui.control.Label
        EditField_RL_CA_3             matlab.ui.control.NumericEditField
        Label_80                      matlab.ui.control.Label
        TwoPhotonAbsorptionTab_3      matlab.ui.container.Tab
        GridLayout7_3                 matlab.ui.container.GridLayout
        EditField_TTM_TPA_3           matlab.ui.control.NumericEditField
        LLabel_8                      matlab.ui.control.Label
        Label_40                      matlab.ui.control.Label
        EditField_LAMBDA_TPA_3        matlab.ui.control.NumericEditField
        Label_39                      matlab.ui.control.Label
        EditField_W0_TPA_3            matlab.ui.control.NumericEditField
        ZLabel_8                      matlab.ui.control.Label
        EditField_RL_TPA_3            matlab.ui.control.NumericEditField
        EditField_alpha_TPA_3         matlab.ui.control.NumericEditField
        Label_38                      matlab.ui.control.Label
        EditField_Leff_TPA_3          matlab.ui.control.NumericEditField
        Label_37                      matlab.ui.control.Label
        iLabel_8                      matlab.ui.control.Label
        EditField_ITILB_TPA_3         matlab.ui.control.NumericEditField
        Label_36                      matlab.ui.control.Label
        EditField_NAC_TPA_3           matlab.ui.control.NumericEditField
        CheckBoxTPA1_3                matlab.ui.control.CheckBox
        CheckBoxTPA2_3                matlab.ui.control.CheckBox
        CheckBoxTPA3_3                matlab.ui.control.CheckBox
        CheckBoxTPA4_3                matlab.ui.control.CheckBox
        CheckBoxTPA5_3                matlab.ui.control.CheckBox
        CheckBoxTPA6_3                matlab.ui.control.CheckBox
        CheckBoxTPA7_3                matlab.ui.control.CheckBox
        CheckBoxTPA8_3                matlab.ui.control.CheckBox
        ThreePhotonAbsorptionTab_3    matlab.ui.container.Tab
        GridLayout8_3                 matlab.ui.container.GridLayout
        EditField_TTM_ThPA_3          matlab.ui.control.NumericEditField
        LLabel_9                      matlab.ui.control.Label
        EditField_NAC_ThPA_3          matlab.ui.control.NumericEditField
        Label_45                      matlab.ui.control.Label
        EditField_ITILB_ThPA_3        matlab.ui.control.NumericEditField
        iLabel_9                      matlab.ui.control.Label
        EditField_RL_ThPA_3           matlab.ui.control.NumericEditField
        ZLabel_9                      matlab.ui.control.Label
        EditField_W0_ThPA_3           matlab.ui.control.NumericEditField
        Label_44                      matlab.ui.control.Label
        EditField_LAMBDA_ThPA_3       matlab.ui.control.NumericEditField
        Label_43                      matlab.ui.control.Label
        EditField_Leff_ThPA_3         matlab.ui.control.NumericEditField
        Label_42                      matlab.ui.control.Label
        EditField_alpha_ThPA_3        matlab.ui.control.NumericEditField
        Label_41                      matlab.ui.control.Label
        CheckBoxThPA1_3               matlab.ui.control.CheckBox
        CheckBoxThPA2_3               matlab.ui.control.CheckBox
        CheckBoxThPA3_3               matlab.ui.control.CheckBox
        CheckBoxThPA4_3               matlab.ui.control.CheckBox
        CheckBoxThPA5_3               matlab.ui.control.CheckBox
        CheckBoxThPA6_3               matlab.ui.control.CheckBox
        CheckBoxThPA7_3               matlab.ui.control.CheckBox
        CheckBoxThPA8_3               matlab.ui.control.CheckBox
        DevelopedbyMuhammedBektaolu2024Button_3  matlab.ui.control.Button
        ParametersForFittingLabel_3   matlab.ui.control.Label
        FittingmethodListBoxLabel_3   matlab.ui.control.Label
        fittingMethods_3              matlab.ui.control.ListBox
        Panel_5                       matlab.ui.container.Panel
        GridLayout4_3                 matlab.ui.container.GridLayout
        RsquareValueEditField_3       matlab.ui.control.NumericEditField
        RsquareValueEditField_3Label  matlab.ui.control.Label
        graphOriginal_3               matlab.ui.control.UIAxes
        graphEdited_3                 matlab.ui.control.UIAxes
        Input4                        matlab.ui.container.Tab
        GridLayout_4                  matlab.ui.container.GridLayout
        FitCheckBox_4                 matlab.ui.control.CheckBox
        FitDataSetLabel_4             matlab.ui.control.Label
        ExcellExportButton_4          matlab.ui.control.Button
        GetthescatteringandfittingLabel_4  matlab.ui.control.Label
        NormalisedCheckBox_4          matlab.ui.control.CheckBox
        NormalisedLabel_4             matlab.ui.control.Label
        XvalueforMLabel_4             matlab.ui.control.Label
        DataSetterField3_4            matlab.ui.control.NumericEditField
        EditDataSetButton_4           matlab.ui.control.Button
        DatabetweenLabel_4            matlab.ui.control.Label
        zLabel_4                      matlab.ui.control.Label
        DataSetterField2_4            matlab.ui.control.NumericEditField
        DataSetterField1_4            matlab.ui.control.NumericEditField
        ExcellImportButton_4          matlab.ui.control.Button
        OriginalDataSetLabel_4        matlab.ui.control.Label
        dataTable_4                   matlab.ui.control.Table
        dataTableFit_4                matlab.ui.control.Table
        dataTableEdited_4             matlab.ui.control.Table
        EditedDataSetLabel_4          matlab.ui.control.Label
        Panel_8                       matlab.ui.container.Panel
        GridLayout3_4                 matlab.ui.container.GridLayout
        Units_ListBox_4               matlab.ui.control.ListBox
        UnitsoftheparametersListBoxLabel_4  matlab.ui.control.Label
        TabGroup2_4                   matlab.ui.container.TabGroup
        OpenApertureZScanTab_4        matlab.ui.container.Tab
        GridLayout5_4                 matlab.ui.container.GridLayout
        CheckBoxOA1_4                 matlab.ui.control.CheckBox
        CheckBoxOA2_4                 matlab.ui.control.CheckBox
        CheckBoxOA3_4                 matlab.ui.control.CheckBox
        CheckBoxOA4_4                 matlab.ui.control.CheckBox
        CheckBoxOA5_4                 matlab.ui.control.CheckBox
        CheckBoxOA6_4                 matlab.ui.control.CheckBox
        CheckBoxOA7_4                 matlab.ui.control.CheckBox
        CheckBoxOA8_4                 matlab.ui.control.CheckBox
        EditField_Leff_OA_4           matlab.ui.control.NumericEditField
        Label_50                      matlab.ui.control.Label
        EditField_ITILB_OA_4          matlab.ui.control.NumericEditField
        iLabel_10                     matlab.ui.control.Label
        EditField_RL_OA_4             matlab.ui.control.NumericEditField
        ZLabel_10                     matlab.ui.control.Label
        EditField_NAC_OA_4            matlab.ui.control.NumericEditField
        Label_49                      matlab.ui.control.Label
        EditField_W0_OA_4             matlab.ui.control.NumericEditField
        Label_48                      matlab.ui.control.Label
        EditField_alpha_OA_4          matlab.ui.control.NumericEditField
        Label_47                      matlab.ui.control.Label
        EditField_LAMBDA_OA_4         matlab.ui.control.NumericEditField
        Label_46                      matlab.ui.control.Label
        EditField_TTM_OA_4            matlab.ui.control.NumericEditField
        LLabel_10                     matlab.ui.control.Label
        ClosedApertureZScanTab_4      matlab.ui.container.Tab
        GridLayout6_4                 matlab.ui.container.GridLayout
        CheckBoxCA2_4                 matlab.ui.control.CheckBox
        CheckBoxCA4_4                 matlab.ui.control.CheckBox
        CheckBoxCA6_4                 matlab.ui.control.CheckBox
        EditField_LAMBDA_CA_4         matlab.ui.control.NumericEditField
        EditField_W0_CA_4             matlab.ui.control.NumericEditField
        Label_83                      matlab.ui.control.Label
        EditField_RL_CA_4             matlab.ui.control.NumericEditField
        ZLabel_19                     matlab.ui.control.Label
        Label_82                      matlab.ui.control.Label
        TwoPhotonAbsorptionTab_4      matlab.ui.container.Tab
        GridLayout7_4                 matlab.ui.container.GridLayout
        EditField_TTM_TPA_4           matlab.ui.control.NumericEditField
        LLabel_11                     matlab.ui.control.Label
        Label_55                      matlab.ui.control.Label
        EditField_LAMBDA_TPA_4        matlab.ui.control.NumericEditField
        Label_54                      matlab.ui.control.Label
        EditField_W0_TPA_4            matlab.ui.control.NumericEditField
        ZLabel_11                     matlab.ui.control.Label
        EditField_RL_TPA_4            matlab.ui.control.NumericEditField
        EditField_alpha_TPA_4         matlab.ui.control.NumericEditField
        Label_53                      matlab.ui.control.Label
        EditField_Leff_TPA_4          matlab.ui.control.NumericEditField
        Label_52                      matlab.ui.control.Label
        iLabel_11                     matlab.ui.control.Label
        EditField_ITILB_TPA_4         matlab.ui.control.NumericEditField
        Label_51                      matlab.ui.control.Label
        EditField_NAC_TPA_4           matlab.ui.control.NumericEditField
        CheckBoxTPA1_4                matlab.ui.control.CheckBox
        CheckBoxTPA2_4                matlab.ui.control.CheckBox
        CheckBoxTPA3_4                matlab.ui.control.CheckBox
        CheckBoxTPA4_4                matlab.ui.control.CheckBox
        CheckBoxTPA5_4                matlab.ui.control.CheckBox
        CheckBoxTPA6_4                matlab.ui.control.CheckBox
        CheckBoxTPA7_4                matlab.ui.control.CheckBox
        CheckBoxTPA8_4                matlab.ui.control.CheckBox
        ThreePhotonAbsorptionTab_4    matlab.ui.container.Tab
        GridLayout8_4                 matlab.ui.container.GridLayout
        EditField_TTM_ThPA_4          matlab.ui.control.NumericEditField
        LLabel_12                     matlab.ui.control.Label
        EditField_NAC_ThPA_4          matlab.ui.control.NumericEditField
        Label_60                      matlab.ui.control.Label
        EditField_ITILB_ThPA_4        matlab.ui.control.NumericEditField
        iLabel_12                     matlab.ui.control.Label
        EditField_RL_ThPA_4           matlab.ui.control.NumericEditField
        ZLabel_12                     matlab.ui.control.Label
        EditField_W0_ThPA_4           matlab.ui.control.NumericEditField
        Label_59                      matlab.ui.control.Label
        EditField_LAMBDA_ThPA_4       matlab.ui.control.NumericEditField
        Label_58                      matlab.ui.control.Label
        EditField_Leff_ThPA_4         matlab.ui.control.NumericEditField
        Label_57                      matlab.ui.control.Label
        EditField_alpha_ThPA_4        matlab.ui.control.NumericEditField
        Label_56                      matlab.ui.control.Label
        CheckBoxThPA1_4               matlab.ui.control.CheckBox
        CheckBoxThPA2_4               matlab.ui.control.CheckBox
        CheckBoxThPA3_4               matlab.ui.control.CheckBox
        CheckBoxThPA4_4               matlab.ui.control.CheckBox
        CheckBoxThPA5_4               matlab.ui.control.CheckBox
        CheckBoxThPA6_4               matlab.ui.control.CheckBox
        CheckBoxThPA7_4               matlab.ui.control.CheckBox
        CheckBoxThPA8_4               matlab.ui.control.CheckBox
        DevelopedbyMuhammedBektaolu2024Button_4  matlab.ui.control.Button
        ParametersForFittingLabel_4   matlab.ui.control.Label
        FittingmethodListBoxLabel_4   matlab.ui.control.Label
        fittingMethods_4              matlab.ui.control.ListBox
        Panel_7                       matlab.ui.container.Panel
        GridLayout4_4                 matlab.ui.container.GridLayout
        RsquareValueEditField_4       matlab.ui.control.NumericEditField
        RsquareValueEditField_4Label  matlab.ui.control.Label
        graphOriginal_4               matlab.ui.control.UIAxes
        graphEdited_4                 matlab.ui.control.UIAxes
        Input5                        matlab.ui.container.Tab
        GridLayout_5                  matlab.ui.container.GridLayout
        FitCheckBox_5                 matlab.ui.control.CheckBox
        FitDataSetLabel_5             matlab.ui.control.Label
        ExcellExportButton_5          matlab.ui.control.Button
        GetthescatteringandfittingLabel_5  matlab.ui.control.Label
        NormalisedCheckBox_5          matlab.ui.control.CheckBox
        NormalisedLabel_5             matlab.ui.control.Label
        XvalueforMLabel_5             matlab.ui.control.Label
        DataSetterField3_5            matlab.ui.control.NumericEditField
        EditDataSetButton_5           matlab.ui.control.Button
        DatabetweenLabel_5            matlab.ui.control.Label
        zLabel_5                      matlab.ui.control.Label
        DataSetterField2_5            matlab.ui.control.NumericEditField
        DataSetterField1_5            matlab.ui.control.NumericEditField
        ExcellImportButton_5          matlab.ui.control.Button
        OriginalDataSetLabel_5        matlab.ui.control.Label
        dataTable_5                   matlab.ui.control.Table
        dataTableFit_5                matlab.ui.control.Table
        dataTableEdited_5             matlab.ui.control.Table
        EditedDataSetLabel_5          matlab.ui.control.Label
        Panel_10                      matlab.ui.container.Panel
        GridLayout3_5                 matlab.ui.container.GridLayout
        Units_ListBox_5               matlab.ui.control.ListBox
        UnitsoftheparametersListBoxLabel_5  matlab.ui.control.Label
        TabGroup2_5                   matlab.ui.container.TabGroup
        OpenApertureZScanTab_5        matlab.ui.container.Tab
        GridLayout5_5                 matlab.ui.container.GridLayout
        CheckBoxOA1_5                 matlab.ui.control.CheckBox
        CheckBoxOA2_5                 matlab.ui.control.CheckBox
        CheckBoxOA3_5                 matlab.ui.control.CheckBox
        CheckBoxOA4_5                 matlab.ui.control.CheckBox
        CheckBoxOA5_5                 matlab.ui.control.CheckBox
        CheckBoxOA6_5                 matlab.ui.control.CheckBox
        CheckBoxOA7_5                 matlab.ui.control.CheckBox
        CheckBoxOA8_5                 matlab.ui.control.CheckBox
        EditField_Leff_OA_5           matlab.ui.control.NumericEditField
        Label_65                      matlab.ui.control.Label
        EditField_ITILB_OA_5          matlab.ui.control.NumericEditField
        iLabel_13                     matlab.ui.control.Label
        EditField_RL_OA_5             matlab.ui.control.NumericEditField
        ZLabel_13                     matlab.ui.control.Label
        EditField_NAC_OA_5            matlab.ui.control.NumericEditField
        Label_64                      matlab.ui.control.Label
        EditField_W0_OA_5             matlab.ui.control.NumericEditField
        Label_63                      matlab.ui.control.Label
        EditField_alpha_OA_5          matlab.ui.control.NumericEditField
        Label_62                      matlab.ui.control.Label
        EditField_LAMBDA_OA_5         matlab.ui.control.NumericEditField
        Label_61                      matlab.ui.control.Label
        EditField_TTM_OA_5            matlab.ui.control.NumericEditField
        LLabel_13                     matlab.ui.control.Label
        ClosedApertureZScanTab_5      matlab.ui.container.Tab
        GridLayout6_5                 matlab.ui.container.GridLayout
        CheckBoxCA2_5                 matlab.ui.control.CheckBox
        CheckBoxCA4_5                 matlab.ui.control.CheckBox
        CheckBoxCA6_5                 matlab.ui.control.CheckBox
        EditField_LAMBDA_CA_5         matlab.ui.control.NumericEditField
        Label_85                      matlab.ui.control.Label
        EditField_W0_CA_5             matlab.ui.control.NumericEditField
        Label_84                      matlab.ui.control.Label
        EditField_RL_CA_5             matlab.ui.control.NumericEditField
        ZLabel_20                     matlab.ui.control.Label
        TwoPhotonAbsorptionTab_5      matlab.ui.container.Tab
        GridLayout7_5                 matlab.ui.container.GridLayout
        EditField_TTM_TPA_5           matlab.ui.control.NumericEditField
        LLabel_14                     matlab.ui.control.Label
        Label_70                      matlab.ui.control.Label
        EditField_LAMBDA_TPA_5        matlab.ui.control.NumericEditField
        Label_69                      matlab.ui.control.Label
        EditField_W0_TPA_5            matlab.ui.control.NumericEditField
        ZLabel_14                     matlab.ui.control.Label
        EditField_RL_TPA_5            matlab.ui.control.NumericEditField
        EditField_alpha_TPA_5         matlab.ui.control.NumericEditField
        Label_68                      matlab.ui.control.Label
        EditField_Leff_TPA_5          matlab.ui.control.NumericEditField
        Label_67                      matlab.ui.control.Label
        iLabel_14                     matlab.ui.control.Label
        EditField_ITILB_TPA_5         matlab.ui.control.NumericEditField
        Label_66                      matlab.ui.control.Label
        EditField_NAC_TPA_5           matlab.ui.control.NumericEditField
        CheckBoxTPA1_5                matlab.ui.control.CheckBox
        CheckBoxTPA2_5                matlab.ui.control.CheckBox
        CheckBoxTPA3_5                matlab.ui.control.CheckBox
        CheckBoxTPA4_5                matlab.ui.control.CheckBox
        CheckBoxTPA5_5                matlab.ui.control.CheckBox
        CheckBoxTPA6_5                matlab.ui.control.CheckBox
        CheckBoxTPA7_5                matlab.ui.control.CheckBox
        CheckBoxTPA8_5                matlab.ui.control.CheckBox
        ThreePhotonAbsorptionTab_5    matlab.ui.container.Tab
        GridLayout8_5                 matlab.ui.container.GridLayout
        EditField_TTM_ThPA_5          matlab.ui.control.NumericEditField
        LLabel_15                     matlab.ui.control.Label
        EditField_NAC_ThPA_5          matlab.ui.control.NumericEditField
        Label_75                      matlab.ui.control.Label
        EditField_ITILB_ThPA_5        matlab.ui.control.NumericEditField
        iLabel_15                     matlab.ui.control.Label
        EditField_RL_ThPA_5           matlab.ui.control.NumericEditField
        ZLabel_15                     matlab.ui.control.Label
        EditField_W0_ThPA_5           matlab.ui.control.NumericEditField
        Label_74                      matlab.ui.control.Label
        EditField_LAMBDA_ThPA_5       matlab.ui.control.NumericEditField
        Label_73                      matlab.ui.control.Label
        EditField_Leff_ThPA_5         matlab.ui.control.NumericEditField
        Label_72                      matlab.ui.control.Label
        EditField_alpha_ThPA_5        matlab.ui.control.NumericEditField
        Label_71                      matlab.ui.control.Label
        CheckBoxThPA1_5               matlab.ui.control.CheckBox
        CheckBoxThPA2_5               matlab.ui.control.CheckBox
        CheckBoxThPA3_5               matlab.ui.control.CheckBox
        CheckBoxThPA4_5               matlab.ui.control.CheckBox
        CheckBoxThPA5_5               matlab.ui.control.CheckBox
        CheckBoxThPA6_5               matlab.ui.control.CheckBox
        CheckBoxThPA7_5               matlab.ui.control.CheckBox
        CheckBoxThPA8_5               matlab.ui.control.CheckBox
        DevelopedbyMuhammedBektaolu2024Button_5  matlab.ui.control.Button
        ParametersForFittingLabel_5   matlab.ui.control.Label
        FittingmethodListBoxLabel_5   matlab.ui.control.Label
        fittingMethods_5              matlab.ui.control.ListBox
        Panel_9                       matlab.ui.container.Panel
        GridLayout4_5                 matlab.ui.container.GridLayout
        RsquareValueEditField_5       matlab.ui.control.NumericEditField
        RsquareValueEditField_5Label  matlab.ui.control.Label
        graphOriginal_5               matlab.ui.control.UIAxes
        graphEdited_5                 matlab.ui.control.UIAxes
        Outputs                       matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        AllDataExport                 matlab.ui.control.Button
        TabGroup3                     matlab.ui.container.TabGroup
        TabOutput1                    matlab.ui.container.Tab
        Parameters1                   matlab.ui.container.GridLayout
        Tab1_ListBox                  matlab.ui.control.ListBox
        UnitsoftheparametersListBoxLabel_6  matlab.ui.control.Label
        Tab1_EditField_1              matlab.ui.control.NumericEditField
        Tab1_EditField_2              matlab.ui.control.NumericEditField
        LeffValueLabel_10             matlab.ui.control.Label
        LeffValueLabel_9              matlab.ui.control.Label
        LeffValueLabel_8              matlab.ui.control.Label
        Tab1_EditField_3              matlab.ui.control.NumericEditField
        Tab1_EditField_4              matlab.ui.control.NumericEditField
        LeffValueLabel_6              matlab.ui.control.Label
        Tab1_EditField_5              matlab.ui.control.NumericEditField
        Tab1_EditField_6              matlab.ui.control.NumericEditField
        Tab1_EditField_7              matlab.ui.control.NumericEditField
        Tab1_EditField_8              matlab.ui.control.NumericEditField
        Tab1_EditField_9              matlab.ui.control.NumericEditField
        NonlinearabsorptioncoefficientLabel_6  matlab.ui.control.Label
        Tab1_EditField_10             matlab.ui.control.NumericEditField
        Tab1_EditField_11             matlab.ui.control.NumericEditField
        Tab1_EditField_12             matlab.ui.control.NumericEditField
        AdjRSquareLabel               matlab.ui.control.Label
        RSquareCODLabel               matlab.ui.control.Label
        ReducedChiSqLabel             matlab.ui.control.Label
        RayleighlengthLabel           matlab.ui.control.Label
        IntensityoftheincidentlaserbeamLabel  matlab.ui.control.Label
        NonlinearabsorptioncoefficientLabel  matlab.ui.control.Label
        LeffValueLabel                matlab.ui.control.Label
        TabOutput2                    matlab.ui.container.Tab
        Parameters2                   matlab.ui.container.GridLayout
        Tab2_ListBox                  matlab.ui.control.ListBox
        UnitsoftheparametersListBoxLabel_7  matlab.ui.control.Label
        LeffValueLabel_15             matlab.ui.control.Label
        LeffValueLabel_14             matlab.ui.control.Label
        Tab2_EditField_1              matlab.ui.control.NumericEditField
        Tab2_EditField_2              matlab.ui.control.NumericEditField
        LeffValueLabel_13             matlab.ui.control.Label
        Tab2_EditField_3              matlab.ui.control.NumericEditField
        Tab2_EditField_4              matlab.ui.control.NumericEditField
        LeffValueLabel_12             matlab.ui.control.Label
        Tab2_EditField_5              matlab.ui.control.NumericEditField
        Tab2_EditField_6              matlab.ui.control.NumericEditField
        Tab2_EditField_7              matlab.ui.control.NumericEditField
        Tab2_EditField_8              matlab.ui.control.NumericEditField
        Tab2_EditField_9              matlab.ui.control.NumericEditField
        NonlinearabsorptioncoefficientLabel_8  matlab.ui.control.Label
        Tab2_EditField_10             matlab.ui.control.NumericEditField
        Tab2_EditField_11             matlab.ui.control.NumericEditField
        Tab2_EditField_12             matlab.ui.control.NumericEditField
        AdjRSquareLabel_6             matlab.ui.control.Label
        RSquareCODLabel_6             matlab.ui.control.Label
        ReducedChiSqLabel_6           matlab.ui.control.Label
        RayleighlengthLabel_6         matlab.ui.control.Label
        IntensityoftheincidentlaserbeamLabel_6  matlab.ui.control.Label
        NonlinearabsorptioncoefficientLabel_7  matlab.ui.control.Label
        LeffValueLabel_11             matlab.ui.control.Label
        TabOutput3                    matlab.ui.container.Tab
        Parameters3                   matlab.ui.container.GridLayout
        Tab3_ListBox                  matlab.ui.control.ListBox
        UnitsoftheparametersListBoxLabel_8  matlab.ui.control.Label
        Tab3_EditField_1              matlab.ui.control.NumericEditField
        LeffValueLabel_20             matlab.ui.control.Label
        Tab3_EditField_2              matlab.ui.control.NumericEditField
        LeffValueLabel_19             matlab.ui.control.Label
        LeffValueLabel_18             matlab.ui.control.Label
        Tab3_EditField_3              matlab.ui.control.NumericEditField
        Tab3_EditField_4              matlab.ui.control.NumericEditField
        LeffValueLabel_17             matlab.ui.control.Label
        Tab3_EditField_5              matlab.ui.control.NumericEditField
        Tab3_EditField_6              matlab.ui.control.NumericEditField
        Tab3_EditField_7              matlab.ui.control.NumericEditField
        Tab3_EditField_8              matlab.ui.control.NumericEditField
        Tab3_EditField_9              matlab.ui.control.NumericEditField
        NonlinearabsorptioncoefficientLabel_10  matlab.ui.control.Label
        Tab3_EditField_10             matlab.ui.control.NumericEditField
        Tab3_EditField_11             matlab.ui.control.NumericEditField
        Tab3_EditField_12             matlab.ui.control.NumericEditField
        AdjRSquareLabel_7             matlab.ui.control.Label
        RSquareCODLabel_7             matlab.ui.control.Label
        ReducedChiSqLabel_7           matlab.ui.control.Label
        RayleighlengthLabel_7         matlab.ui.control.Label
        IntensityoftheincidentlaserbeamLabel_7  matlab.ui.control.Label
        NonlinearabsorptioncoefficientLabel_9  matlab.ui.control.Label
        LeffValueLabel_16             matlab.ui.control.Label
        TabOutput4                    matlab.ui.container.Tab
        Parameters4                   matlab.ui.container.GridLayout
        Tab4_ListBox                  matlab.ui.control.ListBox
        UnitsoftheparametersListBoxLabel_9  matlab.ui.control.Label
        LeffValueLabel_25             matlab.ui.control.Label
        Tab4_EditField_1              matlab.ui.control.NumericEditField
        Tab4_EditField_2              matlab.ui.control.NumericEditField
        LeffValueLabel_24             matlab.ui.control.Label
        LeffValueLabel_23             matlab.ui.control.Label
        Tab4_EditField_3              matlab.ui.control.NumericEditField
        Tab4_EditField_4              matlab.ui.control.NumericEditField
        LeffValueLabel_22             matlab.ui.control.Label
        Tab4_EditField_5              matlab.ui.control.NumericEditField
        Tab4_EditField_6              matlab.ui.control.NumericEditField
        Tab4_EditField_7              matlab.ui.control.NumericEditField
        Tab4_EditField_8              matlab.ui.control.NumericEditField
        Tab4_EditField_9              matlab.ui.control.NumericEditField
        NonlinearabsorptioncoefficientLabel_12  matlab.ui.control.Label
        Tab4_EditField_10             matlab.ui.control.NumericEditField
        AdjRSquareLabel_8             matlab.ui.control.Label
        Tab4_EditField_11             matlab.ui.control.NumericEditField
        Tab4_EditField_12             matlab.ui.control.NumericEditField
        RSquareCODLabel_8             matlab.ui.control.Label
        ReducedChiSqLabel_8           matlab.ui.control.Label
        RayleighlengthLabel_8         matlab.ui.control.Label
        IntensityoftheincidentlaserbeamLabel_8  matlab.ui.control.Label
        NonlinearabsorptioncoefficientLabel_11  matlab.ui.control.Label
        LeffValueLabel_21             matlab.ui.control.Label
        TabOutput5                    matlab.ui.container.Tab
        Parameters5                   matlab.ui.container.GridLayout
        Tab5_ListBox                  matlab.ui.control.ListBox
        UnitsoftheparametersListBoxLabel_10  matlab.ui.control.Label
        Tab5_EditField_1              matlab.ui.control.NumericEditField
        LeffValueLabel_30             matlab.ui.control.Label
        Tab5_EditField_2              matlab.ui.control.NumericEditField
        LeffValueLabel_29             matlab.ui.control.Label
        LeffValueLabel_28             matlab.ui.control.Label
        Tab5_EditField_3              matlab.ui.control.NumericEditField
        Tab5_EditField_4              matlab.ui.control.NumericEditField
        LeffValueLabel_27             matlab.ui.control.Label
        Tab5_EditField_5              matlab.ui.control.NumericEditField
        Tab5_EditField_6              matlab.ui.control.NumericEditField
        Tab5_EditField_7              matlab.ui.control.NumericEditField
        Tab5_EditField_8              matlab.ui.control.NumericEditField
        Tab5_EditField_9              matlab.ui.control.NumericEditField
        NonlinearabsorptioncoefficientLabel_14  matlab.ui.control.Label
        AdjRSquareLabel_9             matlab.ui.control.Label
        RSquareCODLabel_9             matlab.ui.control.Label
        Tab5_EditField_10             matlab.ui.control.NumericEditField
        Tab5_EditField_11             matlab.ui.control.NumericEditField
        Tab5_EditField_12             matlab.ui.control.NumericEditField
        ReducedChiSqLabel_9           matlab.ui.control.Label
        RayleighlengthLabel_9         matlab.ui.control.Label
        IntensityoftheincidentlaserbeamLabel_9  matlab.ui.control.Label
        NonlinearabsorptioncoefficientLabel_13  matlab.ui.control.Label
        LeffValueLabel_26             matlab.ui.control.Label
        figureExport                  matlab.ui.control.Button
        LegendsCloseLabel             matlab.ui.control.Label
        SymbolsLabel_2                matlab.ui.control.Label
        ShapeDropDown2                matlab.ui.control.DropDown
        SymbolsLabel                  matlab.ui.control.Label
        ShapeDropDown                 matlab.ui.control.DropDown
        InputTabsLabel                matlab.ui.control.Label
        ColorDropDownLabel            matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        LineSizeSlider                matlab.ui.control.Slider
        LineSizeLabel                 matlab.ui.control.Label
        DotsSizeSlider                matlab.ui.control.Slider
        DotsSizeLabel                 matlab.ui.control.Label
        OutputTree                    matlab.ui.container.CheckBoxTree
        InputTreeCheckBox1            matlab.ui.container.TreeNode
        InputTreeCheckBox2            matlab.ui.container.TreeNode
        InputTreeCheckBox3            matlab.ui.container.TreeNode
        InputTreeCheckBox4            matlab.ui.container.TreeNode
        InputTreeCheckBox5            matlab.ui.container.TreeNode
        ButtonGroup                   matlab.ui.container.ButtonGroup
        Button1                       matlab.ui.control.RadioButton
        Button2                       matlab.ui.control.RadioButton
        Button3                       matlab.ui.control.RadioButton
        Button4                       matlab.ui.control.RadioButton
        Button5                       matlab.ui.control.RadioButton
        ButtonLegendClose             matlab.ui.control.Button
        ButtonColorEdit               matlab.ui.control.Button
        ButtonDotsSymbolsEdit         matlab.ui.control.Button
        ButtonLineTypesEdit           matlab.ui.control.Button
        ButtonDotsSizeEdit            matlab.ui.control.Button
        ButtonLineSizeEdit            matlab.ui.control.Button
        graphOverall                  matlab.ui.control.UIAxes
        ContextMenuInput1             matlab.ui.container.ContextMenu
        GraphLabelsMenu               matlab.ui.container.Menu
        XLabelEditMenu                matlab.ui.container.Menu
        YLabelEditMenu                matlab.ui.container.Menu
        TitleEditMenu                 matlab.ui.container.Menu
        ContextMenuOutputs            matlab.ui.container.ContextMenu
        GraphLabelsMenuOutputs        matlab.ui.container.Menu
        XLabelEditMenuOutputs         matlab.ui.container.Menu
        YLabelEditMenuOutputs         matlab.ui.container.Menu
        TitleEditMenuOutputs          matlab.ui.container.Menu
    end

    
    properties (Access = public)
        MarkerSize1 = 5; % Başlangıçta kullanılacak marker boyutu
        LineSize1 = 2; % Başlangıçta kullanılacak line boyutu
        LValues = zeros(1,5);
        lambdaValues = zeros(1,5);
        alphaValues = zeros(1,5);
        w0Values = zeros(1,5);
        LeffValues = zeros(1,5);
        zValues = zeros(1,5);
        iValues = zeros(1,5);
        bValues = zeros(1,5);
        dValues = zeros(1,5);
        R2Values = zeros(1,5);
        AdjustedR2Values = zeros(1,5);
        ReducedChiSqValues = zeros(1,5);
        totalIterations;
        currentIteration;
        previousUnit = cell(1,5);
    end
    
    methods (Access = public)


        %%%%% MATEMATİKSEL HESAPLAMALAR %%%%%
        
        function w0 = calculatew0(app)
            % Seçili tab'ı belirle
            selectedTabTag = app.TabGroup.SelectedTab.Tag;
            suffix = extractAfter(selectedTabTag, 'InputTab');
            selectedMethod = app.(sprintf('fittingMethods_%s', suffix)).Value;
            switch selectedMethod
                case 'Open Aperture Z-Scan'
                    RL = app.(sprintf('EditField_RL_OA_%s', suffix)).Value;
                    lambda = app.(sprintf('EditField_LAMBDA_OA_%s', suffix)).Value;
                    w0 = sqrt(lambda*RL/pi);
                case 'Two Photon Absorption'
                    RL = app.(sprintf('EditField_RL_TPA_%s', suffix)).Value;
                    lambda = app.(sprintf('EditField_LAMBDA_TPA_%s', suffix)).Value;
                    w0 = sqrt(lambda*RL/pi);
                case 'Three Photon Absorption'
                    RL = app.(sprintf('EditField_RL_ThPA_%s', suffix)).Value;
                    lambda = app.(sprintf('EditField_LAMBDA_ThPA_%s', suffix)).Value;
                    w0 = sqrt(lambda*RL/pi);
            end
        end


        function L_eff = calculateL_eff(app)
            % Seçili tab'ı belirle
            selectedTabTag = app.TabGroup.SelectedTab.Tag;
            % Seçili tab'a göre uygun son eki belirle
            suffix = extractAfter(selectedTabTag, 'InputTab');
        
            % fittingMethods'deki değeri kontrol et (dinamik isimlendirme ile)
            selectedMethod = app.(sprintf('fittingMethods_%s', suffix)).Value;
        
            % Senaryoya özgü kontrol ve değerlerin ayarlanması
            switch selectedMethod
                case 'Open Aperture Z-Scan'
                    checkBoxOA1 = app.(sprintf('CheckBoxOA1_%s', suffix));
                    checkBoxOA3 = app.(sprintf('CheckBoxOA3_%s', suffix));
                    LeffInput = app.(sprintf('EditField_Leff_OA_%s', suffix)).Value;
                    checkBox = app.(sprintf('CheckBoxOA5_%s', suffix));
                    Alpha = app.(sprintf('EditField_alpha_OA_%s', suffix)).Value;
                    TTM = app.(sprintf('EditField_TTM_OA_%s', suffix)).Value;
                    if checkBox.Value
                        L_eff = LeffInput; % Kullanıcının girdiği L_eff değerini doğrudan kullan
                    else
                        if checkBoxOA1.Value && checkBoxOA3.Value
                            % İlave kontroller sağlanıyorsa hesaplama yap
                            L_eff = (1 - exp(-Alpha * TTM)) / Alpha;
                        else
                            L_eff = NaN;
                            return;
                        end
                    end
                case 'Closed Aperture Z-Scan'
                    checkBoxCA1 = app.(sprintf('CheckBoxCA1_%s', suffix));
                    checkBoxCA3 = app.(sprintf('CheckBoxCA3_%s', suffix));
                    LeffInput = app.(sprintf('EditField_Leff_CA_%s', suffix)).Value;
                    checkBox = app.(sprintf('CheckBoxCA5_%s', suffix));
                    Alpha = app.(sprintf('EditField_alpha_CA_%s', suffix)).Value;
                    TTM = app.(sprintf('EditField_TTM_CA_%s', suffix)).Value;
                    if checkBox.Value
                        L_eff = LeffInput; % Kullanıcının girdiği L_eff değerini doğrudan kullan
                    else
                        if checkBoxCA1.Value && checkBoxCA3.Value
                            % İlave kontroller sağlanıyorsa hesaplama yap
                            L_eff = (1 - exp(-Alpha * TTM)) / Alpha;
                        else
                            L_eff = NaN;
                            return;
                        end
                    end
                case 'Two Photon Absorption'
                    checkBoxTPA1 = app.(sprintf('CheckBoxTPA1_%s', suffix));
                    checkBoxTPA3 = app.(sprintf('CheckBoxTPA3_%s', suffix));
                    LeffInput = app.(sprintf('EditField_Leff_TPA_%s', suffix)).Value;
                    checkBox = app.(sprintf('CheckBoxTPA5_%s', suffix));
                    Alpha = app.(sprintf('EditField_alpha_TPA_%s', suffix)).Value;
                    TTM = app.(sprintf('EditField_TTM_TPA_%s', suffix)).Value;
                    if checkBox.Value
                        L_eff = LeffInput; % Kullanıcının girdiği L_eff değerini doğrudan kullan
                    else
                        if checkBoxTPA1.Value && checkBoxTPA3.Value
                            % Two Photon Absorption için L_eff hesaplaması
                            L_eff = (1 - exp(-Alpha * TTM)) / Alpha;
                        else
                            L_eff = NaN;
                            return;
                        end
                    end
                case 'Three Photon Absorption'
                    checkBoxThPA1 = app.(sprintf('CheckBoxThPA1_%s', suffix));
                    checkBoxThPA3 = app.(sprintf('CheckBoxThPA3_%s', suffix));
                    LeffInput = app.(sprintf('EditField_Leff_ThPA_%s', suffix)).Value;
                    checkBox = app.(sprintf('CheckBoxThPA5_%s', suffix));
                    Alpha = app.(sprintf('EditField_alpha_ThPA_%s', suffix)).Value;
                    TTM = app.(sprintf('EditField_TTM_ThPA_%s', suffix)).Value;
                    if checkBox.Value
                        L_eff = LeffInput; % Kullanıcının girdiği L_eff değerini doğrudan kullan
                    else
                        if checkBoxThPA1.Value && checkBoxThPA3.Value
                            % Two Photon Absorption için L_eff hesaplaması
                            L_eff = (1 - exp(-2 * Alpha * TTM)) / (2 * Alpha);
                        else
                            L_eff = NaN;
                            uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Additional conditions were not met, L_eff could not be calculated.', 'L_eff Calculation Warning', 'Icon', 'warning');
                            return;
                        end
                    end
                otherwise
                    L_eff = NaN; % Tanımsız bir metod seçildiğinde
            end
        end
     
        function plotScatter(app)
            % Aktif tab'ı bul
            selectedTab = app.TabGroup.SelectedTab;
        
            % Aktif tab'a göre verileri ve grafiği belirle
            if selectedTab == app.Input1
                dataTable = app.dataTable_1.Data;
                graph = app.graphOriginal_1;
            elseif selectedTab == app.Input2
                dataTable = app.dataTable_2.Data;
                graph = app.graphOriginal_2;
            elseif selectedTab == app.Input3
                dataTable = app.dataTable_3.Data;
                graph = app.graphOriginal_3;
            elseif selectedTab == app.Input4
                dataTable = app.dataTable_4.Data;
                graph = app.graphOriginal_4;
            elseif selectedTab == app.Input5
                dataTable = app.dataTable_5.Data;
                graph = app.graphOriginal_5;
            else
                disp('Unknown tab selected.');
                return; % Eğer tanınmayan bir tab seçiliyse, fonksiyonu sonlandır
            end
        
            % Verilerin uygun formatta olduğundan emin olun
            if size(dataTable, 2) >= 2
                % Verilerin numeric olup olmadığını kontrol et
                if istable(dataTable)
                    x = dataTable{:, 1}; % İlk sütun x verileri
                    y = dataTable{:, 2}; % İkinci sütun y verileri
                else
                    x = dataTable(:, 1); % İlk sütun x verileri
                    y = dataTable(:, 2); % İkinci sütun y verileri
                end
        
                % x ve y'nin boş veya geçersiz veri içermediğinden emin ol
                if ~isempty(x) && ~isempty(y) && ~any(isnan(x)) && ~any(isnan(y))
                    % Saçılım grafiği çiz
                    scatter(graph, x, y, 'ko', 'MarkerFaceColor', 'k'); % 'filled' parametresi ile noktaların içini doldur
        
                    % Grafiğe bir efsane ekleyin
                    legend(graph, 'Data from Table', 'Location', 'best');
                else
                    % Hata mesajı göster
                    disp('Data contains NaNs or is empty.');
                end
            else
                % Hata mesajı veya uygun bir işlem yapın
                disp('Data table must have at least two columns.');
            end
        end


        % ListBox ValueChanged callback fonksiyonu
        function ListBoxValueChanged(app, event)
            value = app.UnittypesListBox.Value; % ListBox'tan seçilen değeri al
        
            % Seçilen değere göre EditField'ı enable veya disable yap
            if strcmp(value, 'BelirliBirDeger')
                app.DataSetterField1_1.Enable = 'off'; % EditField'ı devre dışı bırak
            else
                app.DataSetterField1_1.Enable = 'on'; % EditField'ı etkinleştir
            end
        end

        % openApertureFit fonksiyonu
        function [x, Y_fit] = openApertureFit(app, x, y)
            % Seçili tab'ı belirle
            selectedTabTag = app.TabGroup.SelectedTab.Tag;
            tabIndex = str2double(regexp(selectedTabTag, '\d+', 'match'));
            % Seçili tab'a göre uygun son eki belirle (örn: '_1', '_2', ...)
            suffix = extractAfter(selectedTabTag, 'InputTab');
            % Kullanıcı girdilerinin kontrolü (dinamik component isimleri ile)
            editField_ITILB_OA = app.(sprintf('EditField_ITILB_OA_%s', suffix));
            editField_RL_OA = app.(sprintf('EditField_RL_OA_%s', suffix));
            editField_NAC_OA = app.(sprintf('EditField_NAC_OA_%s', suffix));
            checkBoxOA1 = app.(sprintf('CheckBoxOA1_%s', suffix));
            checkBoxOA2 = app.(sprintf('CheckBoxOA2_%s', suffix));
            checkBoxOA3 = app.(sprintf('CheckBoxOA3_%s', suffix));
            checkBoxOA4 = app.(sprintf('CheckBoxOA4_%s', suffix));
            checkBoxOA6 = app.(sprintf('CheckBoxOA6_%s', suffix));
            checkBoxOA7 = app.(sprintf('CheckBoxOA7_%s', suffix));
            checkBoxOA8 = app.(sprintf('CheckBoxOA8_%s', suffix));
            editField_W0_OA = app.(sprintf('EditField_W0_OA_%s', suffix));
            editField_Lambda_OA = app.(sprintf('EditField_LAMBDA_OA_%s', suffix));
            fitCheckBox = app.(sprintf('FitCheckBox_%s', suffix));
            graphEdited = app.(sprintf('graphEdited_%s', suffix));
            EditField_TTM_OA = app.(sprintf('EditField_TTM_OA_%s', suffix));
            EditField_alpha_OA = app.(sprintf('EditField_alpha_OA_%s', suffix));
            EditField_LAMBDA_OA = app.(sprintf('EditField_LAMBDA_OA_%s',suffix));
            EditField_W0_OA = app.(sprintf('EditField_W0_OA_%s',suffix));
            
            app.currentIteration = 0;
            % Kullanıcı girdilerinin kontrolü
            if checkBoxOA7.Value
                i = editField_ITILB_OA.Value;
            else
                i = NaN; % i değeri fit ile bulunacak
            end
            if checkBoxOA6.Value
                z = editField_RL_OA.Value;
            else
                z = NaN; % z değeri fit ile bulunacak
            end
            if checkBoxOA8.Value
                b = editField_NAC_OA.Value;
            else
                b = NaN; % b değeri fit ile bulunacak
            end
            if checkBoxOA1.Value
                app.LValues(tabIndex) = EditField_TTM_OA.Value;
            else
                app.LValues(tabIndex) = 0;
            end
            if checkBoxOA3.Value
                app.alphaValues(tabIndex) = EditField_alpha_OA.Value;
            else
                app.alphaValues(tabIndex) = 0;
            end
            if checkBoxOA2.Value
                app.lambdaValues(tabIndex) = EditField_LAMBDA_OA.Value;
            else
                app.lambdaValues(tabIndex) = 0;
            end
            if checkBoxOA4.Value
                app.w0Values(tabIndex) = EditField_W0_OA.Value;
            else
                app.w0Values(tabIndex) = 0;
            end
            if checkBoxOA6.Value
                z = editField_RL_OA.Value;
            else
                if checkBoxOA4.Value && checkBoxOA2.Value
                    w0 = editField_W0_OA.Value;
                    lambda = editField_Lambda_OA.Value;
                    % z'yi hesapla
                    z = (pi * w0^2) / lambda;
                elseif checkBoxOA4.Value || checkBoxOA2.Value % Sadece biri seçiliyse
                    % Uyarı mesajı göster
                    uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, ['Both w0 and λ values must be specified together for calculating Z. ' ...
                        'Please either select both options or deselect both.'], 'Selection Warning', 'Icon', 'warning');
                    fitCheckBox.Value = false;
                    return;
                else
                    z = NaN; % z değeri fit ile bulunacak
                end
            end
            try
                % L_eff değerinin hesaplanması
                L_eff = calculateL_eff(app); % L_eff hesaplayan fonksiyonu çağır ve değeri al

                % L_eff değerinin kontrolü
                if isempty(L_eff) || isnan(L_eff)
                    error('L_eff Calculation Error: L_eff is empty or NaN.');
                end
            catch ME
                % UI Alert mesajı göster
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'L_eff value could not be calculated. Please check the input values.', 'Calculation Warning', 'Icon', 'warning');
                    fitCheckBox.Value = false;
                % Fonksiyondan çıkış yap
                return;
            end

            if checkBoxOA2.Value && checkBoxOA6 .Value
                app.w0Values(tabIndex) = calculatew0(app);
            end

            hWaitBar = waitbar(0, 'Fitting in progress, please wait...', 'Name', 'Open Aperture Fit', 'WindowStyle', 'modal');
            if ~isnan(b) && ~isnan(i) && ~isnan(z)
                disp('Open Aperture: Durum 1: b, i, ve z değerleri var.');
                app.totalIterations = 1;
                % Y_fit hesaplayan anonim fonksiyon
                fitModel = @(b, i, z, x) 1 - 2 * (((x./z).^2 + 3) ./ (((x./z).^2 + 9) .* ((x./z).^2 + 1))) .* (b * i ./2) .* L_eff;
                % Belirli parametreler ile Y_fit hesapla
                Y_fit = fitModel(b, i, z, x);
                updateWaitBar(app, hWaitBar);
            elseif ~isnan(b) && ~isnan(i) && isnan(z)
                disp('Open Aperture: Durum 2: b ve i değerleri var, z NaN.');
                % z için başlangıç tahmini aralığı
                initialGuessRangeZ = logspace(-5, 5, 100); % Örnek: 10^-5 ile 10^5 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeZ);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                for initialGuessZ = initialGuessRangeZ
                    % Y_fit hesaplayan anonim fonksiyon
                    fitModel = @(z, x) 1 - 2 * (((x./z).^2 + 3) ./ (((x./z).^2 + 9) .* ((x./z).^2 + 1))) .* (b * i ./2) .* L_eff;
                    % Optimizasyon seçeneklerini ayarla
                    options = optimoptions('lsqcurvefit', 'Display', 'off');
                    % En uygun z değerini bul
                    [z_opt, resnorm] = lsqcurvefit(@(z,x)fitModel(z,x), initialGuessZ, x, y, [], [], options);
                    % En iyi sonucu kontrol et
                    if resnorm < bestResnorm
                        bestResnorm = resnorm;
                        z = z_opt; % En uygun z değeri
                    end
                    updateWaitBar(app, hWaitBar);
                end
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(z, x);
            elseif ~isnan(b) && isnan(i) && ~isnan(z)
                disp('Open Aperture: Durum 3: b ve z değerleri var, i NaN.');
                % i için başlangıç tahmini
                initialGuessRangeI = logspace(0, 10, 100); % Örnek: 10^0 ile 10^10 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeI);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                % Y_fit hesaplayan anonim fonksiyon
                for initialGuessI = initialGuessRangeI
                    fitModel = @(i, x) 1 - 2 * (((x./z).^2 + 3) ./ (((x./z).^2 + 9) .* ((x./z).^2 + 1))) .* (b * i ./2) .* L_eff;
                    % Optimizasyon seçeneklerini ayarla
                    options = optimoptions('lsqcurvefit', 'Display', 'off');
                    % En uygun i değerini bul
                    [i_opt, resnorm] = lsqcurvefit(fitModel, initialGuessI, x, y, [], [], options);
                    if resnorm < bestResnorm
                        bestResnorm = resnorm;
                        i = i_opt; % En uygun i değeri
                    end
                    updateWaitBar(app, hWaitBar);
                end
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(i, x);
            elseif isnan(b) && ~isnan(i) && ~isnan(z)
                disp('Open Aperture: Durum 4: i ve z değerleri var, b NaN.');
                % b için başlangıç tahmini aralığı
                initialGuessRangeB = logspace(-10, 5, 1000); % Örnek: 10^-10 ile 10^5 arasında logaritmik olarak 1000 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeB);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                for initialGuessB = initialGuessRangeB
                    % Y_fit hesaplayan anonim fonksiyon
                    fitModel = @(b, x) 1 - 2 * (((x./z).^2 + 3) ./ (((x./z).^2 + 9) .* ((x./z).^2 + 1))) .* (b * i ./2) .* L_eff;
                    % Optimizasyon seçeneklerini ayarla
                    options = optimoptions('lsqcurvefit', 'Display', 'off');
                    % En uygun b değerini bul
                    [b_opt, resnorm] = lsqcurvefit(fitModel, initialGuessB, x, y, [], [], options);
                    % En iyi sonucu kontrol et
                    if resnorm < bestResnorm
                        bestResnorm = resnorm;
                        b = b_opt; % En uygun b değeri
                    end
                    updateWaitBar(app, hWaitBar);
                end
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(b, x);
            elseif ~isnan(b) && isnan(i) && isnan(z)
                disp('Open Aperture: Durum 5: yalnızca b değeri var.');
                % i ve z için başlangıç tahmini aralıkları
                initialGuessRangeI = logspace(0, 10, 100); % Örnek: 10^0 ile 10^10 arasında logaritmik olarak 100 farklı başlangıç noktası
                initialGuessRangeZ = logspace(-2, 2, 50); % Örnek: 10^-2 ile 10^2 arasında logaritmik olarak 50 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeI)*numel(initialGuessRangeZ);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                bestParameters = [NaN, NaN]; % En iyi i ve z değerlerini saklamak için
                fitModel = @(params, x) 1 - 2 * (((x./params(2)).^2 + 3) ./ (((x./params(2)).^2 + 9) .* ((x./params(2)).^2 + 1))) .* (b * params(1) ./2) .* L_eff;
                % Optimizasyon seçeneklerini ayarla
                options = optimoptions('lsqcurvefit', 'Display', 'off');
                % İki parametre için optimizasyon döngüsü
                for initialGuessI = initialGuessRangeI
                    for initialGuessZ = initialGuessRangeZ
                        initialGuess = [initialGuessI, initialGuessZ];
                        % En uygun i ve z değerlerini bul
                        [optParams, resnorm] = lsqcurvefit(fitModel, initialGuess, x, y, [], [], options);
                        % En iyi sonucu kontrol et
                        if resnorm < bestResnorm
                            bestResnorm = resnorm;
                            bestParameters = optParams; % En uygun i ve z değerleri
                        end
                        updateWaitBar(app, hWaitBar);
                    end
                end
                % En uygun parametreleri al
                i = bestParameters(1);
                z = bestParameters(2);
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(bestParameters, x);
            elseif isnan(b) && ~isnan(i) && isnan(z)
                disp('Open Aperture: Durum 6: yalnızca i değeri var.');
                % b ve z için başlangıç tahmini aralıkları
                initialGuessRangeB = logspace(-10, 5, 100); % Örnek: 10^-10 ile 10^5 arasında logaritmik olarak 1000 farklı başlangıç noktası
                initialGuessRangeZ = logspace(-2, 2, 50); % Örnek: z için 10^-2 ile 10^2 arasında logaritmik olarak 50 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeB)*numel(initialGuessRangeZ);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                bestParameters = [NaN, NaN]; % En iyi b ve z değerlerini saklamak için
                fitModel = @(params, x) 1 - 2 * (((x./params(2)).^2 + 3) ./ (((x./params(2)).^2 + 9) .* ((x./params(2)).^2 + 1))) .* (params(1) * i ./2) .* L_eff;
                % Optimizasyon seçeneklerini ayarla
                options = optimoptions('lsqcurvefit', 'Display', 'off');
                % İki parametre için optimizasyon döngüsü
                for initialGuessB = initialGuessRangeB
                    for initialGuessZ = initialGuessRangeZ
                        initialGuess = [initialGuessB, initialGuessZ];
                        % En uygun b ve z değerlerini bul
                        [optParams, resnorm] = lsqcurvefit(fitModel, initialGuess, x, y, [], [], options);
                        % En iyi sonucu kontrol et
                        if resnorm < bestResnorm
                            bestResnorm = resnorm;
                            bestParameters = optParams; % En uygun b ve z değerleri
                        end
                        updateWaitBar(app, hWaitBar);
                    end
                end
                b = bestParameters(1);
                z = bestParameters(2);
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(bestParameters, x);
            elseif isnan(b) && isnan(i) && ~isnan(z)
                disp('Open Aperture: Durum 7: yalnızca z değeri var.');
                % b ve i için başlangıç tahmini aralıkları
                initialGuessRangeB = logspace(-10, 5, 70); % Örnek: 10^-10 ile 10^5 arasında logaritmik olarak 1000 farklı başlangıç noktası
                initialGuessRangeI = logspace(0, 10, 70); % Örnek: 10^0 ile 10^10 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeB)*numel(initialGuessRangeI);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                bestParameters = [NaN, NaN]; % En iyi b ve i değerlerini saklamak için
                fitModel = @(params, x) 1 - 2 * (((x./z).^2 + 3) ./ (((x./z).^2 + 9) .* ((x./z).^2 + 1))) .* (params(1) * params(2) ./2) .* L_eff;
                % Optimizasyon seçeneklerini ayarla
                options = optimoptions('lsqcurvefit', 'Display', 'off');
                % İki parametre için optimizasyon döngüsü
                for initialGuessB = initialGuessRangeB
                    for initialGuessI = initialGuessRangeI
                        initialGuess = [initialGuessB, initialGuessI];
                        % En uygun b ve i değerlerini bul
                        [optParams, resnorm] = lsqcurvefit(fitModel, initialGuess, x, y, [], [], options);
                        % En iyi sonucu kontrol et
                        if resnorm < bestResnorm
                            bestResnorm = resnorm;
                            bestParameters = optParams; % En uygun b ve i değerleri
                        end
                        updateWaitBar(app, hWaitBar);
                    end
                end
                % En uygun parametreleri al
                b = bestParameters(1);
                i = bestParameters(2);
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(bestParameters, x);
            else
                close(hWaitBar);
                disp('Open Aperture: Durum 8: b, i, ve z -> tümü NaN.');
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'At least one of the parameters b, i, or z must be provided for the fitting process.', 'Parameter Input Warning', 'Icon', 'warning');
                fitCheckBox.Value = false;
                return;
            end


            app.LeffValues(tabIndex) = L_eff;
            app.zValues(tabIndex) = z;
            app.iValues(tabIndex) = i;
            app.bValues(tabIndex) = b;

            fprintf('b: %s\n', mat2str(app.bValues));
            fprintf('i: %s\n', mat2str(app.iValues));
            fprintf('z: %s\n', mat2str(app.zValues));
            fprintf('leff: %s\n', mat2str(app.LeffValues));

            % Yeni fit edilmiş değerleri grafiğe çiz
            plot(graphEdited, x, y, 'ko', 'MarkerFaceColor', 'k','MarkerSize',app.MarkerSize1,'Tag', 'scatter'); % Orijinal verileri çiz
            hold(graphEdited, 'on'); % Grafik üzerine yeni çizimler yapabilmek için
            plot(graphEdited, x, Y_fit, 'r-', 'LineWidth', app.LineSize1, 'Tag', 'fit_line'); % Fit edilmiş modeli çiz
            hold(graphEdited, 'off'); % Grafik üzerindeki çizimleri bitir
            % Fonksiyon şimdi x ve Y_fit değerlerini döndürüyor
            close(hWaitBar);
        end

        % twoPhotonAbsorption fonksiyonu
        function [x, Y_fit] = twoPhotonAbsorption(app, x, y)

            % Seçili tab'ı belirle
            selectedTabTag = app.TabGroup.SelectedTab.Tag;
            tabIndex = str2double(regexp(selectedTabTag, '\d+', 'match'));
            % Seçili tab'a göre uygun son eki belirle (örn: '_1', '_2', ...)
            suffix = extractAfter(selectedTabTag, 'InputTab');
            % Kullanıcı girdilerinin kontrolü (dinamik component isimleri ile)
            editField_ITILB_TPA = app.(sprintf('EditField_ITILB_TPA_%s', suffix));
            editField_RL_TPA = app.(sprintf('EditField_RL_TPA_%s', suffix));
            checkBoxTPA1 = app.(sprintf('CheckBoxTPA1_%s', suffix));
            checkBoxTPA2 = app.(sprintf('CheckBoxTPA2_%s', suffix));
            checkBoxTPA3 = app.(sprintf('CheckBoxTPA3_%s', suffix));
            checkBoxTPA4 = app.(sprintf('CheckBoxTPA4_%s', suffix));
            checkBoxTPA6 = app.(sprintf('CheckBoxTPA6_%s', suffix));
            checkBoxTPA7 = app.(sprintf('CheckBoxTPA7_%s', suffix));
            checkBoxTPA8 = app.(sprintf('CheckBoxTPA8_%s', suffix));
            editField_NAC_TPA = app.(sprintf('EditField_NAC_TPA_%s', suffix));
            editField_W0_TPA = app.(sprintf('EditField_W0_TPA_%s', suffix));
            editField_Lambda_TPA = app.(sprintf('EditField_LAMBDA_TPA_%s', suffix));
            fitCheckBox = app.(sprintf('FitCheckBox_%s', suffix)); % Suffix kullanarak dinamik isim
            graphEdited = app.(sprintf('graphEdited_%s', suffix)); % Suffix kullanarak dinamik isim
            EditField_TTM_TPA = app.(sprintf('EditField_TTM_TPA_%s', suffix));
            EditField_alpha_TPA = app.(sprintf('EditField_alpha_TPA_%s', suffix));
            EditField_LAMBDA_TPA = app.(sprintf('EditField_LAMBDA_TPA_%s',suffix));
            EditField_W0_TPA = app.(sprintf('EditField_W0_TPA_%s',suffix));
            app.currentIteration = 0;

            % Kullanıcı girdilerinin kontrolü
            if checkBoxTPA7.Value
                i = editField_ITILB_TPA.Value;
            else
                i = NaN; % i değeri fit ile bulunacak
            end
            if checkBoxTPA6.Value
                z = editField_RL_TPA.Value;
            else
                z = NaN; % z değeri fit ile bulunacak
            end
            if checkBoxTPA8.Value
                b = editField_NAC_TPA.Value;
            else
                b = NaN; % b değeri fit ile bulunacak
            end
            if checkBoxTPA1.Value
                app.LValues(tabIndex) = EditField_TTM_TPA.Value;
            else
                app.LValues(tabIndex) = 0;
            end
            if checkBoxTPA3.Value
                app.alphaValues(tabIndex) = EditField_alpha_TPA.Value;
            else
                app.alphaValues(tabIndex) = 0;
            end
            if checkBoxTPA2.Value
                app.lambdaValues(tabIndex) = EditField_LAMBDA_TPA.Value;
            else
                app.lambdaValues(tabIndex) = 0;
            end
            if checkBoxTPA4.Value
                app.w0Values(tabIndex) = EditField_W0_TPA.Value;
            else
                app.w0Values(tabIndex) = 0;
            end
            if checkBoxTPA6.Value
                z = editField_RL_TPA.Value;
            else
                if checkBoxTPA4.Value && checkBoxTPA2.Value
                    w0 = editField_W0_TPA.Value;
                    lambda = editField_Lambda_TPA.Value;
                    % z'yi hesapla
                    z = (pi * w0^2) / lambda;
                elseif checkBoxTPA4.Value || checkBoxTPA2.Value % Sadece biri seçiliyse
                    % Uyarı mesajı göster
                    uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, ['Both w0 and λ values must be specified together for calculating Z. ' ...
                        'Please either select both options or deselect both.'], 'Selection Warning', 'Icon', 'warning');
                    fitCheckBox.Value = false;
                    return;
                else
                    z = NaN; % z değeri fit ile bulunacak
                end
            end
            try
                % L_eff değerinin hesaplanması
                L_eff = calculateL_eff(app); % L_eff hesaplayan fonksiyonu çağır ve değeri al
            
                % L_eff değerinin kontrolü
                if isempty(L_eff) || isnan(L_eff)
                    error('L_eff Calculation Error: L_eff is empty or NaN.');
                end
            catch ME
                % UI Alert mesajı göster
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'L_eff value could not be calculated. Please check the input values.', 'Calculation Warning', 'Icon', 'warning');
                    fitCheckBox.Value = false;
                % Fonksiyondan çıkış yap
                return;
            end

            if checkBoxTPA2.Value && checkBoxTPA6 .Value
                app.w0Values(tabIndex) = calculatew0(app);
            end

            hWaitBar = waitbar(0, 'Fitting in progress, please wait...', 'Name', 'Two Photon Absorption Fit', 'WindowStyle', 'modal');
            if ~isnan(b) && ~isnan(i) && ~isnan(z)
                disp('Two Photon Absorption: Durum 1: b, i, ve z değerleri var.');
                app.totalIterations = 1;
                % Y_fit hesaplayan anonim fonksiyon
                fitModel = @(b, i, z, x) 1 ./ (1 + (b * L_eff * (i ./ (1 + (x./z).^2))));
                % Belirli parametreler ile Y_fit hesapla
                Y_fit = fitModel(b, i, z, x);
                updateWaitBar(app, hWaitBar);
            elseif ~isnan(b) && ~isnan(i) && isnan(z)
                disp('Two Photon Absorption: Durum 2: b ve i değerleri var, z NaN.');
                % z için başlangıç tahmini aralığı
                initialGuessRangeZ = logspace(-5, 5, 100); % Örnek: 10^-5 ile 10^5 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeZ);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                for initialGuessZ = initialGuessRangeZ
                    % Y_fit hesaplayan anonim fonksiyon
                    fitModel = @(z, x) 1 ./ (1 + (b * L_eff * (i ./ (1 + (x./z).^2))));
                    % Optimizasyon seçeneklerini ayarla
                    options = optimoptions('lsqcurvefit', 'Display', 'off');
                    % En uygun z değerini bul
                    [z_opt, resnorm] = lsqcurvefit(@(z,x)fitModel(z,x), initialGuessZ, x, y, [], [], options);
                    % En iyi sonucu kontrol et
                    if resnorm < bestResnorm
                        bestResnorm = resnorm;
                        z = z_opt; % En uygun z değeri
                    end
                    updateWaitBar(app, hWaitBar);
                end
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(z, x);
            elseif ~isnan(b) && isnan(i) && ~isnan(z)
                disp('Two Photon Absorption: Durum 3: b ve z değerleri var, i NaN.');
                % i için başlangıç tahmini
                initialGuessRangeI = logspace(0, 10, 100); % Örnek: 10^0 ile 10^10 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeI);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                % Y_fit hesaplayan anonim fonksiyon
                for initialGuessI = initialGuessRangeI
                    fitModel = @(i, x) 1 ./ (1 + (b * L_eff * (i ./ (1 + (x./z).^2))));
                    % Optimizasyon seçeneklerini ayarla
                    options = optimoptions('lsqcurvefit', 'Display', 'off');
                    % En uygun i değerini bul
                    [i_opt, resnorm] = lsqcurvefit(fitModel, initialGuessI, x, y, [], [], options);
                    if resnorm < bestResnorm
                        bestResnorm = resnorm;
                        i = i_opt; % En uygun i değeri
                    end
                    updateWaitBar(app, hWaitBar);
                end
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(i, x);
            elseif isnan(b) && ~isnan(i) && ~isnan(z)
                disp('Two Photon Absorption: Durum 4: i ve z değerleri var, b NaN.');
                % b için başlangıç tahmini aralığı
                initialGuessRangeB = logspace(-10, 5, 1000); % Örnek: 10^-10 ile 10^5 arasında logaritmik olarak 1000 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeB);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                for initialGuessB = initialGuessRangeB
                    % Y_fit hesaplayan anonim fonksiyon
                    fitModel = @(b, x) 1 ./ (1 + (b * L_eff * (i ./ (1 + (x./z).^2))));
                    % Optimizasyon seçeneklerini ayarla
                    options = optimoptions('lsqcurvefit', 'Display', 'off');
                    % En uygun b değerini bul
                    [b_opt, resnorm] = lsqcurvefit(fitModel, initialGuessB, x, y, [], [], options);
                    % En iyi sonucu kontrol et
                    if resnorm < bestResnorm
                        bestResnorm = resnorm;
                        b = b_opt; % En uygun b değeri
                    end
                    updateWaitBar(app, hWaitBar);
                end
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(b, x);
            elseif ~isnan(b) && isnan(i) && isnan(z)
                disp('Two Photon Absorption: Durum 5: yalnızca b değeri var.');
                % i ve z için başlangıç tahmini aralıkları
                initialGuessRangeI = logspace(0, 10, 100); % Örnek: 10^0 ile 10^10 arasında logaritmik olarak 100 farklı başlangıç noktası
                initialGuessRangeZ = logspace(-2, 2, 50); % Örnek: 10^-5 ile 10^5 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeI)*numel(initialGuessRangeZ);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                bestParameters = [NaN, NaN]; % En iyi i ve z değerlerini saklamak için
                fitModel = @(params, x) 1 ./ (1 + (b* L_eff * (params(1) ./ (1 + (x./params(2)).^2))));
                % Optimizasyon seçeneklerini ayarla
                options = optimoptions('lsqcurvefit', 'Display', 'off');
                % İki parametre için optimizasyon döngüsü
                for initialGuessI = initialGuessRangeI
                    for initialGuessZ = initialGuessRangeZ
                        initialGuess = [initialGuessI, initialGuessZ];
                        % En uygun i ve z değerlerini bul
                        [optParams, resnorm] = lsqcurvefit(fitModel, initialGuess, x, y, [], [], options);
                        % En iyi sonucu kontrol et
                        if resnorm < bestResnorm
                            bestResnorm = resnorm;
                            bestParameters = optParams; % En uygun i ve z değerleri
                        end
                        updateWaitBar(app, hWaitBar);
                    end
                end
                % En uygun parametreleri al
                i = bestParameters(1);
                z = bestParameters(2);
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(bestParameters, x);
            elseif isnan(b) && ~isnan(i) && isnan(z)
                disp('Two Photon Absorption: Durum 6: yalnızca i değeri var.');
                % b ve z için başlangıç tahmini aralıkları
                initialGuessRangeB = logspace(-10, 5, 100); % Örnek: 10^-10 ile 10^5 arasında logaritmik olarak 1000 farklı başlangıç noktası
                initialGuessRangeZ = logspace(-2, 2, 50); % Örnek: z için 10^-2 ile 10^2 arasında logaritmik olarak 50 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeB)*numel(initialGuessRangeZ);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                bestParameters = [NaN, NaN]; % En iyi b ve z değerlerini saklamak için
                fitModel = @(params, x) 1 ./ (1 + (params(1)* L_eff * (i ./ (1 + (x./params(2)).^2))));
                % Optimizasyon seçeneklerini ayarla
                options = optimoptions('lsqcurvefit', 'Display', 'off');
                % İki parametre için optimizasyon döngüsü
                for initialGuessB = initialGuessRangeB
                    for initialGuessZ = initialGuessRangeZ
                        initialGuess = [initialGuessB, initialGuessZ];
                        % En uygun b ve z değerlerini bul
                        [optParams, resnorm] = lsqcurvefit(fitModel, initialGuess, x, y, [], [], options);
                        % En iyi sonucu kontrol et
                        if resnorm < bestResnorm
                            bestResnorm = resnorm;
                            bestParameters = optParams; % En uygun b ve z değerleri
                        end
                        updateWaitBar(app, hWaitBar);
                    end
                end
                b = bestParameters(1);
                z = bestParameters(2);
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(bestParameters, x);
            elseif isnan(b) && isnan(i) && ~isnan(z)
                disp('Two Photon Absorption: Durum 7: yalnızca z değeri var.');
                % b ve i için başlangıç tahmini aralıkları
                initialGuessRangeB = logspace(-10, 5, 70); % Örnek: 10^-10 ile 10^5 arasında logaritmik olarak 1000 farklı başlangıç noktası
                initialGuessRangeI = logspace(0, 10, 70); % Örnek: 10^0 ile 10^10 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeB)*numel(initialGuessRangeI);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                bestParameters = [NaN, NaN]; % En iyi b ve i değerlerini saklamak için
                fitModel = @(params, x) 1 ./ (1 + (params(1)* L_eff * (params(2) ./ (1 + (x./z).^2))));
                % Optimizasyon seçeneklerini ayarla
                options = optimoptions('lsqcurvefit', 'Display', 'off');
                % İki parametre için optimizasyon döngüsü
                for initialGuessB = initialGuessRangeB
                    for initialGuessI = initialGuessRangeI
                        initialGuess = [initialGuessB, initialGuessI];
                        % En uygun b ve i değerlerini bul
                        [optParams, resnorm] = lsqcurvefit(fitModel, initialGuess, x, y, [], [], options);
                        % En iyi sonucu kontrol et
                        if resnorm < bestResnorm
                            bestResnorm = resnorm;
                            bestParameters = optParams; % En uygun b ve i değerleri
                        end
                        updateWaitBar(app, hWaitBar);
                    end
                end
                % En uygun parametreleri al
                b = bestParameters(1);
                i = bestParameters(2);
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(bestParameters, x);
            else
                close(hWaitBar);
                disp('Two Photon Absorption: Durum 8: b, i, ve z -> tümü NaN.');
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'At least one of the parameters b, i, or z must be provided for the fitting process.', 'Parameter Input Warning', 'Icon', 'warning');
                fitCheckBox.Value = false;
                return;
            end
            
            app.LeffValues(tabIndex) = L_eff;
            app.zValues(tabIndex) = z;
            app.iValues(tabIndex) = i;
            app.bValues(tabIndex) = b;

            % Yeni fit edilmiş değerleri grafiğe çiz
            plot(graphEdited, x, y, 'ko', 'MarkerFaceColor', 'k','MarkerSize',app.MarkerSize1,'Tag', 'scatter'); % Orijinal verileri çiz
            hold(graphEdited, 'on'); % Grafik üzerine yeni çizimler yapabilmek için
            plot(graphEdited, x, Y_fit, 'r-', 'LineWidth', app.LineSize1, 'Tag', 'fit_line'); % Fit edilmiş modeli çiz
            hold(graphEdited, 'off'); % Grafik üzerindeki çizimleri bitir
            % Fonksiyon şimdi x ve Y_fit değerlerini döndürüyor
            close(hWaitBar);
        end

        % threePhotonAbsorption fonksiyonu
        function [x, Y_fit] = threePhotonAbsorption(app, x, y)

            % Seçili tab'ı belirle
            selectedTabTag = app.TabGroup.SelectedTab.Tag;
            tabIndex = str2double(regexp(selectedTabTag, '\d+', 'match'));
            % Seçili tab'a göre uygun son eki belirle (örn: '_1', '_2', ...)
            suffix = extractAfter(selectedTabTag, 'InputTab');
            % Kullanıcı girdilerinin kontrolü (dinamik component isimleri ile)
            editField_ITILB_ThPA = app.(sprintf('EditField_ITILB_ThPA_%s', suffix));
            editField_RL_ThPA = app.(sprintf('EditField_RL_ThPA_%s', suffix));
            checkBoxThPA1 = app.(sprintf('CheckBoxThPA1_%s', suffix));
            checkBoxThPA2 = app.(sprintf('CheckBoxThPA2_%s', suffix));
            checkBoxThPA3 = app.(sprintf('CheckBoxThPA3_%s', suffix));
            checkBoxThPA4 = app.(sprintf('CheckBoxThPA4_%s', suffix));
            checkBoxThPA6 = app.(sprintf('CheckBoxThPA6_%s', suffix));
            checkBoxThPA7 = app.(sprintf('CheckBoxThPA7_%s', suffix));
            checkBoxThPA8 = app.(sprintf('CheckBoxThPA8_%s', suffix));
            editField_NAC_ThPA = app.(sprintf('EditField_NAC_ThPA_%s', suffix));
            editField_W0_ThPA = app.(sprintf('EditField_W0_ThPA_%s', suffix));
            editField_Lambda_ThPA = app.(sprintf('EditField_LAMBDA_ThPA_%s', suffix));
            fitCheckBox = app.(sprintf('FitCheckBox_%s', suffix)); % Suffix kullanarak dinamik isim
            graphEdited = app.(sprintf('graphEdited_%s', suffix)); % Suffix kullanarak dinamik isim
            EditField_TTM_ThPA = app.(sprintf('EditField_TTM_ThPA_%s', suffix));
            EditField_alpha_ThPA = app.(sprintf('EditField_alpha_ThPA_%s', suffix));
            EditField_LAMBDA_ThPA = app.(sprintf('EditField_LAMBDA_ThPA_%s',suffix));
            EditField_W0_ThPA = app.(sprintf('EditField_W0_ThPA_%s',suffix));
            app.currentIteration = 0;

            % Kullanıcı girdilerinin kontrolü
            if checkBoxThPA7.Value
                i = editField_ITILB_ThPA.Value;
            else
                i = NaN; % i değeri fit ile bulunacak
            end
            if checkBoxThPA6.Value
                z = editField_RL_ThPA.Value;
            else
                z = NaN; % z değeri fit ile bulunacak
            end
            if checkBoxThPA8.Value
                b = editField_NAC_ThPA.Value;
            else
                b = NaN; % b değeri fit ile bulunacak
            end
            if checkBoxThPA1.Value
                app.LValues(tabIndex) = EditField_TTM_ThPA.Value;
            else
                app.LValues(tabIndex) = 0;
            end
            if checkBoxThPA3.Value
                app.alphaValues(tabIndex) = EditField_alpha_ThPA.Value;
            else
                app.alphaValues(tabIndex) = 0;
            end
            if checkBoxThPA2.Value
                app.lambdaValues(tabIndex) = EditField_LAMBDA_ThPA.Value;
            else
                app.lambdaValues(tabIndex) = 0;
            end
            if checkBoxThPA4.Value
                app.w0Values(tabIndex) = EditField_W0_ThPA.Value;
            else
                app.w0Values(tabIndex) = 0;
            end
            if checkBoxThPA6.Value
                z = editField_RL_ThPA.Value;
            else
                if checkBoxThPA4.Value && checkBoxThPA2.Value
                    w0 = editField_W0_ThPA.Value;
                    lambda = editField_Lambda_ThPA.Value;
                    % z'yi hesapla
                    z = (pi * w0^2) / lambda;
                elseif checkBoxThPA4.Value || checkBoxThPA2.Value % Sadece biri seçiliyse
                    % Uyarı mesajı göster
                    uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, ['Both w0 and λ values must be specified together for calculating Z. ' ...
                        'Please either select both options or deselect both.'], 'Selection Warning', 'Icon', 'warning');
                    fitCheckBox.Value = false;
                    return;
                else
                    z = NaN; % z değeri fit ile bulunacak
                end
            end
            try
                % L_eff değerinin hesaplanması
                L_eff = calculateL_eff(app); % L_eff hesaplayan fonksiyonu çağır ve değeri al
            
                % L_eff değerinin kontrolü
                if isempty(L_eff) || isnan(L_eff)
                    error('L_eff Calculation Error: L_eff is empty or NaN.');
                end
            catch ME
                % UI Alert mesajı göster
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'L_eff value could not be calculated. Please check the input values.', 'Calculation Warning', 'Icon', 'warning');
                    fitCheckBox.Value = false;
                % Fonksiyondan çıkış yap
                return;
            end

            if checkBoxThPA2.Value && checkBoxThPA6 .Value
                app.w0Values(tabIndex) = calculatew0(app);
            end

            hWaitBar = waitbar(0, 'Fitting in progress, please wait...', 'Name', 'Three Photon Absorption Fit', 'WindowStyle', 'modal');
            if ~isnan(b) && ~isnan(i) && ~isnan(z)
                disp('Three Photon Absorption: Durum 1: b, i, ve z değerleri var.');
                app.totalIterations = 1;
                % Y_fit hesaplayan anonim fonksiyon
                fitModel = @(b, i, z, x) 1 ./ sqrt(1 + (2*b * L_eff * (i ./ (1 + (x./z).^2)).^2));
                % Belirli parametreler ile Y_fit hesapla
                Y_fit = fitModel(b, i, z, x);
                updateWaitBar(app, hWaitBar);
            elseif ~isnan(b) && ~isnan(i) && isnan(z)
                disp('Three Photon Absorption: Durum 2: b ve i değerleri var, z NaN.');
                % z için başlangıç tahmini aralığı
                initialGuessRangeZ = logspace(-5, 5, 100); % Örnek: 10^-5 ile 10^5 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeZ);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                for initialGuessZ = initialGuessRangeZ
                    % Y_fit hesaplayan anonim fonksiyon
                    fitModel = @(z, x) 1 ./ sqrt(1 + (2*b * L_eff * (i ./ (1 + (x./z).^2)).^2));
                    % Optimizasyon seçeneklerini ayarla
                    options = optimoptions('lsqcurvefit', 'Display', 'off');
                    % En uygun z değerini bul
                    [z_opt, resnorm] = lsqcurvefit(@(z,x)fitModel(z,x), initialGuessZ, x, y, [], [], options);
                    % En iyi sonucu kontrol et
                    if resnorm < bestResnorm
                        bestResnorm = resnorm;
                        z = z_opt; % En uygun z değeri
                    end
                    updateWaitBar(app, hWaitBar);
                end
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(z, x);
            elseif ~isnan(b) && isnan(i) && ~isnan(z)
                disp('Three Photon Absorption: Durum 3: b ve z değerleri var, i NaN.');
                % i için başlangıç tahmini
                initialGuessRangeI = logspace(0, 10, 100); % Örnek: 10^0 ile 10^10 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeI);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                % Y_fit hesaplayan anonim fonksiyon
                for initialGuessI = initialGuessRangeI
                    fitModel = @(i, x) 1 ./ sqrt(1 + (2*b * L_eff * (i ./ (1 + (x./z).^2)).^2));
                    % Optimizasyon seçeneklerini ayarla
                    options = optimoptions('lsqcurvefit', 'Display', 'off');
                    % En uygun i değerini bul
                    [i_opt, resnorm] = lsqcurvefit(fitModel, initialGuessI, x, y, [], [], options);
                    if resnorm < bestResnorm
                        bestResnorm = resnorm;
                        i = i_opt; % En uygun i değeri
                    end
                    updateWaitBar(app, hWaitBar);
                end
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(i, x);
            elseif isnan(b) && ~isnan(i) && ~isnan(z)
                disp('Three Photon Absorption: Durum 4: i ve z değerleri var, b NaN.');
                % b için başlangıç tahmini aralığı
                initialGuessRangeB = logspace(-10, 5, 1000); % Örnek: 10^-10 ile 10^5 arasında logaritmik olarak 1000 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeB);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                for initialGuessB = initialGuessRangeB
                    % Y_fit hesaplayan anonim fonksiyon
                    fitModel = @(b, x) 1 ./ sqrt(1 + (2*b * L_eff * (i ./ (1 + (x./z).^2)).^2));
                    % Optimizasyon seçeneklerini ayarla
                    options = optimoptions('lsqcurvefit', 'Display', 'off');
                    % En uygun b değerini bul
                    [b_opt, resnorm] = lsqcurvefit(fitModel, initialGuessB, x, y, [], [], options);
                    % En iyi sonucu kontrol et
                    if resnorm < bestResnorm
                        bestResnorm = resnorm;
                        b = b_opt; % En uygun b değeri
                    end
                    updateWaitBar(app, hWaitBar);
                end
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(b, x);
            elseif ~isnan(b) && isnan(i) && isnan(z)
                disp('Three Photon Absorption: Durum 5: yalnızca b değeri var.');
                % i ve z için başlangıç tahmini aralıkları
                initialGuessRangeI = logspace(0, 10, 100); % Örnek: 10^0 ile 10^10 arasında logaritmik olarak 100 farklı başlangıç noktası
                initialGuessRangeZ = logspace(-2, 2, 50); % Örnek: 10^-5 ile 10^5 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeI)*numel(initialGuessRangeZ);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                bestParameters = [NaN, NaN]; % En iyi i ve z değerlerini saklamak için
                fitModel = @(params, x) 1 ./ sqrt(1 + (2*b* L_eff * (params(1) ./ (1 + (x./params(2)).^2).^2)));
                % Optimizasyon seçeneklerini ayarla
                options = optimoptions('lsqcurvefit', 'Display', 'off');
                % İki parametre için optimizasyon döngüsü
                for initialGuessI = initialGuessRangeI
                    for initialGuessZ = initialGuessRangeZ
                        initialGuess = [initialGuessI, initialGuessZ];
                        % En uygun i ve z değerlerini bul
                        [optParams, resnorm] = lsqcurvefit(fitModel, initialGuess, x, y, [], [], options);
                        % En iyi sonucu kontrol et
                        if resnorm < bestResnorm
                            bestResnorm = resnorm;
                            bestParameters = optParams; % En uygun i ve z değerleri
                        end
                        updateWaitBar(app, hWaitBar);
                    end
                end
                % En uygun parametreleri al
                i = bestParameters(1);
                z = bestParameters(2);
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(bestParameters, x);
            elseif isnan(b) && ~isnan(i) && isnan(z)
                disp('Three Photon Absorption: Durum 6: yalnızca i değeri var.');
                % b ve z için başlangıç tahmini aralıkları
                initialGuessRangeB = logspace(-10, 5, 100); % Örnek: 10^-10 ile 10^5 arasında logaritmik olarak 1000 farklı başlangıç noktası
                initialGuessRangeZ = logspace(-2, 2, 50); % Örnek: z için 10^-2 ile 10^2 arasında logaritmik olarak 50 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeB)*numel(initialGuessRangeZ);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                bestParameters = [NaN, NaN]; % En iyi b ve z değerlerini saklamak için
                fitModel = @(params, x) 1 ./ sqrt(1 + (2*params(1)* L_eff * (i ./ (1 + (x./params(2)).^2).^2)));
                % Optimizasyon seçeneklerini ayarla
                options = optimoptions('lsqcurvefit', 'Display', 'off');
                % İki parametre için optimizasyon döngüsü
                for initialGuessB = initialGuessRangeB
                    for initialGuessZ = initialGuessRangeZ
                        initialGuess = [initialGuessB, initialGuessZ];
                        % En uygun b ve z değerlerini bul
                        [optParams, resnorm] = lsqcurvefit(fitModel, initialGuess, x, y, [], [], options);
                        % En iyi sonucu kontrol et
                        if resnorm < bestResnorm
                            bestResnorm = resnorm;
                            bestParameters = optParams; % En uygun b ve z değerleri
                        end
                        updateWaitBar(app, hWaitBar);
                    end
                end
                b = bestParameters(1);
                z = bestParameters(2);
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(bestParameters, x);
            elseif isnan(b) && isnan(i) && ~isnan(z)
                disp('Three Photon Absorption: Durum 7: yalnızca z değeri var.');
                % b ve i için başlangıç tahmini aralıkları
                initialGuessRangeB = logspace(-10, 5, 70); % Örnek: 10^-10 ile 10^5 arasında logaritmik olarak 1000 farklı başlangıç noktası
                initialGuessRangeI = logspace(0, 10, 70); % Örnek: 10^0 ile 10^10 arasında logaritmik olarak 100 farklı başlangıç noktası
                app.totalIterations = numel(initialGuessRangeB)*numel(initialGuessRangeI);
                bestResnorm = inf; % En iyi rezidü normunu saklamak için
                bestParameters = [NaN, NaN]; % En iyi b ve i değerlerini saklamak için
                fitModel = @(params, x) 1 ./ sqrt(1 + (2*params(1)* L_eff * (params(2) ./ (1 + (x./z).^2).^2)));
                % Optimizasyon seçeneklerini ayarla
                options = optimoptions('lsqcurvefit', 'Display', 'off');
                % İki parametre için optimizasyon döngüsü
                for initialGuessB = initialGuessRangeB
                    for initialGuessI = initialGuessRangeI
                        initialGuess = [initialGuessB, initialGuessI];
                        % En uygun b ve i değerlerini bul
                        [optParams, resnorm] = lsqcurvefit(fitModel, initialGuess, x, y, [], [], options);
                        % En iyi sonucu kontrol et
                        if resnorm < bestResnorm
                            bestResnorm = resnorm;
                            bestParameters = optParams; % En uygun b ve i değerleri
                        end
                        updateWaitBar(app, hWaitBar);
                    end
                end
                % En uygun parametreleri al
                b = bestParameters(1);
                i = bestParameters(2);
                % En uygun parametreler ile Y_fit hesapla
                Y_fit = fitModel(bestParameters, x);
            else
                close(hWaitBar);
                disp('Three Photon Absorption: Durum 8: b, i, ve z -> tümü NaN.');
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'At least one of the parameters b, i, or z must be provided for the fitting process.', 'Parameter Input Warning', 'Icon', 'warning');
                fitCheckBox.Value = false;
                return;
            end
            
            app.LeffValues(tabIndex) = L_eff;
            app.zValues(tabIndex) = z;
            app.iValues(tabIndex) = i;
            app.bValues(tabIndex) = b;

            % Yeni fit edilmiş değerleri grafiğe çiz
            plot(graphEdited, x, y, 'ko', 'MarkerFaceColor', 'k','MarkerSize',app.MarkerSize1,'Tag', 'scatter'); % Orijinal verileri çiz
            hold(graphEdited, 'on'); % Grafik üzerine yeni çizimler yapabilmek için
            plot(graphEdited, x, Y_fit, 'r-', 'LineWidth', app.LineSize1, 'Tag', 'fit_line'); % Fit edilmiş modeli çiz
            hold(graphEdited, 'off'); % Grafik üzerindeki çizimleri bitir
            % Fonksiyon şimdi x ve Y_fit değerlerini döndürüyor
            close(hWaitBar);
        end
        
        function EditTable(app, event, activeTabTag)
            % Tab'a göre kontrol yap
            if strcmp(activeTabTag, 'InputTab1')
                dataTable = app.dataTable_1;
                dataTableEdited = app.dataTableEdited_1;
                DataSetterField1 = app.DataSetterField1_1;
                DataSetterField2 = app.DataSetterField2_1;
                DataSetterField3 = app.DataSetterField3_1;
                NormalisedCheckBox = app.NormalisedCheckBox_1;
            elseif strcmp(activeTabTag, 'InputTab2')
                dataTable = app.dataTable_2;
                dataTableEdited = app.dataTableEdited_2;
                DataSetterField1 = app.DataSetterField1_2;
                DataSetterField2 = app.DataSetterField2_2;
                DataSetterField3 = app.DataSetterField3_2;
                NormalisedCheckBox = app.NormalisedCheckBox_2;
            elseif strcmp(activeTabTag, 'InputTab3')
                dataTable = app.dataTable_3;
                dataTableEdited = app.dataTableEdited_3;
                DataSetterField1 = app.DataSetterField1_3;
                DataSetterField2 = app.DataSetterField2_3;
                DataSetterField3 = app.DataSetterField3_3;
                NormalisedCheckBox = app.NormalisedCheckBox_3;
            elseif strcmp(activeTabTag, 'InputTab4')
                dataTable = app.dataTable_4;
                dataTableEdited = app.dataTableEdited_4;
                DataSetterField1 = app.DataSetterField1_4;
                DataSetterField2 = app.DataSetterField2_4;
                DataSetterField3 = app.DataSetterField3_4;
                NormalisedCheckBox = app.NormalisedCheckBox_4;
            elseif strcmp(activeTabTag, 'InputTab5')
                dataTable = app.dataTable_5;
                dataTableEdited = app.dataTableEdited_5;
                DataSetterField1 = app.DataSetterField1_5;
                DataSetterField2 = app.DataSetterField2_5;
                DataSetterField3 = app.DataSetterField3_5;
                NormalisedCheckBox = app.NormalisedCheckBox_5;
            else
                % Eğer hiçbir şart sağlanmazsa uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Invalid tab selection.', 'Error');
                return;
            end
        
            % Original dataTable'dan verileri al
            originalData = dataTable.Data;
        
            % Verileri sayısal dizilere çevir
            xData = table2array(originalData(:, 1));
            yData = table2array(originalData(:, 2));
        
            % Kullanıcı tarafından belirlenen alt ve üst sınırları al
            altSinir = DataSetterField1.Value;
            ustSinir = DataSetterField2.Value;
        
            % Kullanıcı tarafından girilen çıkarılacak değeri al
            cikarilacakDeger = DataSetterField3.Value;
        
            % İlk kolondaki verileri filtrele ve çıkarılacak değeri uygula
            filtreliIndeksler = (xData > altSinir) & (xData < ustSinir);
            xDataFiltreli = xData(filtreliIndeksler) - cikarilacakDeger;
            yDataFiltreli = yData(filtreliIndeksler);
        
            % Eğer NormalisedCheckBox işaretlenmişse, intensity değerlerini normalize et
            if NormalisedCheckBox.Value
                yDataFiltreli = yDataFiltreli / max(yDataFiltreli);
            end
        
            % Düzenlenen verileri bir table olarak oluştur
            editedData = table(xDataFiltreli, yDataFiltreli, 'VariableNames', {'Z (mm)', 'Intensity'});
        
            % Düzenlenen verileri ikinci dataTable'a aktar
            dataTableEdited.Data = editedData;
        end

        function updateLegends(app, newHandle, newText)
            % Mevcut efsane bilgilerini al
            [legendHandles, ~, ~, ~] = legend(app.graphEdited_1);
            
            % Yeni efsaneyi mevcut listeye ekle
            newLegendHandles = [legendHandles; newHandle];
            newLegendTexts = [arrayfun(@(lh) lh.DisplayName, legendHandles, 'UniformOutput', false); newText];
            
            % Efsaneleri güncelle
            legend(app.graphEdited_1, newLegendHandles, newLegendTexts);
        end

        function clearTabContent(app, i)
            % Determine the tab based on the value of i
            switch i
                case 1
                    tab = app.Input1;
                case 2
                    tab = app.ClosedApertureZScanTab_1;
                case 3
                    tab = app.TabOutput3;
                otherwise
                    error('Invalid tab index.');
            end
            
            % Clear scatter plots
            scatterPlots = findobj(tab.Children, 'Type', 'scatter');
            delete(scatterPlots);
            
            % Clear line plots
            linePlots = findobj(tab.Children, 'Type', 'line');
            delete(linePlots);
            
            % Clear table data
            dataTableTag = ['dataTable' num2str(i)];
            dataTableEditedTag = ['dataTableEdited' num2str(i)];
            
            % Find and delete the dataTable object
            dataTable = findobj(tab.Children, 'Tag', dataTableTag);
            if ~isempty(dataTable)
                delete(dataTable);
            end
            
            % Find and delete the dataTableEdited object
            dataTableEdited = findobj(tab.Children, 'Tag', dataTableEditedTag);
            if ~isempty(dataTableEdited)
                delete(dataTableEdited);
            end
        end


        function selectedColor = getColorFromDropDown(app)
            % ColorDropDown'dan seçili olan rengi al
            switch app.ColorDropDown.Value
                case 'Blue'
                    selectedColor = [0 0 1]; % RGB kodu
                case 'Green'
                    selectedColor = [0 1 0]; % RGB kodu
                case 'Red'
                    selectedColor = [1 0 0]; % RGB kodu
                case 'Cyan'
                    selectedColor = [0 1 1]; % RGB kodu
                case 'Magenta'
                    selectedColor = [1 0 1]; % RGB kodu
                case 'Yellow'
                    selectedColor = [1 1 0]; % RGB kodu
                case 'Black'
                    selectedColor = [0 0 0]; % RGB kodu
                otherwise
                    selectedColor = [0 0 0]; % Varsayılan renk siyah
            end
        end

        function updateWaitBar(app, hWaitBar)
            % Mevcut iterasyonun artırılması
            app.currentIteration = app.currentIteration + 1;
            % İlerleme yüzdesinin hesaplanması
            progress = app.currentIteration / app.totalIterations;
            % İlerleme çubuğunun güncellenmesi
            waitbar(progress, hWaitBar, sprintf('Fitting in progress, please wait... %d%%', floor(progress*100)));
        end

        function saveValuesToTab(app, tabIndex, values)
            % Bu fonksiyon, tabIndex'e bağlı olarak doğru tab ve edit field'ı seçer
            % ve values dizisindeki değerleri sırasıyla ilgili edit field'lara kaydeder.
            
            % Her bir değeri ilgili edit field'a ata
            for fieldIndex = 1:length(values)
                % Dinamik field ismi oluştur
                fieldName = sprintf('Tab%d_EditField_%d', tabIndex, fieldIndex);
        
                % app objesinde bu isimde bir property var mı kontrol et
                if isprop(app, fieldName)
                    % Var ise, değeri set et
                    app.(fieldName).Value = values{fieldIndex};
                else
                    % Yok ise, uyarı ver
                    warning('%s is not a valid property of app.', fieldName);
                end
            end
         end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.totalIterations = 0;
        end

        % Button pushed function: ExcellImportButton_1, 
        % ...and 4 other components
        function ExcellImportButtonPushed(app, event)
            % Kullanıcıya dosya seçtir
            [file, path] = uigetfile({'*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'}, 'Select an Excel File');
            
            % Kullanıcı bir dosya seçtiyse, dosyayı oku
            if ~isequal(file, 0)
                % Dosyayı tam yolu ile birlikte oku
                fullPath = fullfile(path, file);
                dataTable = readtable(fullPath);
                
                selectedTab = app.TabGroup.SelectedTab; % Aktif tab'ı bul
                % Aktif tab'a göre verileri ilgili uitable'a yükle
                if selectedTab == app.Input1
                    app.dataTable_1.Data = dataTable;
                    delete(findobj(app.graphEdited_1.Children, 'Type', 'scatter'));
                    delete(findobj(app.graphEdited_1.Children, 'Type', 'line'));
                    app.dataTableEdited_1.Data = {};
                    app.dataTableFit_1.Data = {};
                    app.plotScatter();
                    axis(app.graphOriginal_1, 'tight');
                elseif selectedTab == app.Input2
                    app.dataTable_2.Data = dataTable;
                    delete(findobj(app.graphEdited_2.Children, 'Type', 'scatter'));
                    delete(findobj(app.graphEdited_2.Children, 'Type', 'line'));
                    cla(app.graphEdited_2);
                    app.dataTableEdited_2.Data = {};
                    app.dataTableFit_2.Data = {};
                    app.plotScatter();
                    axis(app.graphOriginal_2, 'tight');
                elseif selectedTab == app.Input3
                    app.dataTable_3.Data = dataTable;
                    delete(findobj(app.graphEdited_3.Children, 'Type', 'scatter'));
                    delete(findobj(app.graphEdited_3.Children, 'Type', 'line'));
                    cla(app.graphEdited_3);
                    app.dataTableEdited_3.Data = {};
                    app.dataTableFit_3.Data = {};
                    app.plotScatter();
                    axis(app.graphOriginal_3, 'tight');
                elseif selectedTab == app.Input4
                    app.dataTable_4.Data = dataTable;
                    delete(findobj(app.graphEdited_4.Children, 'Type', 'scatter'));
                    delete(findobj(app.graphEdited_4.Children, 'Type', 'line'));
                    cla(app.graphEdited_4);
                    app.dataTableEdited_4.Data = {};
                    app.dataTableFit_4.Data = {};
                    app.plotScatter();
                    axis(app.graphOriginal_4, 'tight');
                elseif selectedTab == app.Input5
                    app.dataTable_5.Data = dataTable;
                    delete(findobj(app.graphEdited_5.Children, 'Type', 'scatter'));
                    delete(findobj(app.graphEdited_5.Children, 'Type', 'line'));
                    cla(app.graphEdited_5);
                    app.dataTableEdited_5.Data = {};
                    app.dataTableFit_5.Data = {};
                    app.plotScatter();
                    axis(app.graphOriginal_5, 'tight');
                end
                
                % Dosya seçimi tamamlandıktan sonra MATLAB uygulamasını tekrar odak noktası yap
                figure(app.NonlinearOpticalZScanDataAnalysisUIFigure);
            end
        end

        % Cell edit callback: dataTable_1
        function dataTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.plotScatter();
        end

        % Value changed function: FitCheckBox_1, FitCheckBox_2, 
        % ...and 3 other components
        function FitCheckBoxValueChanged(app, event)
            % Seçili tab'ın tag'ını al
            selectedTabTag = app.TabGroup.SelectedTab.Tag;
            tabIndex = str2double(regexp(selectedTabTag, '\d+', 'match'));
            
            switch selectedTabTag
                case 'InputTab1'
                    fitCheckBox = app.FitCheckBox_1;
                    fittingMethods = app.fittingMethods_1;
                    dataTableEdited = app.dataTableEdited_1;
                    graphEdited = app.graphEdited_1;
                    dataTableFit = app.dataTableFit_1;
                    RsquareValueEditField = app.RsquareValueEditField_1;
                    app.Tab1_ListBox.Value = app.Units_ListBox_1.Value;
                    app.previousUnit{tabIndex} = app.Units_ListBox_1.Value;
                case 'InputTab2'
                    fitCheckBox = app.FitCheckBox_2;
                    fittingMethods = app.fittingMethods_2;
                    dataTableEdited = app.dataTableEdited_2;
                    graphEdited = app.graphEdited_2;
                    dataTableFit = app.dataTableFit_2;
                    RsquareValueEditField = app.RsquareValueEditField_2;
                    app.Tab2_ListBox.Value = app.Units_ListBox_2.Value;
                    app.previousUnit{tabIndex} = app.Units_ListBox_2.Value;
                case 'InputTab3'
                    fitCheckBox = app.FitCheckBox_3;
                    fittingMethods = app.fittingMethods_3;
                    dataTableEdited = app.dataTableEdited_3;
                    graphEdited = app.graphEdited_3;
                    dataTableFit = app.dataTableFit_3;
                    RsquareValueEditField = app.RsquareValueEditField_3;
                    app.Tab3_ListBox.Value = app.Units_ListBox_3.Value;
                    app.previousUnit{tabIndex} = app.Units_ListBox_3.Value;
                case 'InputTab4'
                    fitCheckBox = app.FitCheckBox_4;
                    fittingMethods = app.fittingMethods_4;
                    dataTableEdited = app.dataTableEdited_4;
                    graphEdited = app.graphEdited_4;
                    dataTableFit = app.dataTableFit_4;
                    RsquareValueEditField = app.RsquareValueEditField_4;
                    app.Tab4_ListBox.Value = app.Units_ListBox_4.Value;
                    app.previousUnit{tabIndex} = app.Units_ListBox_4.Value;
                case 'InputTab5'
                    fitCheckBox = app.FitCheckBox_5;
                    fittingMethods = app.fittingMethods_5;
                    dataTableEdited = app.dataTableEdited_5;
                    graphEdited = app.graphEdited_5;
                    dataTableFit = app.dataTableFit_5;
                    RsquareValueEditField = app.RsquareValueEditField_5;
                    app.Tab5_ListBox.Value = app.Units_ListBox_5.Value;
                    app.previousUnit{tabIndex} = app.Units_ListBox_5.Value;
                otherwise
                    % Beklenmeyen bir durumda, işlem yapma veya bir hata mesajı göster
                        uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Unexpected tab selected.', 'Error');
                    return;
            end
            % CheckBox seçiliyse ve fittingMethods'da bir seçim varsa fit işlemini gerçekleştir
            value = fitCheckBox.Value;
            if value
                % Eğer fittingMethods list box'ında bir seçim yapılmamışsa
                if isempty(fittingMethods.Value)
                    uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Please select a fitting method first.', 'Warning');
                    fitCheckBox.Value = false; % FitCheckBox'ı işaretsiz yap
                    return;
                else
                    % Veri var mı kontrol et
                    if isempty(dataTableEdited.Data)
                        uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'There is no data to fit.', 'No Data', 'Icon', 'warning'); % Kullanıcıya uyarı ver
                        fitCheckBox.Value = false; % CheckBox'ı işaretsiz yap
                        return;
                    else
                        try
                            data = dataTableEdited.Data; % Verileri hazırla
                            x = table2array(data(:, 1));
                            y = table2array(data(:, 2));
                            % fittingMethods'ta bir seçim varsa, fit işlemine devam et
                            switch fittingMethods.Value
                                case 'Open Aperture Z-Scan'
                                    [fitResultsX, fitResultsY] = app.openApertureFit(x, y);
                                case 'Closed Aperture Z-Scan'
                                    [fitResultsX, fitResultsY] = app.closedApertureFit(x, y);
                                case 'Two Photon Absorption'
                                    [fitResultsX, fitResultsY] = app.twoPhotonAbsorption(x, y);
                                case 'Three Photon Absorption'
                                    [fitResultsX, fitResultsY] = app.threePhotonAbsorption(x, y);
                            end
                            % Fit sonuçlarını dataTableFit'e yaz
                            fitResultsTable = table(fitResultsX, fitResultsY, 'VariableNames', {'Z (mm)', 'Intensity'});
                            dataTableFit.Data = fitResultsTable;
                            axis(graphEdited, 'tight');
                        catch ME
                            fitCheckBox.Value = false;
                            return; % Fonksiyonun geri kalanını çalıştırmamak için
                        end
                        editedData = dataTableEdited.Data;
                        y1Data = table2array(editedData(:, 2));
                        fitData = dataTableFit.Data;
                        y2Data = table2array(fitData(:, 2));
                        
                        N = length(y1Data); % Veri noktalarının sayısı
                        n = 1; % Burada sadece bir parametre olduğunu varsayıyorum, b
                        
                        SSE = sum((y1Data - y2Data).^2); % Hataların karelerinin toplamı
                        % Gözlemlenen değerlerin varyansı
                        SST = sum((y1Data - mean(y1Data)).^2);
                        % R-Square (COD) hesapla
                        R2 = 1 - (SSE/SST);
                        % Adjusted R-Square hesapla
                        AdjustedR2 = 1 - (1-R2)*(N-1)/(N-n-1);
                        % Reduced Chi-Square hesapla
                        % Standart sapma sigma varsayılan olarak 1 kabul edilir,
                        sigma = 1;
                        ReducedChiSq = (1/(N-n-1)) * (SSE/sigma^2);
                        % Konsola yazdır
                        app.R2Values(tabIndex) = R2;
                        app.AdjustedR2Values(tabIndex) = AdjustedR2;
                        app.ReducedChiSqValues(tabIndex) = ReducedChiSq;
                        fprintf('Reduced Chi-Sq: %s\n', mat2str(app.ReducedChiSqValues));
                        fprintf('R-Square (COD): %s\n', mat2str(app.R2Values));
                        fprintf('Adj. R-Square: %s\n', mat2str(app.AdjustedR2Values));
                        
                        % Hesaplanan değerleri numerik giriş alanlarına atayın (Varsayılan olarak RsquareValueEditField gibi)
                        RsquareValueEditField.Value = R2; % Benzer şekilde diğer değerler için de giriş alanları oluşturulabilir

                    end
                end
            else
                % CheckBox işaretli değilse, temizlik işlemleri
                delete(findobj(graphEdited.Children, 'Type', 'scatter')); % Scatter plotları sil
                delete(findobj(graphEdited.Children, 'Type', 'line')); % Line plotları sil
                RsquareValueEditField.Value = 0;
                app.R2Values(tabIndex) = 0;
                app.AdjustedR2Values(tabIndex) = 0;
                app.ReducedChiSqValues(tabIndex) = 0;
                app.LValues(tabIndex) = 0;
                app.lambdaValues(tabIndex) = 0;
                app.alphaValues(tabIndex) = 0;
                app.w0Values(tabIndex) = 0;
                app.LeffValues(tabIndex) = 0;
                app.zValues(tabIndex) = 0;
                app.iValues(tabIndex) = 0;
                app.bValues(tabIndex) = 0;
                dataTableFit.Data = {};


            end
            values = {app.LValues(tabIndex), app.lambdaValues(tabIndex), app.alphaValues(tabIndex), app.w0Values(tabIndex), app.LeffValues(tabIndex), app.zValues(tabIndex), app.iValues(tabIndex), app.bValues(tabIndex), app.dValues(tabIndex), app.ReducedChiSqValues(tabIndex), app.R2Values(tabIndex), app.AdjustedR2Values(tabIndex)};
            saveValuesToTab(app, tabIndex, values);
        end

        % Button pushed function: EditDataSetButton_1, 
        % ...and 4 other components
        function EditDataSetButtonPushed(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
        
            % Her tab için if-elseif yapısı kullanarak kontrol et
            if strcmp(activeTabTag, 'InputTab1')
                dataTable = app.dataTable_1;
                dataTableEdited = app.dataTableEdited_1;
                dataTableFit = app.dataTableFit_1;
                RsquareValueEditField = app.RsquareValueEditField_1;
                FitCheckBox = app.FitCheckBox_1;
                graphEdited = app.graphEdited_1;
            elseif strcmp(activeTabTag, 'InputTab2')
                dataTable = app.dataTable_2;
                dataTableEdited = app.dataTableEdited_2;
                dataTableFit = app.dataTableFit_2;
                RsquareValueEditField = app.RsquareValueEditField_2;
                FitCheckBox = app.FitCheckBox_2;
                graphEdited = app.graphEdited_2;
            elseif strcmp(activeTabTag, 'InputTab3')
                dataTable = app.dataTable_3;
                dataTableEdited = app.dataTableEdited_3;
                dataTableFit = app.dataTableFit_3;
                RsquareValueEditField = app.RsquareValueEditField_3;
                FitCheckBox = app.FitCheckBox_3;
                graphEdited = app.graphEdited_3;
            elseif strcmp(activeTabTag, 'InputTab4')
                dataTable = app.dataTable_4;
                dataTableEdited = app.dataTableEdited_4;
                dataTableFit = app.dataTableFit_4;
                RsquareValueEditField = app.RsquareValueEditField_4;
                FitCheckBox = app.FitCheckBox_4;
                graphEdited = app.graphEdited_4;
            elseif strcmp(activeTabTag, 'InputTab5')
                dataTable = app.dataTable_5;
                dataTableEdited = app.dataTableEdited_5;
                dataTableFit = app.dataTableFit_5;
                RsquareValueEditField = app.RsquareValueEditField_5;
                FitCheckBox = app.FitCheckBox_5;
                graphEdited = app.graphEdited_5;
            else
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'There is problem with Input Tag.', 'Warning');
            end
        
            % dataTable'da veri olup olmadığını kontrol et
            if isempty(dataTable.Data)
                % Veri yoksa kullanıcıyı uyar
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Please import data before editing.', 'Warning');
            else
                % Veri varsa, düzenleme işlemini gerçekleştir
                EditTable(app, event, activeTabTag); % activeTabTag parametresi ile çağır
                % FitCheckBox'ı false yap
                FitCheckBox.Value = false;
                % Grafiği temizle
                delete(findobj(graphEdited.Children, 'Type', 'scatter')); % Scatter plotları sil
                delete(findobj(graphEdited.Children, 'Type', 'line')); % Line plotları sil
                RsquareValueEditField.Value = 0; % R kare değerini sıfırla
                dataTableFit.Data = {}; % dataTable içindeki verileri temizle
            end
        end

        % Button pushed function: ExcellExportButton_1, 
        % ...and 4 other components
        function ExcellExportButtonPushed(app, event)
    % Seçili tab'ın Tag'ını ve başlığını belirle
    activeTab = app.TabGroup.SelectedTab;
    activeTabTag = activeTab.Tag;
    sheetName = activeTab.Title; % Excel Sheet adı için seçili tab'ın başlığını kullan
    
    % Tab'a göre kontrol yap ve ilgili veri tablolarını seç
    suffix = extractAfter(activeTabTag, 'InputTab');
    originalData = app.(sprintf('dataTable_%s', suffix)).Data;
    editedData = app.(sprintf('dataTableEdited_%s', suffix)).Data;
    fitData = app.(sprintf('dataTableFit_%s', suffix)).Data;
    
    % Veri var mı yok mu kontrol et
    if isempty(originalData) || isempty(editedData) || isempty(fitData)
        % Eğer veri yoksa, kullanıcıya uyarı ver
        uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'There is no data to export.', 'Warning', 'Icon', 'warning');
        return; % İşlemi sonlandır
    end
    
    % Dosya adı ve yolunu belirle
    [file, path] = uiputfile('*.xlsx', 'Save as Excel File');
    if isequal(file, 0)
        disp('User selected Cancel');
        return;
    end
    fullFilePath = fullfile(path, file);
    
    % Excel dosyasına yazma işlemleri
    try
        % Verileri Excel dosyasına yaz
        writetable(originalData, fullFilePath, 'Sheet', sheetName, 'Range', 'A1', 'WriteVariableNames', true);
        writetable(editedData, fullFilePath, 'Sheet', sheetName, 'Range', 'E1', 'WriteVariableNames', true);
        writetable(fitData, fullFilePath, 'Sheet', sheetName, 'Range', 'I1', 'WriteVariableNames', true);
        uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Data exported to Excel successfully.', 'Export Successful', 'Icon', 'success');
        disp('Data exported to Excel successfully.');
        figure(app.NonlinearOpticalZScanDataAnalysisUIFigure);
    catch ME
        uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Error exporting data to Excel. Please try again.', 'Export Error', 'Icon', 'error');
    end
        end

        % Menu selected function: YLabelEditMenu
        function YLabelEditMenuSelected(app, event)
            % Seçili tab'ın Tag'ını al
            selectedTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Seçili tab'a göre grafik nesnesini al (Tag'dan sonra gelen numarayı kullanarak)
            graph = app.(sprintf('graphEdited_%s', extractAfter(selectedTabTag, 'InputTab')));
            
            % Kullanıcıdan yeni y eksen başlığını alın
            newLabel = inputdlg('Enter a new label for the y-axis:', 'Edit Y-Axis Label', [1 50]);
            
            % Yeni bir başlık girildiyse
            if ~isempty(newLabel)
                % Yeni y eksen başlığını ayarlayın
                graph.YLabel.String = newLabel{1};
            end
        end

        % Menu selected function: XLabelEditMenu
        function XLabelEditMenuSelected(app, event)
            % Seçili tab'ın Tag'ını al
            selectedTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Seçili tab'a göre grafik nesnesini al
            graph = app.(sprintf('graphEdited_%s', extractAfter(selectedTabTag, 'InputTab')));
            
            % Kullanıcıdan yeni x eksen başlığını alın
            newLabel = inputdlg('Enter a new label for the x-axis:', 'Edit X-Axis Label', [1 50]);
            
            % Yeni bir başlık girildiyse
            if ~isempty(newLabel)
                % Yeni x eksen başlığını ayarlayın
                graph.XLabel.String = newLabel{1};
            end
        end

        % Menu selected function: TitleEditMenu
        function TitleEditMenuSelected(app, event)
            % Seçili tab'ın Tag'ını al
            selectedTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Seçili tab'a göre grafik nesnesini al
            graph = app.(sprintf('graphEdited_%s', extractAfter(selectedTabTag, 'InputTab')));
            
            % Kullanıcıdan yeni başlık metnini alın
            newTitle = inputdlg('Enter a new title for the plot:', 'Edit Plot Title', [1 50]);
            
            % Yeni bir başlık girildiyse
            if ~isempty(newTitle)
                % Yeni başlığı ayarlayın
                graph.Title.String = newTitle{1};
            end
        end

        % Button down function: Outputs
        function OutputsButtonDown(app, event)
            % Mevcut tab'ların isimlerini al
            tabNames = {app.TabGroup.Children.Title};
            
            % "Outputs" ismini çıkar
            tabNames = tabNames(~strcmp(tabNames, 'Outputs'));
            
            % Tab isimlerini butonların Text özelliklerine atayın
            for i = 1:min(length(tabNames), 5)
                buttonName = ['Button', num2str(i)];
                app.(buttonName).Text = tabNames{i};
            end
            
            % Diğer butonları boş bırak
            for i = length(tabNames)+1:5
                buttonName = ['Button', num2str(i)];
                app.(buttonName).Text = '';
            end
            
            % InputTreeCheckBox nesnelerinin Text özelliklerine de tab isimlerini atayın
            for i = 1:min(length(tabNames), 5)
                checkBoxName = ['InputTreeCheckBox', num2str(i)];
                app.(checkBoxName).Text = tabNames{i};
            end
            
            % Diğer InputTreeCheckBox nesnelerini boş bırak
            for i = length(tabNames)+1:5
                checkBoxName = ['InputTreeCheckBox', num2str(i)];
                app.(checkBoxName).Text = '';
            end
        
            % TabGroup3 altındaki TabOutput'ların Title'larını güncelle
            for i = 1:min(length(tabNames), 5)
                tabOutputName = ['TabOutput', num2str(i)];
                if isprop(app, tabOutputName) % Özellik varsa
                    app.(tabOutputName).Title = tabNames{i};
                end
            end
        end

        % Menu selected function: XLabelEditMenuOutputs
        function XLabelEditMenuOutputsSelected(app, event)
            % Kullanıcıdan yeni x eksen başlığını alın
            newLabel = inputdlg('Enter a new label for the x-axis:', 'Edit X-Axis Label', [1 50]);
            
            figure(app.NonlinearOpticalZScanDataAnalysisUIFigure); % app.NonlinearOpticalZScanDataAnalysisUIFigure, App Designer kullanıyorsanız GUI'nizin figürünü temsil eder

            % Yeni bir başlık girildiyse
            if ~isempty(newLabel)
                % Grafik nesnesini alın
                ax = app.graphOverall; % UIAxes grafiğin Axes nesnesini temsil eder, bu nesneyi kendi UIAxes adınıza göre güncelleyin
                
                % Yeni x eksen başlığını ayarlayın
                ax.XLabel.String = newLabel{1};
            end
        end

        % Menu selected function: YLabelEditMenuOutputs
        function YLabelEditMenuOutputsSelected(app, event)
            % Kullanıcıdan yeni y eksen başlığını alın
            newLabel = inputdlg('Enter a new label for the y-axis:', 'Edit Y-Axis Label', [1 50]);
            
            figure(app.NonlinearOpticalZScanDataAnalysisUIFigure); % app.NonlinearOpticalZScanDataAnalysisUIFigure, App Designer kullanıyorsanız GUI'nizin figürünü temsil eder

            % Yeni bir başlık girildiyse
            if ~isempty(newLabel)
                % Grafik nesnesini alın
                ax = app.graphOverall; % UIAxes grafiğin Axes nesnesini temsil eder, bu nesneyi kendi UIAxes adınıza göre güncelleyin
                
                % Yeni x eksen başlığını ayarlayın
                ax.YLabel.String = newLabel{1};
            end
        end

        % Menu selected function: TitleEditMenuOutputs
        function TitleEditMenuOutputsSelected(app, event)
            % Kullanıcıdan yeni başlık metnini alın
            newTitle = inputdlg('Enter a new title for the plot:', 'Edit Plot Title', [1 50]);
            
            figure(app.NonlinearOpticalZScanDataAnalysisUIFigure); % app.NonlinearOpticalZScanDataAnalysisUIFigure, App Designer kullanıyorsanız GUI'nizin figürünü temsil eder

            % Yeni bir başlık girildiyse
            if ~isempty(newTitle)
                % Grafik nesnesini alın (UIAxes buraya kendi grafik nesnenizin adı gelecek)
                ax = app.graphOverall;
                
                % Yeni başlığı ayarlayın
                ax.Title.String = newTitle{1};
            end
        end

        % Callback function: OutputTree
        function OutputTreeCheckedNodesChanged(app, event)
    % İşaretlenmiş düğümleri al
    checkedNodes = app.OutputTree.CheckedNodes;
    
    % Önce, önceden çizilmiş tüm grafikleri temizleyin
    cla(app.graphOverall); % app.graphOverall grafiğini temizle
    
    % Her bir işaretlenmiş düğüm için döngü
    for i = 1:length(checkedNodes)
        % İşaretlenmiş düğümün Tag'ini kontrol ederek uygun grafik çizimlerini yap
        nodeTag = checkedNodes(i).Tag;
        switch nodeTag
            case 'InputTreeCheckBox1'
                graphEdited = app.graphEdited_1;
            case 'InputTreeCheckBox2'
                graphEdited = app.graphEdited_2;
            case 'InputTreeCheckBox3'
                graphEdited = app.graphEdited_3;
            case 'InputTreeCheckBox4'
                graphEdited = app.graphEdited_4;
            case 'InputTreeCheckBox5'
                graphEdited = app.graphEdited_5;
        end
        
        % app.graphEdited_X nesnesinin çocuklarını al (X, seçilen checkbox'a göre değişir)
        children = graphEdited.Children;
        % scatter ve fit_line nesnelerini Tag özelliğine göre bul
        scatterChild = findobj(children, 'Type', 'line', 'Tag', 'scatter');
        fitChild = findobj(children, 'Type', 'line', 'Tag', 'fit_line');

        % Eğer çocuklar bulunamazsa, checkbox'ı false yap ve uyarı ver
        if isempty(fitChild) || isempty(scatterChild)
            % Checkbox'ı false yap
            %app.(nodeTag).Value = false;
            % Uyarı mesajı göster
            uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, sprintf('There is not suitable data for "%s".', nodeTag), 'No Data', 'Icon', 'warning');
        else
            % Verileri app.graphOverall'a çiz
            plot(app.graphOverall, fitChild.XData, fitChild.YData, 'r-', 'LineWidth', app.LineSize1, 'Tag', ['fitOverall', extractAfter(nodeTag, 'InputTreeCheckBox')], 'DisplayName', ['Fit Line ', extractAfter(nodeTag, 'InputTreeCheckBox')]); % fitData'yı çiz
            hold(app.graphOverall, 'on'); % Mevcut grafiği koru, üzerine çizim yapabilmek için
            scatter(app.graphOverall, scatterChild.XData, scatterChild.YData, 'o', 'MarkerFaceColor', 'r', 'SizeData', app.MarkerSize1, 'Tag', ['scatterOverall', extractAfter(nodeTag, 'InputTreeCheckBox')], 'DisplayName', ['Scatter Data ', extractAfter(nodeTag, 'InputTreeCheckBox')]); % scatterData'yı çiz
            % Legend'ı güncelle
            legend(app.graphOverall, 'Location', 'southwest', 'FontSize', 10);
        end
    end
    hold(app.graphOverall, 'off'); % Çizimi sonlandır ve mevcut grafiği korumayı durdur
        end

        % Button pushed function: ButtonColorEdit
        function ButtonColorEditPushed(app, event)
            % Hangi butonun seçili olduğunu kontrol et
            selectedButtonTag = app.ButtonGroup.SelectedObject.Tag;

            % Seçili renge göre plot rengini ayarla
            selectedColor = getColorFromDropDown(app);
        
            % Plot nesnelerini Tag değerlerine göre bul
            switch selectedButtonTag
                case 'InputButton1'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall1');
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall1');
                case 'InputButton2'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall2');
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall2');
                case 'InputButton3'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall3');
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall3');
                case 'InputButton4'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall4');
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall4');
                case 'InputButton5'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall5');
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall5');
                otherwise
                    error('Unknown button tag.');
            end

            % Bulunan plot nesnelerinin renklerini güncelle
            if ~isempty(fitPlot)
                fitPlot.Color = selectedColor;
            end
        
            if ~isempty(scatterPlot)
                scatterPlot.MarkerFaceColor = selectedColor;
                scatterPlot.MarkerEdgeColor = selectedColor; % Eğer çizgi rengini de değiştirmek isterseniz
            end
        end

        % Button pushed function: ButtonDotsSymbolsEdit
        function ButtonDotsSymbolsEditPushed(app, event)
            % Hangi butonun seçili olduğunu kontrol et
            selectedButtonTag = app.ButtonGroup.SelectedObject.Tag;
        
            % Seçili şekle göre scatter plot'ların sembolünü ayarla
            selectedShape = app.ShapeDropDown.Value;
    
            % Seçili şekle göre scatter plot'ların sembolünü ayarla
            switch selectedShape
                case 'Point'
                    newSymbol = '.'; % Point
                case 'Circle'
                    newSymbol = 'o'; % Circle
                case 'X-Mark'
                    newSymbol = 'x'; % X-Mark
                case 'Plus'
                    newSymbol = '+'; % Plus
                case 'Star'
                    newSymbol = 'p'; % Star (pentagram)
                case 'Square'
                    newSymbol = 's'; % Square
                case 'Diamond'
                    newSymbol = 'd'; % Diamond
                case 'Triangle (down)'
                    newSymbol = 'v'; % Triangle pointing down
                case 'Triangle (up)'
                    newSymbol = '^'; % Triangle pointing up
                case 'Pentagram'
                    newSymbol = 'p'; % Pentagram
                case 'Hexagram'
                    newSymbol = 'h'; % Hexagram
                otherwise
                    newSymbol = '.'; % Varsayılan olarak nokta kullan
            end
    
            % Seçili butona göre scatter plot'ların sembolünü ayarla
            switch selectedButtonTag
                case 'InputButton1'
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall1');
                    if ~isempty(scatterPlot)
                        scatterPlot.Marker = newSymbol;
                    end
                case 'InputButton2'
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall2');
                    if ~isempty(scatterPlot)
                        scatterPlot.Marker = newSymbol;
                    end
                case 'InputButton3'
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall3');
                    if ~isempty(scatterPlot)
                        scatterPlot.Marker = newSymbol;
                    end
                case 'InputButton4'
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall4');
                    if ~isempty(scatterPlot)
                        scatterPlot.Marker = newSymbol;
                    end
                case 'InputButton5'
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall5');
                    if ~isempty(scatterPlot)
                        scatterPlot.Marker = newSymbol;
                    end
                otherwise
                    uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Tanımsız buton.', 'Uyarı');
            end

        end

        % Button pushed function: ButtonDotsSizeEdit
        function ButtonDotsSizeEditPushed(app, event)
            % Kaydırıcıdan alınan değeri oku
            newSize = app.DotsSizeSlider.Value;
        
            % Hangi butonun seçili olduğunu kontrol et
            selectedButtonTag = app.ButtonGroup.SelectedObject.Tag;
        
            % Seçili butona göre scatter plot'ların nokta büyüklüğünü ayarla
            switch selectedButtonTag
                case 'InputButton1'
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall1');
                case 'InputButton2'
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall2');
                case 'InputButton3'
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall3');
                case 'InputButton4'
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall4');
                case 'InputButton5'
                    scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall5');
            end
        
            % Bulunan scatter plot'un nokta büyüklüğünü güncelle
            if ~isempty(scatterPlot)
                scatterPlot.SizeData = newSize; % MATLAB R2019b ve sonrasında
                % scatterPlot.MarkerSize = newSize; % MATLAB R2019a ve öncesinde
                axis(app.graphOriginal_1, 'tight');
            end
        end

        % Button pushed function: ButtonLineSizeEdit
        function ButtonLineSizeEditPushed(app, event)
            % Kaydırıcıdan alınan değeri oku
            newLineWidth = app.LineSizeSlider.Value;
        
            % Hangi butonun seçili olduğunu kontrol et
            selectedButtonTag = app.ButtonGroup.SelectedObject.Tag;
        
            % Seçili butona göre çizgi grafiğinin çizgi kalınlığını ayarla
            switch selectedButtonTag
                case 'InputButton1'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall1');
                case 'InputButton2'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall2');
                case 'InputButton3'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall3');
                case 'InputButton4'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall4');
                case 'InputButton5'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall5');
            end
        
            % Bulunan çizgi grafiğinin çizgi kalınlığını güncelle
            if ~isempty(fitPlot)
                fitPlot.LineWidth = newLineWidth;
            end
        end

        % Button pushed function: ButtonLineTypesEdit
        function ButtonLineTypesEditPushed(app, event)
            % app.ShapeDropDown2'den çizgi tipini oku
            selectedLineType = app.ShapeDropDown2.Value;
        
            % Seçili çizgi tipine göre çizgi grafiğinin çizgi tipini ayarla
            switch selectedLineType
                case 'Solid'
                    newLineStyle = '-'; % Solid line
                case 'Dotted'
                    newLineStyle = ':'; % Dotted line
                case 'Dashdot'
                    newLineStyle = '-.'; % Dash-dot line
                case 'Dashed'
                    newLineStyle = '--'; % Dashed line
                otherwise
                    newLineStyle = '-'; % Varsayılan olarak katı çizgi kullan
            end
        
            % Hangi butonun seçili olduğunu kontrol et
            selectedButtonTag = app.ButtonGroup.SelectedObject.Tag;
        
            % Seçili butona göre fitPlot'un çizgi tipini ayarla
            switch selectedButtonTag
                case 'InputButton1'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall1');
                case 'InputButton2'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall2');
                case 'InputButton3'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall3');
                case 'InputButton4'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall4');
                case 'InputButton5'
                    fitPlot = findobj(app.graphOverall, 'Tag', 'fitOverall5');
            end
                % Bulunan çizgi grafiğinin tipini güncelle
                if ~isempty(fitPlot)
                    fitPlot.LineStyle = newLineStyle;
                end
            
        end

        % Button pushed function: ButtonLegendClose
        function ButtonLegendClosePushed(app, event)
            % Kullanıcıya bir onay mesajı göster
            selection = uiconfirm(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Are you sure you want to close the legend?', ...
                'Confirmation', 'Options', {'Yes', 'No'}, ...
                'DefaultOption', 1, 'CancelOption', 2);
        
            % Kullanıcı 'Yes' seçeneğini seçerse legend'ı kapat
            if strcmp(selection, 'Yes')
                legend(app.graphOverall, 'off');
            end
%              Hangi butonun seçili olduğunu kontrol et
%             selectedButtonTag = app.ButtonGroup.SelectedObject.Tag;
%         
%             % Seçili butona göre scatter plot'ı bul
%             switch selectedButtonTag
%                 case 'InputButton1'
%                     scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall1');
%                 case 'InputButton2'
%                     scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall2');
%                 case 'InputButton3'
%                     scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall3');
%                 case 'InputButton4'
%                     scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall4');
%                 case 'InputButton5'
%                     scatterPlot = findobj(app.graphOverall, 'Tag', 'scatterOverall5');
%             end
% 
%             if ~isempty(scatterPlot)
%                 % scatter plot'un HandleVisibility özelliğini 'off' yap
%                 scatterPlot.HandleVisibility = 'off';
%                 % scatter plot'un DisplayName'ini boş yaparak legend'da
%                 % gözükmesini engelle
%                 scatterPlot.DisplayName = '';
%                 % Legendı güncelle
%                 legend(app.graphOverall, 'off'); % mevcut legend'ı kapat
%                 legend(app.graphOverall, '-DynamicLegend', 'Location', 'southwest'); % dynamic legend'ı etkinleştir
%             end
        end

        % Button pushed function: figureExport
        function figureExportButtonPushed(app, event)
            % Dosya adı için temel kısmı oluştur
            baseFileName = 'GraphOverall.tif';
            
            % Dosya kaydetme diyalog penceresini aç
            [file, path] = uiputfile([baseFileName], 'Save as');
            if isequal(file,0) || isequal(path,0)
                disp('User pressed cancel');
            else
                % Grafiği 600 DPI çözünürlükte TIF olarak kaydet
                filePath = fullfile(path, file);
                exportgraphics(app.graphOverall, filePath, 'Resolution', 600);
                disp(['Graph saved to ', filePath]);
            end
        end

        % Value changed function: CheckBoxOA2_1, CheckBoxOA2_2, 
        % ...and 3 other components
        function CheckBoxOA2ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
        
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            if strcmp(activeTabTag, 'InputTab1')
                CheckBoxOA6 = app.CheckBoxOA6_1;
                CheckBoxOA2 = app.CheckBoxOA2_1;
                EditField_LAMBDA_OA = app.EditField_LAMBDA_OA_1;
            elseif strcmp(activeTabTag, 'InputTab2')
                CheckBoxOA6 = app.CheckBoxOA6_2;
                CheckBoxOA2 = app.CheckBoxOA2_2;
                EditField_LAMBDA_OA = app.EditField_LAMBDA_OA_2;
            elseif strcmp(activeTabTag, 'InputTab3')
                CheckBoxOA6 = app.CheckBoxOA6_3;
                CheckBoxOA2 = app.CheckBoxOA2_3;
                EditField_LAMBDA_OA = app.EditField_LAMBDA_OA_3;
            elseif strcmp(activeTabTag, 'InputTab4')
                CheckBoxOA6 = app.CheckBoxOA6_4;
                CheckBoxOA2 = app.CheckBoxOA2_4;
                EditField_LAMBDA_OA = app.EditField_LAMBDA_OA_4;
            elseif strcmp(activeTabTag, 'InputTab5')
                CheckBoxOA6 = app.CheckBoxOA6_5;
                CheckBoxOA2 = app.CheckBoxOA2_5;
                EditField_LAMBDA_OA = app.EditField_LAMBDA_OA_5;
            else
                % Eğer hiçbir şart sağlanmazsa uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Invalid tab selection.', 'Error');
                return;
            end
        
            % CheckBoxOA6 seçiliyse  ---- Lambda'nın girilebildiği
            % senaryolar oluşturmak için burayı kapatıyorum.
%             if CheckBoxOA6.Value
%                 % Kullanıcıya uyarı ver
%                 uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Z is defined. λ cannot be defined.', 'Selection Error', 'Icon', 'warning');
%                 % CheckBoxOA2'i programatik olarak seçilmemiş olarak ayarla
%                 CheckBoxOA2.Value = false;
%                 return; % Fonksiyondan çık
%             end
            
            value = CheckBoxOA2.Value;
            % Checkbox seçiliyse
            if value
                EditField_LAMBDA_OA.Editable = true; % EditField_LAMBDA'yı düzenlenebilir yap
                EditField_LAMBDA_OA.BackgroundColor = [1.00, 1.00, 1.00]; % Arka plan rengini beyaz yap
            else
                EditField_LAMBDA_OA.Editable = false; % Değilse, düzenlenemez yap
                EditField_LAMBDA_OA.BackgroundColor = [0.80, 0.80, 0.80]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxOA8_1, CheckBoxOA8_2, 
        % ...and 3 other components
        function CheckBoxOA8ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
        
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            if strcmp(activeTabTag, 'InputTab1')
                CheckBoxOA8 = app.CheckBoxOA8_1;
                EditField_NAC_OA = app.EditField_NAC_OA_1;
            elseif strcmp(activeTabTag, 'InputTab2')
                CheckBoxOA8 = app.CheckBoxOA8_2;
                EditField_NAC_OA = app.EditField_NAC_OA_2;
            elseif strcmp(activeTabTag, 'InputTab3')
                CheckBoxOA8 = app.CheckBoxOA8_3;
                EditField_NAC_OA = app.EditField_NAC_OA_3;
            elseif strcmp(activeTabTag, 'InputTab4')
                CheckBoxOA8 = app.CheckBoxOA8_4;
                EditField_NAC_OA = app.EditField_NAC_OA_4;
            elseif strcmp(activeTabTag, 'InputTab5')
                CheckBoxOA8 = app.CheckBoxOA8_5;
                EditField_NAC_OA = app.EditField_NAC_OA_5;
            else
                % Eğer hiçbir şart sağlanmazsa uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Invalid tab selection.', 'Error');
                return;
            end
        
            value = CheckBoxOA8.Value;
            % Checkbox seçiliyse
            if value
                EditField_NAC_OA.Editable = true; % EditField_NAC'yi düzenlenebilir yap
                EditField_NAC_OA.BackgroundColor = [1.00, 1.00, 1.00]; % Arka plan rengini beyaz yap
            else
                EditField_NAC_OA.Editable = false; % Değilse, düzenlenemez yap
                EditField_NAC_OA.BackgroundColor = [0.80, 0.80, 0.80]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxOA1_1, CheckBoxOA1_2, 
        % ...and 3 other components
        function CheckBoxOA1ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
        
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            if strcmp(activeTabTag, 'InputTab1')
                CheckBoxOA5 = app.CheckBoxOA5_1;
                CheckBoxOA1 = app.CheckBoxOA1_1;
                EditField_TTM_OA = app.EditField_TTM_OA_1;
            elseif strcmp(activeTabTag, 'InputTab2')
                CheckBoxOA5 = app.CheckBoxOA5_2;
                CheckBoxOA1 = app.CheckBoxOA1_2;
                EditField_TTM_OA = app.EditField_TTM_OA_2;
            elseif strcmp(activeTabTag, 'InputTab3')
                CheckBoxOA5 = app.CheckBoxOA5_3;
                CheckBoxOA1 = app.CheckBoxOA1_3;
                EditField_TTM_OA = app.EditField_TTM_OA_3;
            elseif strcmp(activeTabTag, 'InputTab4')
                CheckBoxOA5 = app.CheckBoxOA5_4;
                CheckBoxOA1 = app.CheckBoxOA1_4;
                EditField_TTM_OA = app.EditField_TTM_OA_4;
            elseif strcmp(activeTabTag, 'InputTab5')
                CheckBoxOA5 = app.CheckBoxOA5_5;
                CheckBoxOA1 = app.CheckBoxOA1_5;
                EditField_TTM_OA = app.EditField_TTM_OA_5;
            else
                % Eğer hiçbir şart sağlanmazsa uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Invalid tab selection.', 'Error');
                return;
            end
        
            % CheckBoxOA5 seçiliyse
            if CheckBoxOA5.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Leff is defined. L cannot be defined', 'Selection Error', 'Icon', 'warning');
                % CheckBoxOA1'i programatik olarak seçilmemiş olarak ayarla
                CheckBoxOA1.Value = false;
                return; % Fonksiyondan çık
            end
        
            value = CheckBoxOA1.Value;
            % Checkbox seçiliyse
            if value
                EditField_TTM_OA.Editable = true; % EditField_TTM'yi düzenlenebilir yap
                EditField_TTM_OA.BackgroundColor = [1.00, 1.00, 1.00]; % Arka plan rengini beyaz yap
            else
                EditField_TTM_OA.Editable = false; % Değilse, düzenlenemez yap
                EditField_TTM_OA.BackgroundColor = [0.80, 0.80, 0.80]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxOA4_1, CheckBoxOA4_2, 
        % ...and 3 other components
        function CheckBoxOA4ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
        
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            if strcmp(activeTabTag, 'InputTab1')
                CheckBoxOA6 = app.CheckBoxOA6_1;
                CheckBoxOA4 = app.CheckBoxOA4_1;
                EditField_W0_OA = app.EditField_W0_OA_1;
            elseif strcmp(activeTabTag, 'InputTab2')
                CheckBoxOA6 = app.CheckBoxOA6_2;
                CheckBoxOA4 = app.CheckBoxOA4_2;
                EditField_W0_OA = app.EditField_W0_OA_2;
            elseif strcmp(activeTabTag, 'InputTab3')
                CheckBoxOA6 = app.CheckBoxOA6_3;
                CheckBoxOA4 = app.CheckBoxOA4_3;
                EditField_W0_OA = app.EditField_W0_OA_3;
            elseif strcmp(activeTabTag, 'InputTab4')
                CheckBoxOA6 = app.CheckBoxOA6_4;
                CheckBoxOA4 = app.CheckBoxOA4_4;
                EditField_W0_OA = app.EditField_W0_OA_4;
            elseif strcmp(activeTabTag, 'InputTab5')
                CheckBoxOA6 = app.CheckBoxOA6_5;
                CheckBoxOA4 = app.CheckBoxOA4_5;
                EditField_W0_OA = app.EditField_W0_OA_5;
            else
                % Eğer hiçbir şart sağlanmazsa uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Invalid tab selection.', 'Error');
                return;
            end
        
            % CheckBoxOA6 seçiliyse
            if CheckBoxOA6.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Z is defined. w0 cannot be defined.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxOA4'i programatik olarak seçilmemiş olarak ayarla
                CheckBoxOA4.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxOA4.Value;
            % Checkbox seçiliyse
            if value
                EditField_W0_OA.Editable = true; % EditField_W0'ı düzenlenebilir yap
                EditField_W0_OA.BackgroundColor = [1.00, 1.00, 1.00]; % Arka plan rengini beyaz yap
            else
                EditField_W0_OA.Editable = false; % Değilse, düzenlenemez yap
                EditField_W0_OA.BackgroundColor = [0.80, 0.80, 0.80]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxOA3_1, CheckBoxOA3_2, 
        % ...and 3 other components
        function CheckBoxOA3ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
        
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            if strcmp(activeTabTag, 'InputTab1')
                CheckBoxOA5 = app.CheckBoxOA5_1;
                CheckBoxOA3 = app.CheckBoxOA3_1;
                EditField_alpha_OA = app.EditField_alpha_OA_1;
            elseif strcmp(activeTabTag, 'InputTab2')
                CheckBoxOA5 = app.CheckBoxOA5_2;
                CheckBoxOA3 = app.CheckBoxOA3_2;
                EditField_alpha_OA = app.EditField_alpha_OA_2;
            elseif strcmp(activeTabTag, 'InputTab3')
                CheckBoxOA5 = app.CheckBoxOA5_3;
                CheckBoxOA3 = app.CheckBoxOA3_3;
                EditField_alpha_OA = app.EditField_alpha_OA_3;
            elseif strcmp(activeTabTag, 'InputTab4')
                CheckBoxOA5 = app.CheckBoxOA5_4;
                CheckBoxOA3 = app.CheckBoxOA3_4;
                EditField_alpha_OA = app.EditField_alpha_OA_4;
            elseif strcmp(activeTabTag, 'InputTab5')
                CheckBoxOA5 = app.CheckBoxOA5_5;
                CheckBoxOA3 = app.CheckBoxOA3_5;
                EditField_alpha_OA = app.EditField_alpha_OA_5;
            else
                % Eğer hiçbir şart sağlanmazsa uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Invalid tab selection.', 'Error');
                return;
            end
        
            % CheckBoxOA5 seçiliyse
            if CheckBoxOA5.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Leff is defined. α cannot be defined', 'Selection Error', 'Icon', 'warning');
                % CheckBoxOA3'i programatik olarak seçilmemiş olarak ayarla
                CheckBoxOA3.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxOA3.Value;
            % Checkbox seçiliyse
            if value
                EditField_alpha_OA.Editable = true; % EditField_alpha'yı düzenlenebilir yap
                EditField_alpha_OA.BackgroundColor = [1.00, 1.00, 1.00]; % Arka plan rengini beyaz yap
            else
                EditField_alpha_OA.Editable = false; % Değilse, düzenlenemez yap
                EditField_alpha_OA.BackgroundColor = [0.80, 0.80, 0.80]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxOA6_1, CheckBoxOA6_2, 
        % ...and 3 other components
        function CheckBoxOA6ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
        
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            if strcmp(activeTabTag, 'InputTab1')
                CheckBoxOA2 = app.CheckBoxOA2_1;
                CheckBoxOA4 = app.CheckBoxOA4_1;
                CheckBoxOA6 = app.CheckBoxOA6_1;
                EditField_RL_OA = app.EditField_RL_OA_1;
            elseif strcmp(activeTabTag, 'InputTab2')
                CheckBoxOA2 = app.CheckBoxOA2_2;
                CheckBoxOA4 = app.CheckBoxOA4_2;
                CheckBoxOA6 = app.CheckBoxOA6_2;
                EditField_RL_OA = app.EditField_RL_OA_2;
            elseif strcmp(activeTabTag, 'InputTab3')
                CheckBoxOA2 = app.CheckBoxOA2_3;
                CheckBoxOA4 = app.CheckBoxOA4_3;
                CheckBoxOA6 = app.CheckBoxOA6_3;
                EditField_RL_OA = app.EditField_RL_OA_3;
            elseif strcmp(activeTabTag, 'InputTab4')
                CheckBoxOA2 = app.CheckBoxOA2_4;
                CheckBoxOA4 = app.CheckBoxOA4_4;
                CheckBoxOA6 = app.CheckBoxOA6_4;
                EditField_RL_OA = app.EditField_RL_OA_4;
            elseif strcmp(activeTabTag, 'InputTab5')
                CheckBoxOA2 = app.CheckBoxOA2_5;
                CheckBoxOA4 = app.CheckBoxOA4_5;
                CheckBoxOA6 = app.CheckBoxOA6_5;
                EditField_RL_OA = app.EditField_RL_OA_5;
            else
                % Eğer hiçbir şart sağlanmazsa uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Invalid tab selection.', 'Error');
                return;
            end
        
            % CheckBoxOA4 seçiliyse
            if CheckBoxOA4.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'w0 is defined. Meantime, Z cannot be defined.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxOA6'ı programatik olarak seçilmemiş olarak ayarla
                CheckBoxOA6.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxOA6.Value;
            % Checkbox seçiliyse
            if value
                EditField_RL_OA.Editable = true; % EditField_RL'yi düzenlenebilir yap
                EditField_RL_OA.BackgroundColor = [1.00, 1.00, 1.00]; % Arka plan rengini beyaz yap
            else
                EditField_RL_OA.Editable = false; % Değilse, düzenlenemez yap
                EditField_RL_OA.BackgroundColor = [0.80, 0.80, 0.80]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxOA5_1, CheckBoxOA5_2, 
        % ...and 3 other components
        function CheckBoxOA5ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
        
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            if strcmp(activeTabTag, 'InputTab1')
                CheckBoxOA1 = app.CheckBoxOA1_1;
                CheckBoxOA3 = app.CheckBoxOA3_1;
                CheckBoxOA5 = app.CheckBoxOA5_1;
                EditField_Leff_OA = app.EditField_Leff_OA_1;
            elseif strcmp(activeTabTag, 'InputTab2')
                CheckBoxOA1 = app.CheckBoxOA1_2;
                CheckBoxOA3 = app.CheckBoxOA3_2;
                CheckBoxOA5 = app.CheckBoxOA5_2;
                EditField_Leff_OA = app.EditField_Leff_OA_2;
            elseif strcmp(activeTabTag, 'InputTab3')
                CheckBoxOA1 = app.CheckBoxOA1_3;
                CheckBoxOA3 = app.CheckBoxOA3_3;
                CheckBoxOA5 = app.CheckBoxOA5_3;
                EditField_Leff_OA = app.EditField_Leff_OA_3;
            elseif strcmp(activeTabTag, 'InputTab4')
                CheckBoxOA1 = app.CheckBoxOA1_4;
                CheckBoxOA3 = app.CheckBoxOA3_4;
                CheckBoxOA5 = app.CheckBoxOA5_4;
                EditField_Leff_OA = app.EditField_Leff_OA_4;
            elseif strcmp(activeTabTag, 'InputTab5')
                CheckBoxOA1 = app.CheckBoxOA1_5;
                CheckBoxOA3 = app.CheckBoxOA3_5;
                CheckBoxOA5 = app.CheckBoxOA5_5;
                EditField_Leff_OA = app.EditField_Leff_OA_5;
            else
                % Eğer hiçbir şart sağlanmazsa uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Invalid tab selection.', 'Error');
                return;
            end
        
            % CheckBoxOA1 veya CheckBoxOA3 seçiliyse
            if CheckBoxOA1.Value || CheckBoxOA3.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'L or α is defined. Meantime, Leff cannot be defined.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxOA5'i programatik olarak seçilmemiş olarak ayarla
                CheckBoxOA5.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxOA5.Value;
            % Checkbox seçiliyse
            if value
                EditField_Leff_OA.Editable = true; % EditField_Leff'yi düzenlenebilir yap
                EditField_Leff_OA.BackgroundColor = [1.00, 1.00, 1.00]; % Arka plan rengini beyaz yap
            else
                EditField_Leff_OA.Editable = false; % Değilse, düzenlenemez yap
                EditField_Leff_OA.BackgroundColor = [0.80, 0.80, 0.80]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxOA7_1, CheckBoxOA7_2, 
        % ...and 3 other components
        function CheckBoxOA7ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
        
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            if strcmp(activeTabTag, 'InputTab1')
                CheckBoxOA7 = app.CheckBoxOA7_1;
                EditField_ITILB_OA = app.EditField_ITILB_OA_1;
            elseif strcmp(activeTabTag, 'InputTab2')
                CheckBoxOA7 = app.CheckBoxOA7_2;
                EditField_ITILB_OA = app.EditField_ITILB_OA_2;
            elseif strcmp(activeTabTag, 'InputTab3')
                CheckBoxOA7 = app.CheckBoxOA7_3;
                EditField_ITILB_OA = app.EditField_ITILB_OA_3;
            elseif strcmp(activeTabTag, 'InputTab4')
                CheckBoxOA7 = app.CheckBoxOA7_4;
                EditField_ITILB_OA = app.EditField_ITILB_OA_4;
            elseif strcmp(activeTabTag, 'InputTab5')
                CheckBoxOA7 = app.CheckBoxOA7_5;
                EditField_ITILB_OA = app.EditField_ITILB_OA_5;
            else
                % Eğer hiçbir şart sağlanmazsa uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Invalid tab selection.', 'Error');
                return;
            end
        
            value = CheckBoxOA7.Value;
            % Checkbox seçiliyse
            if value
                EditField_ITILB_OA.Editable = true; % EditField_ITILB'yi düzenlenebilir yap
                EditField_ITILB_OA.BackgroundColor = [1.00, 1.00, 1.00]; % Arka plan rengini beyaz yap
            else
                EditField_ITILB_OA.Editable = false; % Değilse, düzenlenemez yap
                EditField_ITILB_OA.BackgroundColor = [0.80, 0.80, 0.80]; % Arka plan rengini gri yap
            end
        end

        % Button pushed function: DevelopedbyMuhammedBektaolu2024Button_1, 
        % ...and 4 other components
        function DevelopedbyButtonPushed(app, event)
            web('https://www.linkedin.com/in/muhammedbktsglu/','-browser');
        end

        % Value changed function: CheckBoxTPA1_1, CheckBoxTPA1_2, 
        % ...and 3 other components
        function CheckBoxTPA1ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxTPA5 = app.(sprintf('CheckBoxTPA5_%s', suffix));
            CheckBoxTPA1 = app.(sprintf('CheckBoxTPA1_%s', suffix));
            EditField_TTM_TPA = app.(sprintf('EditField_TTM_TPA_%s', suffix));
            
            % CheckBoxTPA5 seçiliyse
            if CheckBoxTPA5.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Leff is already defined. L cannot be defined additionally.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxTPA1'i programatik olarak seçilmemiş olarak ayarla
                CheckBoxTPA1.Value = false;
                return; % Fonksiyondan çık
            end
        
            value = CheckBoxTPA1.Value;
            % Checkbox seçiliyse
            if value
                EditField_TTM_TPA.Editable = true; % EditField_TTM_TPA'yı düzenlenebilir yap
                EditField_TTM_TPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_TTM_TPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_TTM_TPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxTPA2_1, CheckBoxTPA2_2, 
        % ...and 3 other components
        function CheckBoxTPA2ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxTPA6 = app.(sprintf('CheckBoxTPA6_%s', suffix));
            CheckBoxTPA2 = app.(sprintf('CheckBoxTPA2_%s', suffix));
            EditField_LAMBDA_TPA = app.(sprintf('EditField_LAMBDA_TPA_%s', suffix));
            
            value = CheckBoxTPA2.Value;
            % Checkbox seçiliyse
            if value
                EditField_LAMBDA_TPA.Editable = true; % EditField_LAMBDA_TPA'yı düzenlenebilir yap
                EditField_LAMBDA_TPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_LAMBDA_TPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_LAMBDA_TPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxTPA3_1, CheckBoxTPA3_2, 
        % ...and 3 other components
        function CheckBoxTPA3ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxTPA5 = app.(sprintf('CheckBoxTPA5_%s', suffix));
            CheckBoxTPA3 = app.(sprintf('CheckBoxTPA3_%s', suffix));
            EditField_alpha_TPA = app.(sprintf('EditField_alpha_TPA_%s', suffix));
            
            % CheckBoxTPA5 seçiliyse
            if CheckBoxTPA5.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Leff is already defined. α cannot be defined additionally.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxTPA3'ü programatik olarak seçilmemiş olarak ayarla
                CheckBoxTPA3.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxTPA3.Value;
            % Checkbox seçiliyse
            if value
                EditField_alpha_TPA.Editable = true; % EditField_alpha_TPA'yı düzenlenebilir yap
                EditField_alpha_TPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_alpha_TPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_alpha_TPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxTPA4_1, CheckBoxTPA4_2, 
        % ...and 3 other components
        function CheckBoxTPA4ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxTPA6 = app.(sprintf('CheckBoxTPA6_%s', suffix));
            CheckBoxTPA4 = app.(sprintf('CheckBoxTPA4_%s', suffix));
            EditField_W0_TPA = app.(sprintf('EditField_W0_TPA_%s', suffix));
            
            % CheckBoxTPA6 seçiliyse
            if CheckBoxTPA6.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Z is already defined. w0 cannot be defined additionally.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxTPA4'ü programatik olarak seçilmemiş olarak ayarla
                CheckBoxTPA4.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxTPA4.Value;
            % Checkbox seçiliyse
            if value
                EditField_W0_TPA.Editable = true; % EditField_W0_TPA'yı düzenlenebilir yap
                EditField_W0_TPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_W0_TPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_W0_TPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxTPA5_1, CheckBoxTPA5_2, 
        % ...and 3 other components
        function CheckBoxTPA5ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxTPA1 = app.(sprintf('CheckBoxTPA1_%s', suffix));
            CheckBoxTPA3 = app.(sprintf('CheckBoxTPA3_%s', suffix));
            CheckBoxTPA5 = app.(sprintf('CheckBoxTPA5_%s', suffix));
            EditField_Leff_TPA = app.(sprintf('EditField_Leff_TPA_%s', suffix));
            
            % CheckBoxTPA1 veya CheckBoxTPA3 seçiliyse
            if CheckBoxTPA1.Value || CheckBoxTPA3.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'L or α is defined. Meantime, Leff cannot be defined.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxTPA5'i programatik olarak seçilmemiş olarak ayarla
                CheckBoxTPA5.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxTPA5.Value;
            % Checkbox seçiliyse
            if value
                EditField_Leff_TPA.Editable = true; % EditField_Leff_TPA'yı düzenlenebilir yap
                EditField_Leff_TPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_Leff_TPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_Leff_TPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxTPA6_1, CheckBoxTPA6_2, 
        % ...and 3 other components
        function CheckBoxTPA6ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxTPA2 = app.(sprintf('CheckBoxTPA2_%s', suffix));
            CheckBoxTPA4 = app.(sprintf('CheckBoxTPA4_%s', suffix));
            CheckBoxTPA6 = app.(sprintf('CheckBoxTPA6_%s', suffix));
            EditField_RL_TPA = app.(sprintf('EditField_RL_TPA_%s', suffix));
            
            % CheckBoxTPA4 seçiliyse
            if CheckBoxTPA4.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'w0 is already defined. Meantime, Z cannot be defined.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxTPA6'ı programatik olarak seçilmemiş olarak ayarla
                CheckBoxTPA6.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxTPA6.Value;
            % Checkbox seçiliyse
            if value
                EditField_RL_TPA.Editable = true; % EditField_RL_TPA'yı düzenlenebilir yap
                EditField_RL_TPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_RL_TPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_RL_TPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxTPA7_1, CheckBoxTPA7_2, 
        % ...and 3 other components
        function CheckBoxTPA7ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxTPA7 = app.(sprintf('CheckBoxTPA7_%s', suffix));
            EditField_ITILB_TPA = app.(sprintf('EditField_ITILB_TPA_%s', suffix));
            
            value = CheckBoxTPA7.Value;
            % Checkbox seçiliyse
            if value
                EditField_ITILB_TPA.Editable = true; % EditField_ITILB_TPA'yı düzenlenebilir yap
                EditField_ITILB_TPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_ITILB_TPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_ITILB_TPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxTPA8_1, CheckBoxTPA8_2, 
        % ...and 3 other components
        function CheckBoxTPA8ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxTPA8 = app.(sprintf('CheckBoxTPA8_%s', suffix));
            EditField_NAC_TPA = app.(sprintf('EditField_NAC_TPA_%s', suffix));
            
            value = CheckBoxTPA8.Value;
            % Checkbox seçiliyse
            if value
                EditField_NAC_TPA.Editable = true; % EditField_NAC_TPA'yı düzenlenebilir yap
                EditField_NAC_TPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_NAC_TPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_NAC_TPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxThPA1_1, CheckBoxThPA1_2, 
        % ...and 3 other components
        function CheckBoxThPA1ValueChanged(app, event)
    % Aktif tab'ın Tag'ını belirle
    activeTabTag = app.TabGroup.SelectedTab.Tag;
    
    % Tab'a göre kontrol yap ve ilgili componentleri ayarla
    suffix = extractAfter(activeTabTag, 'InputTab');
    CheckBoxThPA5 = app.(sprintf('CheckBoxThPA5_%s', suffix));
    CheckBoxThPA1 = app.(sprintf('CheckBoxThPA1_%s', suffix));
    EditField_TTM_ThPA = app.(sprintf('EditField_TTM_ThPA_%s', suffix));
    
    % CheckBoxThPA5 seçiliyse
    if CheckBoxThPA5.Value
        % Kullanıcıya uyarı ver
        uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Leff is already defined. L cannot be defined additionally.', 'Selection Error', 'Icon', 'warning');
        % CheckBoxThPA1'i programatik olarak seçilmemiş olarak ayarla
        CheckBoxThPA1.Value = false;
        return; % Fonksiyondan çık
    end
    
    value = CheckBoxThPA1.Value;
    % Checkbox seçiliyse
    if value
        EditField_TTM_ThPA.Editable = true; % EditField_TTM_ThPA'yı düzenlenebilir yap
        EditField_TTM_ThPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
    else
        EditField_TTM_ThPA.Editable = false; % Değilse, düzenlenemez yap
        EditField_TTM_ThPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
    end
        end

        % Value changed function: CheckBoxThPA2_1, CheckBoxThPA2_2, 
        % ...and 3 other components
        function CheckBoxThPA2ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxThPA6 = app.(sprintf('CheckBoxThPA6_%s', suffix));
            CheckBoxThPA2 = app.(sprintf('CheckBoxThPA2_%s', suffix));
            EditField_LAMBDA_ThPA = app.(sprintf('EditField_LAMBDA_ThPA_%s', suffix));
            
            value = CheckBoxThPA2.Value;
            % Checkbox seçiliyse
            if value
                EditField_LAMBDA_ThPA.Editable = true; % EditField_LAMBDA_ThPA'yı düzenlenebilir yap
                EditField_LAMBDA_ThPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_LAMBDA_ThPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_LAMBDA_ThPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxThPA3_1, CheckBoxThPA3_2, 
        % ...and 3 other components
        function CheckBoxThPA3ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxThPA5 = app.(sprintf('CheckBoxThPA5_%s', suffix));
            CheckBoxThPA3 = app.(sprintf('CheckBoxThPA3_%s', suffix));
            EditField_alpha_ThPA = app.(sprintf('EditField_alpha_ThPA_%s', suffix));
            
            % CheckBoxThPA5 seçiliyse
            if CheckBoxThPA5.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Leff is already defined. α cannot be defined additionally.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxThPA3'ü programatik olarak seçilmemiş olarak ayarla
                CheckBoxThPA3.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxThPA3.Value;
            % Checkbox seçiliyse
            if value
                EditField_alpha_ThPA.Editable = true; % EditField_alpha_ThPA'yı düzenlenebilir yap
                EditField_alpha_ThPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_alpha_ThPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_alpha_ThPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxThPA4_1, CheckBoxThPA4_2, 
        % ...and 3 other components
        function CheckBoxThPA4ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxThPA6 = app.(sprintf('CheckBoxThPA6_%s', suffix));
            CheckBoxThPA4 = app.(sprintf('CheckBoxThPA4_%s', suffix));
            EditField_W0_ThPA = app.(sprintf('EditField_W0_ThPA_%s', suffix));
            
            % CheckBoxThPA6 seçiliyse
            if CheckBoxThPA6.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Z is already defined. w0 cannot be defined additionally.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxThPA4'ü programatik olarak seçilmemiş olarak ayarla
                CheckBoxThPA4.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxThPA4.Value;
            % Checkbox seçiliyse
            if value
                EditField_W0_ThPA.Editable = true; % EditField_W0_ThPA'yı düzenlenebilir yap
                EditField_W0_ThPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_W0_ThPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_W0_ThPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxThPA5_1, CheckBoxThPA5_2, 
        % ...and 3 other components
        function CheckBoxThPA5ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxThPA1 = app.(sprintf('CheckBoxThPA1_%s', suffix));
            CheckBoxThPA3 = app.(sprintf('CheckBoxThPA3_%s', suffix));
            CheckBoxThPA5 = app.(sprintf('CheckBoxThPA5_%s', suffix));
            EditField_Leff_ThPA = app.(sprintf('EditField_Leff_ThPA_%s', suffix));
            
            % CheckBoxThPA1 veya CheckBoxThPA3 seçiliyse
            if CheckBoxThPA1.Value || CheckBoxThPA3.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'L or α is already defined. Meantime, Leff cannot be defined additionally.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxThPA5'i programatik olarak seçilmemiş olarak ayarla
                CheckBoxThPA5.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxThPA5.Value;
            % Checkbox seçiliyse
            if value
                EditField_Leff_ThPA.Editable = true; % EditField_Leff_ThPA'yı düzenlenebilir yap
                EditField_Leff_ThPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_Leff_ThPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_Leff_ThPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxThPA6_1, CheckBoxThPA6_2, 
        % ...and 3 other components
        function CheckBoxThPA6ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxThPA2 = app.(sprintf('CheckBoxThPA2_%s', suffix));
            CheckBoxThPA4 = app.(sprintf('CheckBoxThPA4_%s', suffix));
            CheckBoxThPA6 = app.(sprintf('CheckBoxThPA6_%s', suffix));
            EditField_RL_ThPA = app.(sprintf('EditField_RL_ThPA_%s', suffix));
            
            % CheckBoxThPA4 seçiliyse
            if CheckBoxThPA4.Value
                % Kullanıcıya uyarı ver
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'w0 is already defined. Meantime, Z cannot be defined additionally.', 'Selection Error', 'Icon', 'warning');
                % CheckBoxThPA6'ı programatik olarak seçilmemiş olarak ayarla
                CheckBoxThPA6.Value = false;
                return; % Fonksiyondan çık
            end
            
            value = CheckBoxThPA6.Value;
            % Checkbox seçiliyse
            if value
                EditField_RL_ThPA.Editable = true; % EditField_RL_ThPA'yı düzenlenebilir yap
                EditField_RL_ThPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_RL_ThPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_RL_ThPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxThPA7_1, CheckBoxThPA7_2, 
        % ...and 3 other components
        function CheckBoxThPA7ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxThPA7 = app.(sprintf('CheckBoxThPA7_%s', suffix));
            EditField_ITILB_ThPA = app.(sprintf('EditField_ITILB_ThPA_%s', suffix));
            
            value = CheckBoxThPA7.Value;
            % Checkbox seçiliyse
            if value
                EditField_ITILB_ThPA.Editable = true; % EditField_ITILB_ThPA'yı düzenlenebilir yap
                EditField_ITILB_ThPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_ITILB_ThPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_ITILB_ThPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: CheckBoxThPA8_1, CheckBoxThPA8_2, 
        % ...and 3 other components
        function CheckBoxThPA8ValueChanged(app, event)
            % Aktif tab'ın Tag'ını belirle
            activeTabTag = app.TabGroup.SelectedTab.Tag;
            
            % Tab'a göre kontrol yap ve ilgili componentleri ayarla
            suffix = extractAfter(activeTabTag, 'InputTab');
            CheckBoxThPA8 = app.(sprintf('CheckBoxThPA8_%s', suffix));
            EditField_NAC_ThPA = app.(sprintf('EditField_NAC_ThPA_%s', suffix));
            
            value = CheckBoxThPA8.Value;
            % Checkbox seçiliyse
            if value
                EditField_NAC_ThPA.Editable = true; % EditField_NAC_ThPA'yı düzenlenebilir yap
                EditField_NAC_ThPA.BackgroundColor = [1, 1, 1]; % Arka plan rengini beyaz yap
            else
                EditField_NAC_ThPA.Editable = false; % Değilse, düzenlenemez yap
                EditField_NAC_ThPA.BackgroundColor = [0.8, 0.8, 0.8]; % Arka plan rengini gri yap
            end
        end

        % Value changed function: fittingMethods_1, fittingMethods_2, 
        % ...and 3 other components
        function fittingMethodsValueChanged(app, event)
            % Event'i tetikleyen fittingMethods componentini ve onun tag'ını al
            sourceComponent = event.Source;
            tagSuffix = extractAfter(sourceComponent.Tag, 'fittingMethods_');
            
            % Seçili tab'ı ve değeri belirle
            selectedValue = sourceComponent.Value;
            selectedTabGroupTag = sprintf('TabGroup2_%s', tagSuffix);
            
            % Bu fonksiyonun hangi tab group ve tab'ı etkileyeceğini dinamik olarak belirle
            switch selectedValue
                case 'Open Aperture Z-Scan'
                    selectedTab = app.(sprintf('OpenApertureZScanTab_%s', tagSuffix));
                case 'Closed Aperture Z-Scan'
                    selectedTab = app.(sprintf('ClosedApertureZScanTab_%s', tagSuffix));
                case 'Two Photon Absorption'
                    selectedTab = app.(sprintf('TwoPhotonAbsorptionTab_%s', tagSuffix));
                case 'Three Photon Absorption'
                    selectedTab = app.(sprintf('ThreePhotonAbsorptionTab_%s', tagSuffix));
                otherwise
                    % Eğer bir değer seçilmediyse, hiçbir şey yapma
                    return;
            end
            
            % Dinamik olarak belirlenen TabGroup ve Tab'ı seç
            app.(selectedTabGroupTag).SelectedTab = selectedTab;
        end

        % Button down function: Input1, Input2, Input3, Input4, Input5
        function InputButtonDown(app, event)
            persistent clickCount lastClickTime lastButtonClicked
            
            % Geçerli zamanı alın
            currentTime = datetime('now');
            
            % Butonun Tag'ini alın
            currentButtonClicked = event.Source.Tag;
        
            % İlk tıklama mı, son tıklamadan beri çok zaman geçti mi veya farklı bir buton mu tıklanmış kontrol edin
            if isempty(clickCount) || isempty(lastClickTime) || ...
               (currentTime - lastClickTime > seconds(1)) || ...
               (~strcmp(lastButtonClicked, currentButtonClicked))
                clickCount = 1;
                lastButtonClicked = currentButtonClicked;
            else
                clickCount = clickCount + 1;
            end
        
            % Son tıklama zamanını güncelleyin
            lastClickTime = currentTime;
        
            % İkinci tıklama mı kontrol edin
            if clickCount == 2
                % Yeni bir isim alın
                newTitle = inputdlg('Enter a new title for the tab:', 'Edit Tab Title', [1 50]);
                
                % Tıklama sayısını ve tıklanan butonu sıfırlayın
                clickCount = [];
                lastButtonClicked = '';
                
                if ~isempty(newTitle)
                    % Erişmek istediğiniz tab bileşenini bulun
                    tabComponent = findobj(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'Type', 'uitab', 'Tag', currentButtonClicked);
                    
                    % Tab'ın başlığını güncelleyin
                    if ~isempty(tabComponent) && ~isempty(newTitle{1})
                        tabComponent.Title = newTitle{1};
                    end
                else
                    % Kullanıcı 'Cancel' tıkladığında veya diyalog kutusunu kapattığında uygulamaya odaklan
                    figure(app.NonlinearOpticalZScanDataAnalysisUIFigure);
                end
            end
        end

        % Callback function: PushTool
        function UserManualToolClicked(app, event)
            web('https://github.com/Muhammedbktsglu/Nonlinear-Optical-Z-Scan-Analysis/blob/main/ThesisForProject.pdf', '-browser');
        end

        % Button pushed function: AllDataExport
        function AllDataExportButtonPushed(app, event)
            % Dosya adı ve yolunu belirle
            [file, path] = uiputfile('*.xlsx', 'Save as Excel File');
            if isequal(file, 0)
                disp('User selected Cancel');
                figure(app.NonlinearOpticalZScanDataAnalysisUIFigure);
                return;
            end
            fullFilePath = fullfile(path, file);
            
            % CheckedNodes listesini al
            checkedNodes = app.OutputTree.CheckedNodes;
            dataExportedSuccessfully = false;
            % İşaretli her düğüm için
            for i = 1:length(checkedNodes)
                % Düğümün adından hangi veri setlerinin kullanılacağını tespit et
                nodeTag = checkedNodes(i).Tag;
                
                % nodeTag değerine göre uygun veri setlerini al
                switch nodeTag
                    case 'InputTreeCheckBox1'
                        dataTable = app.dataTable_1;
                        dataTableEdited = app.dataTableEdited_1;
                        dataTableFit = app.dataTableFit_1;
                    case 'InputTreeCheckBox2'
                        dataTable = app.dataTable_2;
                        dataTableEdited = app.dataTableEdited_2;
                        dataTableFit = app.dataTableFit_2;
                    case 'InputTreeCheckBox3'
                        dataTable = app.dataTable_3;
                        dataTableEdited = app.dataTableEdited_3;
                        dataTableFit = app.dataTableFit_3;
                    case 'InputTreeCheckBox4'
                        dataTable = app.dataTable_4;
                        dataTableEdited = app.dataTableEdited_4;
                        dataTableFit = app.dataTableFit_4;
                    case 'InputTreeCheckBox5'
                        dataTable = app.dataTable_5;
                        dataTableEdited = app.dataTableEdited_5;
                        dataTableFit = app.dataTableFit_5;
                    otherwise
                        disp(['Unknown node: ', nodeTag]);
                        continue;
                end
                
                % Veri setlerini al (kodun geri kalanı)
                originalData = dataTable.Data;
                editedData = dataTableEdited.Data;
                fitData = dataTableFit.Data;
                
                % Veri var mı yok mu kontrol et
                if isempty(originalData) || isempty(editedData) || isempty(fitData)
                    disp(['No data to export for ', nodeTag]);
                    continue;
                end
                
                % Excel'e aktar
                sheetName = nodeTag; % veya 'Data ' + nodeTag gibi bir şey yapabilirsiniz
                writetable(originalData, fullFilePath, 'Sheet', sheetName, 'Range', 'A1');
                writetable(editedData, fullFilePath, 'Sheet', sheetName, 'Range', 'E1');
                writetable(fitData, fullFilePath, 'Sheet', sheetName, 'Range', 'I1');
                dataExportedSuccessfully = true;
                disp([nodeTag ' data exported successfully.']);
            end
            if ~dataExportedSuccessfully
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'No data was exported. Please check your selections and try again.', 'Export Failed', 'Icon', 'warning');
            else
                uialert(app.NonlinearOpticalZScanDataAnalysisUIFigure, 'All selected data was successfully exported to Excel.', 'Export Successful', 'Icon', 'success');
            end
            figure(app.NonlinearOpticalZScanDataAnalysisUIFigure);
        end

        % Value changed function: Tab1_ListBox, Tab2_ListBox, 
        % ...and 3 other components
        function OutputListBoxValueChanged(app, event)
            % Hangi tab'ın seçili olduğunu belirle
            selectedTabTag = app.TabGroup3.SelectedTab.Tag;
            tabIndex = str2double(regexp(selectedTabTag, '\d+', 'match'));
                    
            % Önceki ListBox değerini sakla
            ListBoxValue = app.(['Tab' num2str(tabIndex) '_ListBox']).Value;

            switch app.previousUnit{tabIndex}
                case 'centimeter'
                    switch ListBoxValue
                        case 'millimeter'
                            conversionFactor = 10; % 1 santimetre = 10 milimetre
                        case 'meter'
                            conversionFactor = 0.01; % 1 santimetre = 0.01 metre
                        otherwise
                            conversionFactor = 1; % Metre zaten metredir, dönüşüm yapmaya gerek yok
                    end
                case 'millimeter'
                    switch ListBoxValue
                        case 'centimeter'
                            conversionFactor = 0.1; % 1 milimetre = 0.1 santimetre
                        case 'meter'
                            conversionFactor = 0.001; % 1 milimetre = 0.001 metre
                        otherwise
                            conversionFactor = 1; % Metre zaten metredir, dönüşüm yapmaya gerek yok
                    end
                case 'meter'
                    switch ListBoxValue
                        case 'centimeter'
                            conversionFactor = 100; % 1 metre = 100 santimetre
                        case 'millimeter'
                            conversionFactor = 1000; % 1 metre = 1000 milimetre
                        otherwise
                            conversionFactor = 1; % Metre zaten metredir, dönüşüm yapmaya gerek yok
                    end
                otherwise
                    error('Invalid unit selected.');
            end
        
            % Hangi değerin değiştirileceğini belirle
            app.LValues(tabIndex) = app.LValues(tabIndex)*conversionFactor;
            app.lambdaValues(tabIndex) = app.lambdaValues(tabIndex)*conversionFactor;
            app.alphaValues(tabIndex) = app.alphaValues(tabIndex)*conversionFactor;
            app.w0Values(tabIndex) = app.w0Values(tabIndex)*conversionFactor;
            app.LeffValues(tabIndex) = app.LeffValues(tabIndex)*conversionFactor;
            app.zValues(tabIndex) = app.zValues(tabIndex)*conversionFactor;
            app.iValues(tabIndex) = app.iValues(tabIndex)*conversionFactor;
            app.bValues(tabIndex) = app.bValues(tabIndex)*conversionFactor;
            app.dValues(tabIndex) = app.dValues(tabIndex)*conversionFactor;
            values = {app.LValues(tabIndex), app.lambdaValues(tabIndex), app.alphaValues(tabIndex), app.w0Values(tabIndex), app.LeffValues(tabIndex), app.zValues(tabIndex), app.iValues(tabIndex), app.bValues(tabIndex), app.dValues(tabIndex), app.ReducedChiSqValues(tabIndex), app.R2Values(tabIndex), app.AdjustedR2Values(tabIndex)};
            saveValuesToTab(app, tabIndex, values);
            app.previousUnit{tabIndex} = ListBoxValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NonlinearOpticalZScanDataAnalysisUIFigure = uifigure('Visible', 'off');
            app.NonlinearOpticalZScanDataAnalysisUIFigure.Name = 'Nonlinear Optical Z-Scan Data Analysis';
            app.NonlinearOpticalZScanDataAnalysisUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'App_icon.png');
            app.Toolbar = uitoolbar(app.NonlinearOpticalZScanDataAnalysisUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Help'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @UserManualToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icons', 'question.png');
            app.TabGroup = uitabgroup(app.NonlinearOpticalZScanDataAnalysisUIFigure);
            app.Input1 = uitab(app.TabGroup);
            app.Input1.Title = 'Input 1';
            app.Input1.ButtonDownFcn = createCallbackFcn(app, @InputButtonDown, true);
            app.Input1.Tag = 'InputTab1';
            app.GridLayout_1 = uigridlayout(app.Input1);
            app.Panel_2 = uipanel(app.GridLayout_1);
            app.GridLayout4_1 = uigridlayout(app.Panel_2);
            app.graphOriginal_1 = uiaxes(app.GridLayout4_1);
            title(app.graphOriginal_1, 'Original Data')
            xlabel(app.graphOriginal_1, 'Z (mm)')
            ylabel(app.graphOriginal_1, 'Intensity')
            app.graphEdited_1 = uiaxes(app.GridLayout4_1);
            title(app.graphEdited_1, 'Edited Data')
            xlabel(app.graphEdited_1, 'Z (mm)')
            ylabel(app.graphEdited_1, 'Intensity')
            app.RsquareValueEditFieldLabel = uilabel(app.GridLayout4_1);
            app.RsquareValueEditFieldLabel.Text = 'R square Value :';
            app.RsquareValueEditField_1 = uieditfield(app.GridLayout4_1, 'numeric');
            app.RsquareValueEditField_1.Editable = 'off';
            app.Panel_1 = uipanel(app.GridLayout_1);
            app.GridLayout3_1 = uigridlayout(app.Panel_1);
            app.fittingMethods_1 = uilistbox(app.GridLayout3_1);
            app.fittingMethods_1.Items = {'', 'Open Aperture Z-Scan', 'Closed Aperture Z-Scan', 'Two Photon Absorption', 'Three Photon Absorption'};
            app.fittingMethods_1.ValueChangedFcn = createCallbackFcn(app, @fittingMethodsValueChanged, true);
            app.fittingMethods_1.Tag = 'fittingMethods_1';
            app.fittingMethods_1.Value = '';
            app.FittingmethodListBoxLabel_1 = uilabel(app.GridLayout3_1);
            app.FittingmethodListBoxLabel_1.Text = 'Fitting method';
            app.ParametersForFittingLabel_1 = uilabel(app.GridLayout3_1);
            app.ParametersForFittingLabel_1.Text = 'Parameters for Fitting';
            app.DevelopedbyMuhammedBektaolu2024Button_1 = uibutton(app.GridLayout3_1, 'push');
            app.DevelopedbyMuhammedBektaolu2024Button_1.ButtonPushedFcn = createCallbackFcn(app, @DevelopedbyButtonPushed, true);
            app.DevelopedbyMuhammedBektaolu2024Button_1.Text = {'Developed by Muhammed Bektaşoğlu'; '© 2024'};
            app.TabGroup2_1 = uitabgroup(app.GridLayout3_1);
            app.OpenApertureZScanTab_1 = uitab(app.TabGroup2_1);
            app.OpenApertureZScanTab_1.Title = 'Open Aperture Z-Scan';
            app.GridLayout5_1 = uigridlayout(app.OpenApertureZScanTab_1);
            app.LLabel = uilabel(app.GridLayout5_1);
            app.LLabel.Tooltip = {'Thickness of the material'};
            app.LLabel.Text = 'L :';
            app.Label_2 = uilabel(app.GridLayout5_1);
            app.Label_2.Tooltip = {'Wavelength of laser'};
            app.Label_2.Text = 'λ :';
            app.EditField_LAMBDA_OA_1 = uieditfield(app.GridLayout5_1, 'numeric');
            app.EditField_LAMBDA_OA_1.Editable = 'off';
            app.EditField_LAMBDA_OA_1.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_OA_1.Value = 532;
            app.Label_3 = uilabel(app.GridLayout5_1);
            app.Label_3.Tooltip = {'Linear absorption coefficient'};
            app.Label_3.Text = 'α :';
            app.EditField_alpha_OA_1 = uieditfield(app.GridLayout5_1, 'numeric');
            app.EditField_alpha_OA_1.Editable = 'off';
            app.EditField_alpha_OA_1.Tooltip = {'Linear absorption coefficient'};
            app.Label_4 = uilabel(app.GridLayout5_1);
            app.Label_4.Tooltip = {'Beam Waist'};
            app.Label_4.Text = 'w0 :';
            app.EditField_W0_OA_1 = uieditfield(app.GridLayout5_1, 'numeric');
            app.EditField_W0_OA_1.Editable = 'off';
            app.EditField_W0_OA_1.Tooltip = {'Beam Waist'};
            app.Label = uilabel(app.GridLayout5_1);
            app.Label.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label.Text = 'ß :';
            app.EditField_NAC_OA_1 = uieditfield(app.GridLayout5_1, 'numeric');
            app.EditField_NAC_OA_1.Editable = 'off';
            app.EditField_NAC_OA_1.Tooltip = {'Nonlinear absorption coefficient'};
            app.ZLabel = uilabel(app.GridLayout5_1);
            app.ZLabel.Tag = 'Rayleigh Range';
            app.ZLabel.Tooltip = {'Rayleigh length'};
            app.ZLabel.Text = 'Z :';
            app.EditField_RL_OA_1 = uieditfield(app.GridLayout5_1, 'numeric');
            app.EditField_RL_OA_1.Editable = 'off';
            app.EditField_RL_OA_1.Tooltip = {'Rayleigh length'};
            app.iLabel = uilabel(app.GridLayout5_1);
            app.iLabel.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel.Text = 'i :';
            app.EditField_ITILB_OA_1 = uieditfield(app.GridLayout5_1, 'numeric');
            app.EditField_ITILB_OA_1.Editable = 'off';
            app.EditField_ITILB_OA_1.Tooltip = {'Intensity of the incident laser beam'};
            app.Label_5 = uilabel(app.GridLayout5_1);
            app.Label_5.Tooltip = {'Effective Length of the Sample'};
            app.Label_5.Text = 'Leff :';
            app.EditField_Leff_OA_1 = uieditfield(app.GridLayout5_1, 'numeric');
            app.EditField_Leff_OA_1.Editable = 'off';
            app.EditField_Leff_OA_1.Tooltip = {'Effective Length of the Sample'};
            app.EditField_TTM_OA_1 = uieditfield(app.GridLayout5_1, 'numeric');
            app.EditField_TTM_OA_1.Editable = 'off';
            app.EditField_TTM_OA_1.Tooltip = {'Thickness of the material'};
            app.CheckBoxOA8_1 = uicheckbox(app.GridLayout5_1);
            app.CheckBoxOA8_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA8ValueChanged, true);
            app.CheckBoxOA8_1.Text = '';
            app.CheckBoxOA7_1 = uicheckbox(app.GridLayout5_1);
            app.CheckBoxOA7_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA7ValueChanged, true);
            app.CheckBoxOA7_1.Text = '';
            app.CheckBoxOA6_1 = uicheckbox(app.GridLayout5_1);
            app.CheckBoxOA6_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA6ValueChanged, true);
            app.CheckBoxOA6_1.Text = '';
            app.CheckBoxOA5_1 = uicheckbox(app.GridLayout5_1);
            app.CheckBoxOA5_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA5ValueChanged, true);
            app.CheckBoxOA5_1.Text = '';
            app.CheckBoxOA4_1 = uicheckbox(app.GridLayout5_1);
            app.CheckBoxOA4_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA4ValueChanged, true);
            app.CheckBoxOA4_1.Text = '';
            app.CheckBoxOA3_1 = uicheckbox(app.GridLayout5_1);
            app.CheckBoxOA3_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA3ValueChanged, true);
            app.CheckBoxOA3_1.Text = '';
            app.CheckBoxOA2_1 = uicheckbox(app.GridLayout5_1);
            app.CheckBoxOA2_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA2ValueChanged, true);
            app.CheckBoxOA2_1.Text = '';
            app.CheckBoxOA1_1 = uicheckbox(app.GridLayout5_1);
            app.CheckBoxOA1_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA1ValueChanged, true);
            app.CheckBoxOA1_1.Text = '';
            app.ClosedApertureZScanTab_1 = uitab(app.TabGroup2_1);
            app.ClosedApertureZScanTab_1.Title = 'Closed Aperture Z-Scan';
            app.GridLayout6_1 = uigridlayout(app.ClosedApertureZScanTab_1);
            app.Label_76 = uilabel(app.GridLayout6_1);
            app.Label_76.Tooltip = {'Wavelength of laser'};
            app.Label_76.Text = 'λ :';
            app.EditField_RL_CA_1 = uieditfield(app.GridLayout6_1, 'numeric');
            app.EditField_RL_CA_1.Editable = 'off';
            app.EditField_RL_CA_1.Tooltip = {'Rayleigh length'};
            app.EditField_W0_CA_1 = uieditfield(app.GridLayout6_1, 'numeric');
            app.EditField_W0_CA_1.Editable = 'off';
            app.EditField_W0_CA_1.Tooltip = {'Beam Waist'};
            app.EditField_LAMBDA_CA_1 = uieditfield(app.GridLayout6_1, 'numeric');
            app.EditField_LAMBDA_CA_1.Editable = 'off';
            app.EditField_LAMBDA_CA_1.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_CA_1.Value = 532;
            app.CheckBoxCA6_1 = uicheckbox(app.GridLayout6_1);
            app.CheckBoxCA6_1.Text = '';
            app.CheckBoxCA4_1 = uicheckbox(app.GridLayout6_1);
            app.CheckBoxCA4_1.Text = '';
            app.CheckBoxCA2_1 = uicheckbox(app.GridLayout6_1);
            app.CheckBoxCA2_1.Text = '';
            app.Label_77 = uilabel(app.GridLayout6_1);
            app.Label_77.Tooltip = {'Beam Waist'};
            app.Label_77.Text = 'w0 :';
            app.ZLabel_16 = uilabel(app.GridLayout6_1);
            app.ZLabel_16.Tag = 'Rayleigh Range';
            app.ZLabel_16.Tooltip = {'Rayleigh length'};
            app.ZLabel_16.Text = 'Z :';
            app.Label_86 = uilabel(app.GridLayout6_1);
            app.Label_86.Tooltip = {'Wavelength of laser'};
            app.Label_86.Text = 'D :';
            app.EditField_D_CA_1 = uieditfield(app.GridLayout6_1, 'numeric');
            app.EditField_D_CA_1.Editable = 'off';
            app.EditField_D_CA_1.Tooltip = {'Wavelength of laser'};
            app.CheckBoxCA6_6 = uicheckbox(app.GridLayout6_1);
            app.CheckBoxCA6_6.Text = '';
            app.TwoPhotonAbsorptionTab_1 = uitab(app.TabGroup2_1);
            app.TwoPhotonAbsorptionTab_1.Title = 'Two Photon Absorption';
            app.GridLayout7_1 = uigridlayout(app.TwoPhotonAbsorptionTab_1);
            app.CheckBoxTPA8_1 = uicheckbox(app.GridLayout7_1);
            app.CheckBoxTPA8_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA8ValueChanged, true);
            app.CheckBoxTPA8_1.Text = '';
            app.CheckBoxTPA7_1 = uicheckbox(app.GridLayout7_1);
            app.CheckBoxTPA7_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA7ValueChanged, true);
            app.CheckBoxTPA7_1.Text = '';
            app.CheckBoxTPA6_1 = uicheckbox(app.GridLayout7_1);
            app.CheckBoxTPA6_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA6ValueChanged, true);
            app.CheckBoxTPA6_1.Text = '';
            app.CheckBoxTPA5_1 = uicheckbox(app.GridLayout7_1);
            app.CheckBoxTPA5_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA5ValueChanged, true);
            app.CheckBoxTPA5_1.Text = '';
            app.CheckBoxTPA4_1 = uicheckbox(app.GridLayout7_1);
            app.CheckBoxTPA4_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA4ValueChanged, true);
            app.CheckBoxTPA4_1.Text = '';
            app.CheckBoxTPA3_1 = uicheckbox(app.GridLayout7_1);
            app.CheckBoxTPA3_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA3ValueChanged, true);
            app.CheckBoxTPA3_1.Text = '';
            app.CheckBoxTPA2_1 = uicheckbox(app.GridLayout7_1);
            app.CheckBoxTPA2_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA2ValueChanged, true);
            app.CheckBoxTPA2_1.Text = '';
            app.CheckBoxTPA1_1 = uicheckbox(app.GridLayout7_1);
            app.CheckBoxTPA1_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA1ValueChanged, true);
            app.CheckBoxTPA1_1.Text = '';
            app.EditField_NAC_TPA_1 = uieditfield(app.GridLayout7_1, 'numeric');
            app.EditField_NAC_TPA_1.Editable = 'off';
            app.EditField_NAC_TPA_1.Tooltip = {'Nonlinear absorption coefficient'};
            app.EditField_ITILB_TPA_1 = uieditfield(app.GridLayout7_1, 'numeric');
            app.EditField_ITILB_TPA_1.Editable = 'off';
            app.EditField_ITILB_TPA_1.Tooltip = {'Intensity of the incident laser beam'};
            app.Label_6 = uilabel(app.GridLayout7_1);
            app.Label_6.Tooltip = {'Effective Length of the Sample'};
            app.Label_6.Text = 'Leff :';
            app.Label_7 = uilabel(app.GridLayout7_1);
            app.Label_7.Tooltip = {'Linear absorption coefficient'};
            app.Label_7.Text = 'α :';
            app.EditField_RL_TPA_1 = uieditfield(app.GridLayout7_1, 'numeric');
            app.EditField_RL_TPA_1.Editable = 'off';
            app.EditField_RL_TPA_1.Tooltip = {'Rayleigh length'};
            app.EditField_Leff_TPA_1 = uieditfield(app.GridLayout7_1, 'numeric');
            app.EditField_Leff_TPA_1.Editable = 'off';
            app.EditField_Leff_TPA_1.Tooltip = {'Effective Length of the Sample'};
            app.EditField_W0_TPA_1 = uieditfield(app.GridLayout7_1, 'numeric');
            app.EditField_W0_TPA_1.Editable = 'off';
            app.EditField_W0_TPA_1.Tooltip = {'Beam Waist'};
            app.EditField_alpha_TPA_1 = uieditfield(app.GridLayout7_1, 'numeric');
            app.EditField_alpha_TPA_1.Editable = 'off';
            app.EditField_alpha_TPA_1.Tooltip = {'Linear absorption coefficient'};
            app.Label_8 = uilabel(app.GridLayout7_1);
            app.Label_8.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_8.Text = 'ß :';
            app.EditField_LAMBDA_TPA_1 = uieditfield(app.GridLayout7_1, 'numeric');
            app.EditField_LAMBDA_TPA_1.Editable = 'off';
            app.EditField_LAMBDA_TPA_1.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_TPA_1.Value = 532;
            app.LLabel_2 = uilabel(app.GridLayout7_1);
            app.LLabel_2.Tooltip = {'Thickness of the material'};
            app.LLabel_2.Text = 'L :';
            app.EditField_TTM_TPA_1 = uieditfield(app.GridLayout7_1, 'numeric');
            app.EditField_TTM_TPA_1.Editable = 'off';
            app.EditField_TTM_TPA_1.Tooltip = {'Thickness of the material'};
            app.iLabel_2 = uilabel(app.GridLayout7_1);
            app.iLabel_2.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_2.Text = 'i :';
            app.Label_9 = uilabel(app.GridLayout7_1);
            app.Label_9.Tooltip = {'Wavelength of laser'};
            app.Label_9.Text = 'λ :';
            app.Label_10 = uilabel(app.GridLayout7_1);
            app.Label_10.Tooltip = {'Beam Waist'};
            app.Label_10.Text = 'w0 :';
            app.ZLabel_2 = uilabel(app.GridLayout7_1);
            app.ZLabel_2.Tag = 'Rayleigh Range';
            app.ZLabel_2.Tooltip = {'Rayleigh length'};
            app.ZLabel_2.Text = 'Z :';
            app.ThreePhotonAbsorptionTab_1 = uitab(app.TabGroup2_1);
            app.ThreePhotonAbsorptionTab_1.Title = 'Three Photon Absorption';
            app.GridLayout8_1 = uigridlayout(app.ThreePhotonAbsorptionTab_1);
            app.CheckBoxThPA8_1 = uicheckbox(app.GridLayout8_1);
            app.CheckBoxThPA8_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA8ValueChanged, true);
            app.CheckBoxThPA8_1.Text = '';
            app.CheckBoxThPA7_1 = uicheckbox(app.GridLayout8_1);
            app.CheckBoxThPA7_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA7ValueChanged, true);
            app.CheckBoxThPA7_1.Text = '';
            app.CheckBoxThPA6_1 = uicheckbox(app.GridLayout8_1);
            app.CheckBoxThPA6_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA6ValueChanged, true);
            app.CheckBoxThPA6_1.Text = '';
            app.CheckBoxThPA5_1 = uicheckbox(app.GridLayout8_1);
            app.CheckBoxThPA5_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA5ValueChanged, true);
            app.CheckBoxThPA5_1.Text = '';
            app.CheckBoxThPA4_1 = uicheckbox(app.GridLayout8_1);
            app.CheckBoxThPA4_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA4ValueChanged, true);
            app.CheckBoxThPA4_1.Text = '';
            app.CheckBoxThPA3_1 = uicheckbox(app.GridLayout8_1);
            app.CheckBoxThPA3_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA3ValueChanged, true);
            app.CheckBoxThPA3_1.Text = '';
            app.CheckBoxThPA2_1 = uicheckbox(app.GridLayout8_1);
            app.CheckBoxThPA2_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA2ValueChanged, true);
            app.CheckBoxThPA2_1.Text = '';
            app.CheckBoxThPA1_1 = uicheckbox(app.GridLayout8_1);
            app.CheckBoxThPA1_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA1ValueChanged, true);
            app.CheckBoxThPA1_1.Text = '';
            app.Label_11 = uilabel(app.GridLayout8_1);
            app.Label_11.Tooltip = {'Linear absorption coefficient'};
            app.Label_11.Text = 'α :';
            app.Label_12 = uilabel(app.GridLayout8_1);
            app.Label_12.Tooltip = {'Effective Length of the Sample'};
            app.Label_12.Text = 'Leff :';
            app.Label_13 = uilabel(app.GridLayout8_1);
            app.Label_13.Tooltip = {'Wavelength of laser'};
            app.Label_13.Text = 'λ :';
            app.Label_14 = uilabel(app.GridLayout8_1);
            app.Label_14.Tooltip = {'Beam Waist'};
            app.Label_14.Text = 'w0 :';
            app.ZLabel_3 = uilabel(app.GridLayout8_1);
            app.ZLabel_3.Tag = 'Rayleigh Range';
            app.ZLabel_3.Tooltip = {'Rayleigh length'};
            app.ZLabel_3.Text = 'Z :';
            app.iLabel_3 = uilabel(app.GridLayout8_1);
            app.iLabel_3.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_3.Text = 'i :';
            app.Label_15 = uilabel(app.GridLayout8_1);
            app.Label_15.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_15.Text = 'ß :';
            app.EditField_NAC_ThPA_1 = uieditfield(app.GridLayout8_1, 'numeric');
            app.EditField_NAC_ThPA_1.Editable = 'off';
            app.EditField_NAC_ThPA_1.Tooltip = {'Nonlinear absorption coefficient'};
            app.EditField_ITILB_ThPA_1 = uieditfield(app.GridLayout8_1, 'numeric');
            app.EditField_ITILB_ThPA_1.Editable = 'off';
            app.EditField_ITILB_ThPA_1.Tooltip = {'Intensity of the incident laser beam'};
            app.EditField_RL_ThPA_1 = uieditfield(app.GridLayout8_1, 'numeric');
            app.EditField_RL_ThPA_1.Editable = 'off';
            app.EditField_RL_ThPA_1.Tooltip = {'Rayleigh length'};
            app.EditField_Leff_ThPA_1 = uieditfield(app.GridLayout8_1, 'numeric');
            app.EditField_Leff_ThPA_1.Editable = 'off';
            app.EditField_Leff_ThPA_1.Tooltip = {'Effective Length of the Sample'};
            app.EditField_W0_ThPA_1 = uieditfield(app.GridLayout8_1, 'numeric');
            app.EditField_W0_ThPA_1.Editable = 'off';
            app.EditField_W0_ThPA_1.Tooltip = {'Beam Waist'};
            app.EditField_alpha_ThPA_1 = uieditfield(app.GridLayout8_1, 'numeric');
            app.EditField_alpha_ThPA_1.Editable = 'off';
            app.EditField_alpha_ThPA_1.Tooltip = {'Linear absorption coefficient'};
            app.EditField_LAMBDA_ThPA_1 = uieditfield(app.GridLayout8_1, 'numeric');
            app.EditField_LAMBDA_ThPA_1.Editable = 'off';
            app.EditField_LAMBDA_ThPA_1.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_ThPA_1.Value = 532;
            app.LLabel_3 = uilabel(app.GridLayout8_1);
            app.LLabel_3.Tooltip = {'Thickness of the material'};
            app.LLabel_3.Text = 'L :';
            app.EditField_TTM_ThPA_1 = uieditfield(app.GridLayout8_1, 'numeric');
            app.EditField_TTM_ThPA_1.Editable = 'off';
            app.EditField_TTM_ThPA_1.Tooltip = {'Thickness of the material'};
            app.UnitsoftheparametersListBoxLabel_2 = uilabel(app.GridLayout3_1);
            app.UnitsoftheparametersListBoxLabel_2.Text = 'Units of the parameters :';
            app.Units_ListBox_1 = uilistbox(app.GridLayout3_1);
            app.Units_ListBox_1.Items = {'millimeter', 'centimeter', 'meter'};
            app.Units_ListBox_1.Value = 'millimeter';
            app.EditedDataSetLabel_1 = uilabel(app.GridLayout_1);
            app.EditedDataSetLabel_1.Text = 'Edited Data Set';
            app.dataTableEdited_1 = uitable(app.GridLayout_1);
            app.dataTableEdited_1.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTableEdited_1.RowName = {};
            app.dataTableEdited_1.ColumnSortable = true;
            app.dataTableEdited_1.ColumnEditable = true;
            app.dataTableFit_1 = uitable(app.GridLayout_1);
            app.dataTableFit_1.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTableFit_1.RowName = {};
            app.dataTableFit_1.ColumnSortable = true;
            app.dataTableFit_1.ColumnEditable = true;
            app.dataTable_1 = uitable(app.GridLayout_1);
            app.dataTable_1.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTable_1.RowName = {};
            app.dataTable_1.ColumnSortable = true;
            app.dataTable_1.ColumnEditable = true;
            app.dataTable_1.CellEditCallback = createCallbackFcn(app, @dataTableCellEdit, true);
            app.OriginalDataSetLabel_1 = uilabel(app.GridLayout_1);
            app.OriginalDataSetLabel_1.Text = 'Original Data Set';
            app.ExcellImportButton_1 = uibutton(app.GridLayout_1, 'push');
            app.ExcellImportButton_1.ButtonPushedFcn = createCallbackFcn(app, @ExcellImportButtonPushed, true);
            app.ExcellImportButton_1.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.ExcellImportButton_1.Text = 'Excell Import   ';
            app.DataSetterField1_1 = uieditfield(app.GridLayout_1, 'numeric');
            app.DataSetterField1_1.Value = -Inf;
            app.DataSetterField2_1 = uieditfield(app.GridLayout_1, 'numeric');
            app.DataSetterField2_1.Value = Inf;
            app.zLabel_1 = uilabel(app.GridLayout_1);
            app.zLabel_1.Text = '< z <';
            app.DatabetweenLabel_1 = uilabel(app.GridLayout_1);
            app.DatabetweenLabel_1.Text = 'Data between :';
            app.EditDataSetButton_1 = uibutton(app.GridLayout_1, 'push');
            app.EditDataSetButton_1.ButtonPushedFcn = createCallbackFcn(app, @EditDataSetButtonPushed, true);
            app.EditDataSetButton_1.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward.png');
            app.EditDataSetButton_1.Text = 'Edit Data Set';
            app.DataSetterField3_1 = uieditfield(app.GridLayout_1, 'numeric');
            app.XvalueforMLabel = uilabel(app.GridLayout_1);
            app.XvalueforMLabel.Text = 'Shift on x-direction :';
            app.NormalisedLabel_1 = uilabel(app.GridLayout_1);
            app.NormalisedLabel_1.Text = 'Normalised :';
            app.NormalisedCheckBox_1 = uicheckbox(app.GridLayout_1);
            app.NormalisedCheckBox_1.Text = '';
            app.NormalisedCheckBox_1.Value = true;
            app.GetthescatteringandfittingLabel_1 = uilabel(app.GridLayout_1);
            app.GetthescatteringandfittingLabel_1.Text = 'Get the scattering and fitting :';
            app.ExcellExportButton_1 = uibutton(app.GridLayout_1, 'push');
            app.ExcellExportButton_1.ButtonPushedFcn = createCallbackFcn(app, @ExcellExportButtonPushed, true);
            app.ExcellExportButton_1.Icon = fullfile(pathToMLAPP, 'icons', 'export.png');
            app.ExcellExportButton_1.Text = 'Excell Export';
            app.FitDataSetLabel_1 = uilabel(app.GridLayout_1);
            app.FitDataSetLabel_1.Text = 'Fit Data Set';
            app.FitCheckBox_1 = uicheckbox(app.GridLayout_1);
            app.FitCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @FitCheckBoxValueChanged, true);
            app.FitCheckBox_1.Text = '';
            app.Input2 = uitab(app.TabGroup);
            app.Input2.Title = 'Input 2';
            app.Input2.ButtonDownFcn = createCallbackFcn(app, @InputButtonDown, true);
            app.Input2.Tag = 'InputTab2';
            app.GridLayout_2 = uigridlayout(app.Input2);
            app.Panel_3 = uipanel(app.GridLayout_2);
            app.GridLayout4_2 = uigridlayout(app.Panel_3);
            app.graphEdited_2 = uiaxes(app.GridLayout4_2);
            title(app.graphEdited_2, 'Edited Data')
            xlabel(app.graphEdited_2, 'Z (mm)')
            ylabel(app.graphEdited_2, 'Intensity')
            app.graphOriginal_2 = uiaxes(app.GridLayout4_2);
            title(app.graphOriginal_2, 'Original Data')
            xlabel(app.graphOriginal_2, 'Z (mm)')
            ylabel(app.graphOriginal_2, 'Intensity')
            app.RsquareValueEditField_2Label = uilabel(app.GridLayout4_2);
            app.RsquareValueEditField_2Label.Text = 'R square Value :';
            app.RsquareValueEditField_2 = uieditfield(app.GridLayout4_2, 'numeric');
            app.RsquareValueEditField_2.Editable = 'off';
            app.Panel_4 = uipanel(app.GridLayout_2);
            app.GridLayout3_2 = uigridlayout(app.Panel_4);
            app.DevelopedbyMuhammedBektaolu2024Button_2 = uibutton(app.GridLayout3_2, 'push');
            app.DevelopedbyMuhammedBektaolu2024Button_2.ButtonPushedFcn = createCallbackFcn(app, @DevelopedbyButtonPushed, true);
            app.DevelopedbyMuhammedBektaolu2024Button_2.Text = {'Developed by Muhammed Bektaşoğlu'; '© 2024'};
            app.ParametersForFittingLabel_2 = uilabel(app.GridLayout3_2);
            app.ParametersForFittingLabel_2.Text = 'Parameters for Fitting';
            app.fittingMethods_2 = uilistbox(app.GridLayout3_2);
            app.fittingMethods_2.Items = {'', 'Open Aperture Z-Scan', 'Closed Aperture Z-Scan', 'Two Photon Absorption', 'Three Photon Absorption'};
            app.fittingMethods_2.ValueChangedFcn = createCallbackFcn(app, @fittingMethodsValueChanged, true);
            app.fittingMethods_2.Tag = 'fittingMethods_2';
            app.fittingMethods_2.Value = '';
            app.FittingmethodListBoxLabel_2 = uilabel(app.GridLayout3_2);
            app.FittingmethodListBoxLabel_2.Text = 'Fitting method';
            app.TabGroup2_2 = uitabgroup(app.GridLayout3_2);
            app.OpenApertureZScanTab_2 = uitab(app.TabGroup2_2);
            app.OpenApertureZScanTab_2.Title = 'Open Aperture Z-Scan';
            app.GridLayout5_2 = uigridlayout(app.OpenApertureZScanTab_2);
            app.LLabel_4 = uilabel(app.GridLayout5_2);
            app.LLabel_4.Tooltip = {'Thickness of the material'};
            app.LLabel_4.Text = 'L :';
            app.EditField_TTM_OA_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditField_TTM_OA_2.Editable = 'off';
            app.EditField_TTM_OA_2.Tooltip = {'Thickness of the material'};
            app.Label_16 = uilabel(app.GridLayout5_2);
            app.Label_16.Tooltip = {'Wavelength of laser'};
            app.Label_16.Text = 'λ :';
            app.EditField_LAMBDA_OA_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditField_LAMBDA_OA_2.Editable = 'off';
            app.EditField_LAMBDA_OA_2.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_OA_2.Value = 532;
            app.Label_17 = uilabel(app.GridLayout5_2);
            app.Label_17.Tooltip = {'Linear absorption coefficient'};
            app.Label_17.Text = 'α :';
            app.EditField_alpha_OA_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditField_alpha_OA_2.Editable = 'off';
            app.EditField_alpha_OA_2.Tooltip = {'Linear absorption coefficient'};
            app.Label_18 = uilabel(app.GridLayout5_2);
            app.Label_18.Tooltip = {'Beam Waist'};
            app.Label_18.Text = 'w0 :';
            app.EditField_W0_OA_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditField_W0_OA_2.Editable = 'off';
            app.EditField_W0_OA_2.Tooltip = {'Beam Waist'};
            app.Label_19 = uilabel(app.GridLayout5_2);
            app.Label_19.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_19.Text = 'ß :';
            app.EditField_NAC_OA_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditField_NAC_OA_2.Editable = 'off';
            app.EditField_NAC_OA_2.Tooltip = {'Nonlinear absorption coefficient'};
            app.ZLabel_4 = uilabel(app.GridLayout5_2);
            app.ZLabel_4.Tag = 'Rayleigh Range';
            app.ZLabel_4.Tooltip = {'Rayleigh length'};
            app.ZLabel_4.Text = 'Z :';
            app.EditField_RL_OA_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditField_RL_OA_2.Editable = 'off';
            app.EditField_RL_OA_2.Tooltip = {'Rayleigh length'};
            app.iLabel_4 = uilabel(app.GridLayout5_2);
            app.iLabel_4.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_4.Text = 'i :';
            app.EditField_ITILB_OA_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditField_ITILB_OA_2.Editable = 'off';
            app.EditField_ITILB_OA_2.Tooltip = {'Intensity of the incident laser beam'};
            app.Label_20 = uilabel(app.GridLayout5_2);
            app.Label_20.Tooltip = {'Effective Length of the Sample'};
            app.Label_20.Text = 'Leff :';
            app.EditField_Leff_OA_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditField_Leff_OA_2.Editable = 'off';
            app.EditField_Leff_OA_2.Tooltip = {'Effective Length of the Sample'};
            app.CheckBoxOA8_2 = uicheckbox(app.GridLayout5_2);
            app.CheckBoxOA8_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA8ValueChanged, true);
            app.CheckBoxOA8_2.Text = '';
            app.CheckBoxOA7_2 = uicheckbox(app.GridLayout5_2);
            app.CheckBoxOA7_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA7ValueChanged, true);
            app.CheckBoxOA7_2.Text = '';
            app.CheckBoxOA6_2 = uicheckbox(app.GridLayout5_2);
            app.CheckBoxOA6_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA6ValueChanged, true);
            app.CheckBoxOA6_2.Text = '';
            app.CheckBoxOA5_2 = uicheckbox(app.GridLayout5_2);
            app.CheckBoxOA5_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA5ValueChanged, true);
            app.CheckBoxOA5_2.Text = '';
            app.CheckBoxOA4_2 = uicheckbox(app.GridLayout5_2);
            app.CheckBoxOA4_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA4ValueChanged, true);
            app.CheckBoxOA4_2.Text = '';
            app.CheckBoxOA3_2 = uicheckbox(app.GridLayout5_2);
            app.CheckBoxOA3_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA3ValueChanged, true);
            app.CheckBoxOA3_2.Text = '';
            app.CheckBoxOA2_2 = uicheckbox(app.GridLayout5_2);
            app.CheckBoxOA2_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA2ValueChanged, true);
            app.CheckBoxOA2_2.Text = '';
            app.CheckBoxOA1_2 = uicheckbox(app.GridLayout5_2);
            app.CheckBoxOA1_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA1ValueChanged, true);
            app.CheckBoxOA1_2.Text = '';
            app.ClosedApertureZScanTab_2 = uitab(app.TabGroup2_2);
            app.ClosedApertureZScanTab_2.Title = 'Closed Aperture Z-Scan';
            app.GridLayout6_2 = uigridlayout(app.ClosedApertureZScanTab_2);
            app.Label_79 = uilabel(app.GridLayout6_2);
            app.Label_79.Tooltip = {'Beam Waist'};
            app.Label_79.Text = 'w0 :';
            app.EditField_RL_CA_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.EditField_RL_CA_2.Editable = 'off';
            app.EditField_RL_CA_2.Tooltip = {'Rayleigh length'};
            app.EditField_W0_CA_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.EditField_W0_CA_2.Editable = 'off';
            app.EditField_W0_CA_2.Tooltip = {'Beam Waist'};
            app.ZLabel_17 = uilabel(app.GridLayout6_2);
            app.ZLabel_17.Tag = 'Rayleigh Range';
            app.ZLabel_17.Tooltip = {'Rayleigh length'};
            app.ZLabel_17.Text = 'Z :';
            app.Label_78 = uilabel(app.GridLayout6_2);
            app.Label_78.Tooltip = {'Wavelength of laser'};
            app.Label_78.Text = 'λ :';
            app.EditField_LAMBDA_CA_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.EditField_LAMBDA_CA_2.Editable = 'off';
            app.EditField_LAMBDA_CA_2.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_CA_2.Value = 532;
            app.CheckBoxCA6_2 = uicheckbox(app.GridLayout6_2);
            app.CheckBoxCA6_2.Text = '';
            app.CheckBoxCA4_2 = uicheckbox(app.GridLayout6_2);
            app.CheckBoxCA4_2.Text = '';
            app.CheckBoxCA2_2 = uicheckbox(app.GridLayout6_2);
            app.CheckBoxCA2_2.Text = '';
            app.TwoPhotonAbsorptionTab_2 = uitab(app.TabGroup2_2);
            app.TwoPhotonAbsorptionTab_2.Title = 'Two Photon Absorption';
            app.GridLayout7_2 = uigridlayout(app.TwoPhotonAbsorptionTab_2);
            app.CheckBoxTPA8_2 = uicheckbox(app.GridLayout7_2);
            app.CheckBoxTPA8_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA8ValueChanged, true);
            app.CheckBoxTPA8_2.Text = '';
            app.CheckBoxTPA7_2 = uicheckbox(app.GridLayout7_2);
            app.CheckBoxTPA7_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA7ValueChanged, true);
            app.CheckBoxTPA7_2.Text = '';
            app.CheckBoxTPA6_2 = uicheckbox(app.GridLayout7_2);
            app.CheckBoxTPA6_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA6ValueChanged, true);
            app.CheckBoxTPA6_2.Text = '';
            app.CheckBoxTPA5_2 = uicheckbox(app.GridLayout7_2);
            app.CheckBoxTPA5_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA5ValueChanged, true);
            app.CheckBoxTPA5_2.Text = '';
            app.CheckBoxTPA4_2 = uicheckbox(app.GridLayout7_2);
            app.CheckBoxTPA4_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA4ValueChanged, true);
            app.CheckBoxTPA4_2.Text = '';
            app.CheckBoxTPA3_2 = uicheckbox(app.GridLayout7_2);
            app.CheckBoxTPA3_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA3ValueChanged, true);
            app.CheckBoxTPA3_2.Text = '';
            app.EditField_ITILB_TPA_2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.EditField_ITILB_TPA_2.Editable = 'off';
            app.EditField_ITILB_TPA_2.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_5 = uilabel(app.GridLayout7_2);
            app.iLabel_5.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_5.Text = 'i :';
            app.EditField_NAC_TPA_2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.EditField_NAC_TPA_2.Editable = 'off';
            app.EditField_NAC_TPA_2.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_21 = uilabel(app.GridLayout7_2);
            app.Label_21.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_21.Text = 'ß :';
            app.CheckBoxTPA2_2 = uicheckbox(app.GridLayout7_2);
            app.CheckBoxTPA2_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA2ValueChanged, true);
            app.CheckBoxTPA2_2.Text = '';
            app.CheckBoxTPA1_2 = uicheckbox(app.GridLayout7_2);
            app.CheckBoxTPA1_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA1ValueChanged, true);
            app.CheckBoxTPA1_2.Text = '';
            app.Label_22 = uilabel(app.GridLayout7_2);
            app.Label_22.Tooltip = {'Effective Length of the Sample'};
            app.Label_22.Text = 'Leff :';
            app.EditField_Leff_TPA_2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.EditField_Leff_TPA_2.Editable = 'off';
            app.EditField_Leff_TPA_2.Tooltip = {'Effective Length of the Sample'};
            app.Label_23 = uilabel(app.GridLayout7_2);
            app.Label_23.Tooltip = {'Linear absorption coefficient'};
            app.Label_23.Text = 'α :';
            app.EditField_alpha_TPA_2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.EditField_alpha_TPA_2.Editable = 'off';
            app.EditField_alpha_TPA_2.Tooltip = {'Linear absorption coefficient'};
            app.EditField_RL_TPA_2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.EditField_RL_TPA_2.Editable = 'off';
            app.EditField_RL_TPA_2.Tooltip = {'Rayleigh length'};
            app.ZLabel_5 = uilabel(app.GridLayout7_2);
            app.ZLabel_5.Tag = 'Rayleigh Range';
            app.ZLabel_5.Tooltip = {'Rayleigh length'};
            app.ZLabel_5.Text = 'Z :';
            app.EditField_W0_TPA_2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.EditField_W0_TPA_2.Editable = 'off';
            app.EditField_W0_TPA_2.Tooltip = {'Beam Waist'};
            app.Label_24 = uilabel(app.GridLayout7_2);
            app.Label_24.Tooltip = {'Beam Waist'};
            app.Label_24.Text = 'w0 :';
            app.EditField_LAMBDA_TPA_2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.EditField_LAMBDA_TPA_2.Editable = 'off';
            app.EditField_LAMBDA_TPA_2.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_TPA_2.Value = 532;
            app.Label_25 = uilabel(app.GridLayout7_2);
            app.Label_25.Tooltip = {'Wavelength of laser'};
            app.Label_25.Text = 'λ :';
            app.LLabel_5 = uilabel(app.GridLayout7_2);
            app.LLabel_5.Tooltip = {'Thickness of the material'};
            app.LLabel_5.Text = 'L :';
            app.EditField_TTM_TPA_2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.EditField_TTM_TPA_2.Editable = 'off';
            app.EditField_TTM_TPA_2.Tooltip = {'Thickness of the material'};
            app.ThreePhotonAbsorptionTab_2 = uitab(app.TabGroup2_2);
            app.ThreePhotonAbsorptionTab_2.Title = 'Three Photon Absorption';
            app.GridLayout8_2 = uigridlayout(app.ThreePhotonAbsorptionTab_2);
            app.CheckBoxThPA8_2 = uicheckbox(app.GridLayout8_2);
            app.CheckBoxThPA8_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA8ValueChanged, true);
            app.CheckBoxThPA8_2.Text = '';
            app.CheckBoxThPA7_2 = uicheckbox(app.GridLayout8_2);
            app.CheckBoxThPA7_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA7ValueChanged, true);
            app.CheckBoxThPA7_2.Text = '';
            app.CheckBoxThPA6_2 = uicheckbox(app.GridLayout8_2);
            app.CheckBoxThPA6_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA6ValueChanged, true);
            app.CheckBoxThPA6_2.Text = '';
            app.CheckBoxThPA5_2 = uicheckbox(app.GridLayout8_2);
            app.CheckBoxThPA5_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA5ValueChanged, true);
            app.CheckBoxThPA5_2.Text = '';
            app.CheckBoxThPA4_2 = uicheckbox(app.GridLayout8_2);
            app.CheckBoxThPA4_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA4ValueChanged, true);
            app.CheckBoxThPA4_2.Text = '';
            app.CheckBoxThPA3_2 = uicheckbox(app.GridLayout8_2);
            app.CheckBoxThPA3_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA3ValueChanged, true);
            app.CheckBoxThPA3_2.Text = '';
            app.CheckBoxThPA2_2 = uicheckbox(app.GridLayout8_2);
            app.CheckBoxThPA2_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA2ValueChanged, true);
            app.CheckBoxThPA2_2.Text = '';
            app.CheckBoxThPA1_2 = uicheckbox(app.GridLayout8_2);
            app.CheckBoxThPA1_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA1ValueChanged, true);
            app.CheckBoxThPA1_2.Text = '';
            app.Label_26 = uilabel(app.GridLayout8_2);
            app.Label_26.Tooltip = {'Linear absorption coefficient'};
            app.Label_26.Text = 'α :';
            app.EditField_alpha_ThPA_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.EditField_alpha_ThPA_2.Editable = 'off';
            app.EditField_alpha_ThPA_2.Tooltip = {'Linear absorption coefficient'};
            app.Label_27 = uilabel(app.GridLayout8_2);
            app.Label_27.Tooltip = {'Effective Length of the Sample'};
            app.Label_27.Text = 'Leff :';
            app.EditField_Leff_ThPA_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.EditField_Leff_ThPA_2.Editable = 'off';
            app.EditField_Leff_ThPA_2.Tooltip = {'Effective Length of the Sample'};
            app.Label_28 = uilabel(app.GridLayout8_2);
            app.Label_28.Tooltip = {'Wavelength of laser'};
            app.Label_28.Text = 'λ :';
            app.EditField_LAMBDA_ThPA_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.EditField_LAMBDA_ThPA_2.Editable = 'off';
            app.EditField_LAMBDA_ThPA_2.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_ThPA_2.Value = 532;
            app.Label_29 = uilabel(app.GridLayout8_2);
            app.Label_29.Tooltip = {'Beam Waist'};
            app.Label_29.Text = 'w0 :';
            app.EditField_W0_ThPA_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.EditField_W0_ThPA_2.Editable = 'off';
            app.EditField_W0_ThPA_2.Tooltip = {'Beam Waist'};
            app.ZLabel_6 = uilabel(app.GridLayout8_2);
            app.ZLabel_6.Tag = 'Rayleigh Range';
            app.ZLabel_6.Tooltip = {'Rayleigh length'};
            app.ZLabel_6.Text = 'Z :';
            app.EditField_RL_ThPA_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.EditField_RL_ThPA_2.Editable = 'off';
            app.EditField_RL_ThPA_2.Tooltip = {'Rayleigh length'};
            app.iLabel_6 = uilabel(app.GridLayout8_2);
            app.iLabel_6.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_6.Text = 'i :';
            app.EditField_ITILB_ThPA_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.EditField_ITILB_ThPA_2.Editable = 'off';
            app.EditField_ITILB_ThPA_2.Tooltip = {'Intensity of the incident laser beam'};
            app.Label_30 = uilabel(app.GridLayout8_2);
            app.Label_30.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_30.Text = 'ß :';
            app.EditField_NAC_ThPA_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.EditField_NAC_ThPA_2.Editable = 'off';
            app.EditField_NAC_ThPA_2.Tooltip = {'Nonlinear absorption coefficient'};
            app.LLabel_6 = uilabel(app.GridLayout8_2);
            app.LLabel_6.Tooltip = {'Thickness of the material'};
            app.LLabel_6.Text = 'L :';
            app.EditField_TTM_ThPA_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.EditField_TTM_ThPA_2.Editable = 'off';
            app.EditField_TTM_ThPA_2.Tooltip = {'Thickness of the material'};
            app.UnitsoftheparametersListBoxLabel = uilabel(app.GridLayout3_2);
            app.UnitsoftheparametersListBoxLabel.Text = 'Units of the parameters :';
            app.Units_ListBox_2 = uilistbox(app.GridLayout3_2);
            app.Units_ListBox_2.Items = {'millimeter', 'centimeter', 'meter'};
            app.Units_ListBox_2.Value = 'millimeter';
            app.EditedDataSetLabel_2 = uilabel(app.GridLayout_2);
            app.EditedDataSetLabel_2.Text = 'Edited Data Set';
            app.dataTableEdited_2 = uitable(app.GridLayout_2);
            app.dataTableEdited_2.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTableEdited_2.RowName = {};
            app.dataTableEdited_2.ColumnSortable = true;
            app.dataTableEdited_2.ColumnEditable = true;
            app.dataTableFit_2 = uitable(app.GridLayout_2);
            app.dataTableFit_2.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTableFit_2.RowName = {};
            app.dataTableFit_2.ColumnSortable = true;
            app.dataTableFit_2.ColumnEditable = true;
            app.dataTable_2 = uitable(app.GridLayout_2);
            app.dataTable_2.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTable_2.RowName = {};
            app.dataTable_2.ColumnSortable = true;
            app.dataTable_2.ColumnEditable = true;
            app.OriginalDataSetLabel_2 = uilabel(app.GridLayout_2);
            app.OriginalDataSetLabel_2.Text = 'Original Data Set';
            app.ExcellImportButton_2 = uibutton(app.GridLayout_2, 'push');
            app.ExcellImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExcellImportButtonPushed, true);
            app.ExcellImportButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.ExcellImportButton_2.Text = 'Excell Import   ';
            app.DataSetterField1_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.DataSetterField1_2.Value = -Inf;
            app.DataSetterField2_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.DataSetterField2_2.Value = Inf;
            app.zLabel_2 = uilabel(app.GridLayout_2);
            app.zLabel_2.Text = '< z <';
            app.DatabetweenLabel_2 = uilabel(app.GridLayout_2);
            app.DatabetweenLabel_2.Text = 'Data between :';
            app.EditDataSetButton_2 = uibutton(app.GridLayout_2, 'push');
            app.EditDataSetButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditDataSetButtonPushed, true);
            app.EditDataSetButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward.png');
            app.EditDataSetButton_2.Text = 'Edit Data Set';
            app.DataSetterField3_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.XvalueforMLabel_2 = uilabel(app.GridLayout_2);
            app.XvalueforMLabel_2.Text = 'Shift on x-direction :';
            app.NormalisedLabel_2 = uilabel(app.GridLayout_2);
            app.NormalisedLabel_2.Text = 'Normalised :';
            app.NormalisedCheckBox_2 = uicheckbox(app.GridLayout_2);
            app.NormalisedCheckBox_2.Text = '';
            app.NormalisedCheckBox_2.Value = true;
            app.GetthescatteringandfittingLabel_2 = uilabel(app.GridLayout_2);
            app.GetthescatteringandfittingLabel_2.Text = 'Get the scattering and fitting :';
            app.ExcellExportButton_2 = uibutton(app.GridLayout_2, 'push');
            app.ExcellExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExcellExportButtonPushed, true);
            app.ExcellExportButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'export.png');
            app.ExcellExportButton_2.Text = 'Excell Export';
            app.FitDataSetLabel_2 = uilabel(app.GridLayout_2);
            app.FitDataSetLabel_2.Text = 'Fit Data Set';
            app.FitCheckBox_2 = uicheckbox(app.GridLayout_2);
            app.FitCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @FitCheckBoxValueChanged, true);
            app.FitCheckBox_2.Text = '';
            app.Input3 = uitab(app.TabGroup);
            app.Input3.Title = 'Input 3';
            app.Input3.ButtonDownFcn = createCallbackFcn(app, @InputButtonDown, true);
            app.Input3.Tag = 'InputTab3';
            app.GridLayout_3 = uigridlayout(app.Input3);
            app.Panel_5 = uipanel(app.GridLayout_3);
            app.GridLayout4_3 = uigridlayout(app.Panel_5);
            app.graphEdited_3 = uiaxes(app.GridLayout4_3);
            title(app.graphEdited_3, 'Edited Data')
            xlabel(app.graphEdited_3, 'Z (mm)')
            ylabel(app.graphEdited_3, 'Intensity')
            app.graphOriginal_3 = uiaxes(app.GridLayout4_3);
            title(app.graphOriginal_3, 'Original Data')
            xlabel(app.graphOriginal_3, 'Z (mm)')
            ylabel(app.graphOriginal_3, 'Intensity')
            app.RsquareValueEditField_3Label = uilabel(app.GridLayout4_3);
            app.RsquareValueEditField_3Label.Text = 'R square Value :';
            app.RsquareValueEditField_3 = uieditfield(app.GridLayout4_3, 'numeric');
            app.RsquareValueEditField_3.Editable = 'off';
            app.Panel_6 = uipanel(app.GridLayout_3);
            app.GridLayout3_3 = uigridlayout(app.Panel_6);
            app.fittingMethods_3 = uilistbox(app.GridLayout3_3);
            app.fittingMethods_3.Items = {'', 'Open Aperture Z-Scan', 'Closed Aperture Z-Scan', 'Two Photon Absorption', 'Three Photon Absorption'};
            app.fittingMethods_3.ValueChangedFcn = createCallbackFcn(app, @fittingMethodsValueChanged, true);
            app.fittingMethods_3.Tag = 'fittingMethods_3';
            app.fittingMethods_3.Value = '';
            app.FittingmethodListBoxLabel_3 = uilabel(app.GridLayout3_3);
            app.FittingmethodListBoxLabel_3.Text = 'Fitting method';
            app.ParametersForFittingLabel_3 = uilabel(app.GridLayout3_3);
            app.ParametersForFittingLabel_3.Text = 'Parameters for Fitting';
            app.DevelopedbyMuhammedBektaolu2024Button_3 = uibutton(app.GridLayout3_3, 'push');
            app.DevelopedbyMuhammedBektaolu2024Button_3.ButtonPushedFcn = createCallbackFcn(app, @DevelopedbyButtonPushed, true);
            app.DevelopedbyMuhammedBektaolu2024Button_3.Text = {'Developed by Muhammed Bektaşoğlu'; '© 2024'};
            app.TabGroup2_3 = uitabgroup(app.GridLayout3_3);
            app.OpenApertureZScanTab_3 = uitab(app.TabGroup2_3);
            app.OpenApertureZScanTab_3.Title = 'Open Aperture Z-Scan';
            app.GridLayout5_3 = uigridlayout(app.OpenApertureZScanTab_3);
            app.LLabel_7 = uilabel(app.GridLayout5_3);
            app.LLabel_7.Tooltip = {'Thickness of the material'};
            app.LLabel_7.Text = 'L :';
            app.EditField_TTM_OA_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditField_TTM_OA_3.Editable = 'off';
            app.EditField_TTM_OA_3.Tooltip = {'Thickness of the material'};
            app.Label_31 = uilabel(app.GridLayout5_3);
            app.Label_31.Tooltip = {'Wavelength of laser'};
            app.Label_31.Text = 'λ :';
            app.EditField_LAMBDA_OA_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditField_LAMBDA_OA_3.Editable = 'off';
            app.EditField_LAMBDA_OA_3.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_OA_3.Value = 532;
            app.Label_32 = uilabel(app.GridLayout5_3);
            app.Label_32.Tooltip = {'Linear absorption coefficient'};
            app.Label_32.Text = 'α :';
            app.EditField_alpha_OA_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditField_alpha_OA_3.Editable = 'off';
            app.EditField_alpha_OA_3.Tooltip = {'Linear absorption coefficient'};
            app.Label_33 = uilabel(app.GridLayout5_3);
            app.Label_33.Tooltip = {'Beam Waist'};
            app.Label_33.Text = 'w0 :';
            app.EditField_W0_OA_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditField_W0_OA_3.Editable = 'off';
            app.EditField_W0_OA_3.Tooltip = {'Beam Waist'};
            app.Label_34 = uilabel(app.GridLayout5_3);
            app.Label_34.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_34.Text = 'ß :';
            app.EditField_NAC_OA_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditField_NAC_OA_3.Editable = 'off';
            app.EditField_NAC_OA_3.Tooltip = {'Nonlinear absorption coefficient'};
            app.ZLabel_7 = uilabel(app.GridLayout5_3);
            app.ZLabel_7.Tag = 'Rayleigh Range';
            app.ZLabel_7.Tooltip = {'Rayleigh length'};
            app.ZLabel_7.Text = 'Z :';
            app.EditField_RL_OA_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditField_RL_OA_3.Editable = 'off';
            app.EditField_RL_OA_3.Tooltip = {'Rayleigh length'};
            app.iLabel_7 = uilabel(app.GridLayout5_3);
            app.iLabel_7.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_7.Text = 'i :';
            app.EditField_ITILB_OA_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditField_ITILB_OA_3.Editable = 'off';
            app.EditField_ITILB_OA_3.Tooltip = {'Intensity of the incident laser beam'};
            app.Label_35 = uilabel(app.GridLayout5_3);
            app.Label_35.Tooltip = {'Effective Length of the Sample'};
            app.Label_35.Text = 'Leff :';
            app.EditField_Leff_OA_3 = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditField_Leff_OA_3.Editable = 'off';
            app.EditField_Leff_OA_3.Tooltip = {'Effective Length of the Sample'};
            app.CheckBoxOA8_3 = uicheckbox(app.GridLayout5_3);
            app.CheckBoxOA8_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA8ValueChanged, true);
            app.CheckBoxOA8_3.Text = '';
            app.CheckBoxOA7_3 = uicheckbox(app.GridLayout5_3);
            app.CheckBoxOA7_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA7ValueChanged, true);
            app.CheckBoxOA7_3.Text = '';
            app.CheckBoxOA6_3 = uicheckbox(app.GridLayout5_3);
            app.CheckBoxOA6_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA6ValueChanged, true);
            app.CheckBoxOA6_3.Text = '';
            app.CheckBoxOA5_3 = uicheckbox(app.GridLayout5_3);
            app.CheckBoxOA5_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA5ValueChanged, true);
            app.CheckBoxOA5_3.Text = '';
            app.CheckBoxOA4_3 = uicheckbox(app.GridLayout5_3);
            app.CheckBoxOA4_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA4ValueChanged, true);
            app.CheckBoxOA4_3.Text = '';
            app.CheckBoxOA3_3 = uicheckbox(app.GridLayout5_3);
            app.CheckBoxOA3_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA3ValueChanged, true);
            app.CheckBoxOA3_3.Text = '';
            app.CheckBoxOA2_3 = uicheckbox(app.GridLayout5_3);
            app.CheckBoxOA2_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA2ValueChanged, true);
            app.CheckBoxOA2_3.Text = '';
            app.CheckBoxOA1_3 = uicheckbox(app.GridLayout5_3);
            app.CheckBoxOA1_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA1ValueChanged, true);
            app.CheckBoxOA1_3.Text = '';
            app.ClosedApertureZScanTab_3 = uitab(app.TabGroup2_3);
            app.ClosedApertureZScanTab_3.Title = 'Closed Aperture Z-Scan';
            app.GridLayout6_3 = uigridlayout(app.ClosedApertureZScanTab_3);
            app.Label_80 = uilabel(app.GridLayout6_3);
            app.Label_80.Tooltip = {'Wavelength of laser'};
            app.Label_80.Text = 'λ :';
            app.EditField_RL_CA_3 = uieditfield(app.GridLayout6_3, 'numeric');
            app.EditField_RL_CA_3.Editable = 'off';
            app.EditField_RL_CA_3.Tooltip = {'Rayleigh length'};
            app.Label_81 = uilabel(app.GridLayout6_3);
            app.Label_81.Tooltip = {'Beam Waist'};
            app.Label_81.Text = 'w0 :';
            app.EditField_W0_CA_3 = uieditfield(app.GridLayout6_3, 'numeric');
            app.EditField_W0_CA_3.Editable = 'off';
            app.EditField_W0_CA_3.Tooltip = {'Beam Waist'};
            app.EditField_LAMBDA_CA_3 = uieditfield(app.GridLayout6_3, 'numeric');
            app.EditField_LAMBDA_CA_3.Editable = 'off';
            app.EditField_LAMBDA_CA_3.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_CA_3.Value = 532;
            app.ZLabel_18 = uilabel(app.GridLayout6_3);
            app.ZLabel_18.Tag = 'Rayleigh Range';
            app.ZLabel_18.Tooltip = {'Rayleigh length'};
            app.ZLabel_18.Text = 'Z :';
            app.CheckBoxCA6_3 = uicheckbox(app.GridLayout6_3);
            app.CheckBoxCA6_3.Text = '';
            app.CheckBoxCA4_3 = uicheckbox(app.GridLayout6_3);
            app.CheckBoxCA4_3.Text = '';
            app.CheckBoxCA2_3 = uicheckbox(app.GridLayout6_3);
            app.CheckBoxCA2_3.Text = '';
            app.TwoPhotonAbsorptionTab_3 = uitab(app.TabGroup2_3);
            app.TwoPhotonAbsorptionTab_3.Title = 'Two Photon Absorption';
            app.GridLayout7_3 = uigridlayout(app.TwoPhotonAbsorptionTab_3);
            app.CheckBoxTPA8_3 = uicheckbox(app.GridLayout7_3);
            app.CheckBoxTPA8_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA8ValueChanged, true);
            app.CheckBoxTPA8_3.Text = '';
            app.CheckBoxTPA7_3 = uicheckbox(app.GridLayout7_3);
            app.CheckBoxTPA7_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA7ValueChanged, true);
            app.CheckBoxTPA7_3.Text = '';
            app.CheckBoxTPA6_3 = uicheckbox(app.GridLayout7_3);
            app.CheckBoxTPA6_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA6ValueChanged, true);
            app.CheckBoxTPA6_3.Text = '';
            app.CheckBoxTPA5_3 = uicheckbox(app.GridLayout7_3);
            app.CheckBoxTPA5_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA5ValueChanged, true);
            app.CheckBoxTPA5_3.Text = '';
            app.CheckBoxTPA4_3 = uicheckbox(app.GridLayout7_3);
            app.CheckBoxTPA4_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA4ValueChanged, true);
            app.CheckBoxTPA4_3.Text = '';
            app.CheckBoxTPA3_3 = uicheckbox(app.GridLayout7_3);
            app.CheckBoxTPA3_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA3ValueChanged, true);
            app.CheckBoxTPA3_3.Text = '';
            app.CheckBoxTPA2_3 = uicheckbox(app.GridLayout7_3);
            app.CheckBoxTPA2_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA2ValueChanged, true);
            app.CheckBoxTPA2_3.Text = '';
            app.CheckBoxTPA1_3 = uicheckbox(app.GridLayout7_3);
            app.CheckBoxTPA1_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA1ValueChanged, true);
            app.CheckBoxTPA1_3.Text = '';
            app.EditField_NAC_TPA_3 = uieditfield(app.GridLayout7_3, 'numeric');
            app.EditField_NAC_TPA_3.Editable = 'off';
            app.EditField_NAC_TPA_3.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_36 = uilabel(app.GridLayout7_3);
            app.Label_36.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_36.Text = 'ß :';
            app.EditField_ITILB_TPA_3 = uieditfield(app.GridLayout7_3, 'numeric');
            app.EditField_ITILB_TPA_3.Editable = 'off';
            app.EditField_ITILB_TPA_3.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_8 = uilabel(app.GridLayout7_3);
            app.iLabel_8.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_8.Text = 'i :';
            app.Label_37 = uilabel(app.GridLayout7_3);
            app.Label_37.Tooltip = {'Effective Length of the Sample'};
            app.Label_37.Text = 'Leff :';
            app.EditField_Leff_TPA_3 = uieditfield(app.GridLayout7_3, 'numeric');
            app.EditField_Leff_TPA_3.Editable = 'off';
            app.EditField_Leff_TPA_3.Tooltip = {'Effective Length of the Sample'};
            app.Label_38 = uilabel(app.GridLayout7_3);
            app.Label_38.Tooltip = {'Linear absorption coefficient'};
            app.Label_38.Text = 'α :';
            app.EditField_alpha_TPA_3 = uieditfield(app.GridLayout7_3, 'numeric');
            app.EditField_alpha_TPA_3.Editable = 'off';
            app.EditField_alpha_TPA_3.Tooltip = {'Linear absorption coefficient'};
            app.EditField_RL_TPA_3 = uieditfield(app.GridLayout7_3, 'numeric');
            app.EditField_RL_TPA_3.Editable = 'off';
            app.EditField_RL_TPA_3.Tooltip = {'Rayleigh length'};
            app.ZLabel_8 = uilabel(app.GridLayout7_3);
            app.ZLabel_8.Tag = 'Rayleigh Range';
            app.ZLabel_8.Tooltip = {'Rayleigh length'};
            app.ZLabel_8.Text = 'Z :';
            app.EditField_W0_TPA_3 = uieditfield(app.GridLayout7_3, 'numeric');
            app.EditField_W0_TPA_3.Editable = 'off';
            app.EditField_W0_TPA_3.Tooltip = {'Beam Waist'};
            app.Label_39 = uilabel(app.GridLayout7_3);
            app.Label_39.Tooltip = {'Beam Waist'};
            app.Label_39.Text = 'w0 :';
            app.EditField_LAMBDA_TPA_3 = uieditfield(app.GridLayout7_3, 'numeric');
            app.EditField_LAMBDA_TPA_3.Editable = 'off';
            app.EditField_LAMBDA_TPA_3.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_TPA_3.Value = 532;
            app.Label_40 = uilabel(app.GridLayout7_3);
            app.Label_40.Tooltip = {'Wavelength of laser'};
            app.Label_40.Text = 'λ :';
            app.LLabel_8 = uilabel(app.GridLayout7_3);
            app.LLabel_8.Tooltip = {'Thickness of the material'};
            app.LLabel_8.Text = 'L :';
            app.EditField_TTM_TPA_3 = uieditfield(app.GridLayout7_3, 'numeric');
            app.EditField_TTM_TPA_3.Editable = 'off';
            app.EditField_TTM_TPA_3.Tooltip = {'Thickness of the material'};
            app.ThreePhotonAbsorptionTab_3 = uitab(app.TabGroup2_3);
            app.ThreePhotonAbsorptionTab_3.Title = 'Three Photon Absorption';
            app.GridLayout8_3 = uigridlayout(app.ThreePhotonAbsorptionTab_3);
            app.CheckBoxThPA8_3 = uicheckbox(app.GridLayout8_3);
            app.CheckBoxThPA8_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA8ValueChanged, true);
            app.CheckBoxThPA8_3.Text = '';
            app.CheckBoxThPA7_3 = uicheckbox(app.GridLayout8_3);
            app.CheckBoxThPA7_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA7ValueChanged, true);
            app.CheckBoxThPA7_3.Text = '';
            app.CheckBoxThPA6_3 = uicheckbox(app.GridLayout8_3);
            app.CheckBoxThPA6_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA6ValueChanged, true);
            app.CheckBoxThPA6_3.Text = '';
            app.CheckBoxThPA5_3 = uicheckbox(app.GridLayout8_3);
            app.CheckBoxThPA5_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA5ValueChanged, true);
            app.CheckBoxThPA5_3.Text = '';
            app.CheckBoxThPA4_3 = uicheckbox(app.GridLayout8_3);
            app.CheckBoxThPA4_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA4ValueChanged, true);
            app.CheckBoxThPA4_3.Text = '';
            app.CheckBoxThPA3_3 = uicheckbox(app.GridLayout8_3);
            app.CheckBoxThPA3_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA3ValueChanged, true);
            app.CheckBoxThPA3_3.Text = '';
            app.CheckBoxThPA2_3 = uicheckbox(app.GridLayout8_3);
            app.CheckBoxThPA2_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA2ValueChanged, true);
            app.CheckBoxThPA2_3.Text = '';
            app.CheckBoxThPA1_3 = uicheckbox(app.GridLayout8_3);
            app.CheckBoxThPA1_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA1ValueChanged, true);
            app.CheckBoxThPA1_3.Text = '';
            app.Label_41 = uilabel(app.GridLayout8_3);
            app.Label_41.Tooltip = {'Linear absorption coefficient'};
            app.Label_41.Text = 'α :';
            app.EditField_alpha_ThPA_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.EditField_alpha_ThPA_3.Editable = 'off';
            app.EditField_alpha_ThPA_3.Tooltip = {'Linear absorption coefficient'};
            app.Label_42 = uilabel(app.GridLayout8_3);
            app.Label_42.Tooltip = {'Effective Length of the Sample'};
            app.Label_42.Text = 'Leff :';
            app.EditField_Leff_ThPA_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.EditField_Leff_ThPA_3.Editable = 'off';
            app.EditField_Leff_ThPA_3.Tooltip = {'Effective Length of the Sample'};
            app.Label_43 = uilabel(app.GridLayout8_3);
            app.Label_43.Tooltip = {'Wavelength of laser'};
            app.Label_43.Text = 'λ :';
            app.EditField_LAMBDA_ThPA_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.EditField_LAMBDA_ThPA_3.Editable = 'off';
            app.EditField_LAMBDA_ThPA_3.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_ThPA_3.Value = 532;
            app.Label_44 = uilabel(app.GridLayout8_3);
            app.Label_44.Tooltip = {'Beam Waist'};
            app.Label_44.Text = 'w0 :';
            app.EditField_W0_ThPA_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.EditField_W0_ThPA_3.Editable = 'off';
            app.EditField_W0_ThPA_3.Tooltip = {'Beam Waist'};
            app.ZLabel_9 = uilabel(app.GridLayout8_3);
            app.ZLabel_9.Tag = 'Rayleigh Range';
            app.ZLabel_9.Tooltip = {'Rayleigh length'};
            app.ZLabel_9.Text = 'Z :';
            app.EditField_RL_ThPA_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.EditField_RL_ThPA_3.Editable = 'off';
            app.EditField_RL_ThPA_3.Tooltip = {'Rayleigh length'};
            app.iLabel_9 = uilabel(app.GridLayout8_3);
            app.iLabel_9.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_9.Text = 'i :';
            app.EditField_ITILB_ThPA_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.EditField_ITILB_ThPA_3.Editable = 'off';
            app.EditField_ITILB_ThPA_3.Tooltip = {'Intensity of the incident laser beam'};
            app.Label_45 = uilabel(app.GridLayout8_3);
            app.Label_45.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_45.Text = 'ß :';
            app.EditField_NAC_ThPA_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.EditField_NAC_ThPA_3.Editable = 'off';
            app.EditField_NAC_ThPA_3.Tooltip = {'Nonlinear absorption coefficient'};
            app.LLabel_9 = uilabel(app.GridLayout8_3);
            app.LLabel_9.Tooltip = {'Thickness of the material'};
            app.LLabel_9.Text = 'L :';
            app.EditField_TTM_ThPA_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.EditField_TTM_ThPA_3.Editable = 'off';
            app.EditField_TTM_ThPA_3.Tooltip = {'Thickness of the material'};
            app.UnitsoftheparametersListBoxLabel_3 = uilabel(app.GridLayout3_3);
            app.UnitsoftheparametersListBoxLabel_3.Text = 'Units of the parameters :';
            app.Units_ListBox_3 = uilistbox(app.GridLayout3_3);
            app.Units_ListBox_3.Items = {'millimeter', 'centimeter', 'meter'};
            app.Units_ListBox_3.Value = 'millimeter';
            app.EditedDataSetLabel_3 = uilabel(app.GridLayout_3);
            app.EditedDataSetLabel_3.Text = 'Edited Data Set';
            app.dataTableEdited_3 = uitable(app.GridLayout_3);
            app.dataTableEdited_3.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTableEdited_3.RowName = {};
            app.dataTableEdited_3.ColumnSortable = true;
            app.dataTableEdited_3.ColumnEditable = true;
            app.dataTableFit_3 = uitable(app.GridLayout_3);
            app.dataTableFit_3.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTableFit_3.RowName = {};
            app.dataTableFit_3.ColumnSortable = true;
            app.dataTableFit_3.ColumnEditable = true;
            app.dataTable_3 = uitable(app.GridLayout_3);
            app.dataTable_3.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTable_3.RowName = {};
            app.dataTable_3.ColumnSortable = true;
            app.dataTable_3.ColumnEditable = true;
            app.OriginalDataSetLabel_3 = uilabel(app.GridLayout_3);
            app.OriginalDataSetLabel_3.Text = 'Original Data Set';
            app.ExcellImportButton_3 = uibutton(app.GridLayout_3, 'push');
            app.ExcellImportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExcellImportButtonPushed, true);
            app.ExcellImportButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.ExcellImportButton_3.Text = 'Excell Import   ';
            app.DataSetterField1_3 = uieditfield(app.GridLayout_3, 'numeric');
            app.DataSetterField1_3.Value = -Inf;
            app.DataSetterField2_3 = uieditfield(app.GridLayout_3, 'numeric');
            app.DataSetterField2_3.Value = Inf;
            app.zLabel_3 = uilabel(app.GridLayout_3);
            app.zLabel_3.Text = '< z <';
            app.DatabetweenLabel_3 = uilabel(app.GridLayout_3);
            app.DatabetweenLabel_3.Text = 'Data between :';
            app.EditDataSetButton_3 = uibutton(app.GridLayout_3, 'push');
            app.EditDataSetButton_3.ButtonPushedFcn = createCallbackFcn(app, @EditDataSetButtonPushed, true);
            app.EditDataSetButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward.png');
            app.EditDataSetButton_3.Text = 'Edit Data Set';
            app.DataSetterField3_3 = uieditfield(app.GridLayout_3, 'numeric');
            app.XvalueforMLabel_3 = uilabel(app.GridLayout_3);
            app.XvalueforMLabel_3.Text = 'Shift on x-direction :';
            app.NormalisedLabel_3 = uilabel(app.GridLayout_3);
            app.NormalisedLabel_3.Text = 'Normalised :';
            app.NormalisedCheckBox_3 = uicheckbox(app.GridLayout_3);
            app.NormalisedCheckBox_3.Text = '';
            app.NormalisedCheckBox_3.Value = true;
            app.GetthescatteringandfittingLabel_3 = uilabel(app.GridLayout_3);
            app.GetthescatteringandfittingLabel_3.Text = 'Get the scattering and fitting :';
            app.ExcellExportButton_3 = uibutton(app.GridLayout_3, 'push');
            app.ExcellExportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExcellExportButtonPushed, true);
            app.ExcellExportButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'export.png');
            app.ExcellExportButton_3.Text = 'Excell Export';
            app.FitDataSetLabel_3 = uilabel(app.GridLayout_3);
            app.FitDataSetLabel_3.Text = 'Fit Data Set';
            app.FitCheckBox_3 = uicheckbox(app.GridLayout_3);
            app.FitCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @FitCheckBoxValueChanged, true);
            app.FitCheckBox_3.Text = '';
            app.Input4 = uitab(app.TabGroup);
            app.Input4.Title = 'Input 4';
            app.Input4.ButtonDownFcn = createCallbackFcn(app, @InputButtonDown, true);
            app.Input4.Tag = 'InputTab4';
            app.GridLayout_4 = uigridlayout(app.Input4);
            app.Panel_7 = uipanel(app.GridLayout_4);
            app.GridLayout4_4 = uigridlayout(app.Panel_7);
            app.graphEdited_4 = uiaxes(app.GridLayout4_4);
            title(app.graphEdited_4, 'Edited Data')
            xlabel(app.graphEdited_4, 'Z (mm)')
            ylabel(app.graphEdited_4, 'Intensity')
            app.graphOriginal_4 = uiaxes(app.GridLayout4_4);
            title(app.graphOriginal_4, 'Original Data')
            xlabel(app.graphOriginal_4, 'Z (mm)')
            ylabel(app.graphOriginal_4, 'Intensity')
            app.RsquareValueEditField_4Label = uilabel(app.GridLayout4_4);
            app.RsquareValueEditField_4Label.Text = 'R square Value :';
            app.RsquareValueEditField_4 = uieditfield(app.GridLayout4_4, 'numeric');
            app.RsquareValueEditField_4.Editable = 'off';
            app.Panel_8 = uipanel(app.GridLayout_4);
            app.GridLayout3_4 = uigridlayout(app.Panel_8);
            app.fittingMethods_4 = uilistbox(app.GridLayout3_4);
            app.fittingMethods_4.Items = {'', 'Open Aperture Z-Scan', 'Closed Aperture Z-Scan', 'Two Photon Absorption', 'Three Photon Absorption'};
            app.fittingMethods_4.ValueChangedFcn = createCallbackFcn(app, @fittingMethodsValueChanged, true);
            app.fittingMethods_4.Tag = 'fittingMethods_4';
            app.fittingMethods_4.Value = '';
            app.FittingmethodListBoxLabel_4 = uilabel(app.GridLayout3_4);
            app.FittingmethodListBoxLabel_4.Text = 'Fitting method';
            app.ParametersForFittingLabel_4 = uilabel(app.GridLayout3_4);
            app.ParametersForFittingLabel_4.Text = 'Parameters for Fitting';
            app.DevelopedbyMuhammedBektaolu2024Button_4 = uibutton(app.GridLayout3_4, 'push');
            app.DevelopedbyMuhammedBektaolu2024Button_4.ButtonPushedFcn = createCallbackFcn(app, @DevelopedbyButtonPushed, true);
            app.DevelopedbyMuhammedBektaolu2024Button_4.Text = {'Developed by Muhammed Bektaşoğlu'; '© 2024'};
            app.TabGroup2_4 = uitabgroup(app.GridLayout3_4);
            app.OpenApertureZScanTab_4 = uitab(app.TabGroup2_4);
            app.OpenApertureZScanTab_4.Title = 'Open Aperture Z-Scan';
            app.GridLayout5_4 = uigridlayout(app.OpenApertureZScanTab_4);
            app.LLabel_10 = uilabel(app.GridLayout5_4);
            app.LLabel_10.Tooltip = {'Thickness of the material'};
            app.LLabel_10.Text = 'L :';
            app.EditField_TTM_OA_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.EditField_TTM_OA_4.Editable = 'off';
            app.EditField_TTM_OA_4.Tooltip = {'Thickness of the material'};
            app.Label_46 = uilabel(app.GridLayout5_4);
            app.Label_46.Tooltip = {'Wavelength of laser'};
            app.Label_46.Text = 'λ :';
            app.EditField_LAMBDA_OA_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.EditField_LAMBDA_OA_4.Editable = 'off';
            app.EditField_LAMBDA_OA_4.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_OA_4.Value = 532;
            app.Label_47 = uilabel(app.GridLayout5_4);
            app.Label_47.Tooltip = {'Linear absorption coefficient'};
            app.Label_47.Text = 'α :';
            app.EditField_alpha_OA_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.EditField_alpha_OA_4.Editable = 'off';
            app.EditField_alpha_OA_4.Tooltip = {'Linear absorption coefficient'};
            app.Label_48 = uilabel(app.GridLayout5_4);
            app.Label_48.Tooltip = {'Beam Waist'};
            app.Label_48.Text = 'w0 :';
            app.EditField_W0_OA_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.EditField_W0_OA_4.Editable = 'off';
            app.EditField_W0_OA_4.Tooltip = {'Beam Waist'};
            app.Label_49 = uilabel(app.GridLayout5_4);
            app.Label_49.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_49.Text = 'ß :';
            app.EditField_NAC_OA_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.EditField_NAC_OA_4.Editable = 'off';
            app.EditField_NAC_OA_4.Tooltip = {'Nonlinear absorption coefficient'};
            app.ZLabel_10 = uilabel(app.GridLayout5_4);
            app.ZLabel_10.Tag = 'Rayleigh Range';
            app.ZLabel_10.Tooltip = {'Rayleigh length'};
            app.ZLabel_10.Text = 'Z :';
            app.EditField_RL_OA_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.EditField_RL_OA_4.Editable = 'off';
            app.EditField_RL_OA_4.Tooltip = {'Rayleigh length'};
            app.iLabel_10 = uilabel(app.GridLayout5_4);
            app.iLabel_10.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_10.Text = 'i :';
            app.EditField_ITILB_OA_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.EditField_ITILB_OA_4.Editable = 'off';
            app.EditField_ITILB_OA_4.Tooltip = {'Intensity of the incident laser beam'};
            app.Label_50 = uilabel(app.GridLayout5_4);
            app.Label_50.Tooltip = {'Effective Length of the Sample'};
            app.Label_50.Text = 'Leff :';
            app.EditField_Leff_OA_4 = uieditfield(app.GridLayout5_4, 'numeric');
            app.EditField_Leff_OA_4.Editable = 'off';
            app.EditField_Leff_OA_4.Tooltip = {'Effective Length of the Sample'};
            app.CheckBoxOA8_4 = uicheckbox(app.GridLayout5_4);
            app.CheckBoxOA8_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA8ValueChanged, true);
            app.CheckBoxOA8_4.Text = '';
            app.CheckBoxOA7_4 = uicheckbox(app.GridLayout5_4);
            app.CheckBoxOA7_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA7ValueChanged, true);
            app.CheckBoxOA7_4.Text = '';
            app.CheckBoxOA6_4 = uicheckbox(app.GridLayout5_4);
            app.CheckBoxOA6_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA6ValueChanged, true);
            app.CheckBoxOA6_4.Text = '';
            app.CheckBoxOA5_4 = uicheckbox(app.GridLayout5_4);
            app.CheckBoxOA5_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA5ValueChanged, true);
            app.CheckBoxOA5_4.Text = '';
            app.CheckBoxOA4_4 = uicheckbox(app.GridLayout5_4);
            app.CheckBoxOA4_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA4ValueChanged, true);
            app.CheckBoxOA4_4.Text = '';
            app.CheckBoxOA3_4 = uicheckbox(app.GridLayout5_4);
            app.CheckBoxOA3_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA3ValueChanged, true);
            app.CheckBoxOA3_4.Text = '';
            app.CheckBoxOA2_4 = uicheckbox(app.GridLayout5_4);
            app.CheckBoxOA2_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA2ValueChanged, true);
            app.CheckBoxOA2_4.Text = '';
            app.CheckBoxOA1_4 = uicheckbox(app.GridLayout5_4);
            app.CheckBoxOA1_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA1ValueChanged, true);
            app.CheckBoxOA1_4.Text = '';
            app.ClosedApertureZScanTab_4 = uitab(app.TabGroup2_4);
            app.ClosedApertureZScanTab_4.Title = 'Closed Aperture Z-Scan';
            app.GridLayout6_4 = uigridlayout(app.ClosedApertureZScanTab_4);
            app.Label_82 = uilabel(app.GridLayout6_4);
            app.Label_82.Tooltip = {'Wavelength of laser'};
            app.Label_82.Text = 'λ :';
            app.ZLabel_19 = uilabel(app.GridLayout6_4);
            app.ZLabel_19.Tag = 'Rayleigh Range';
            app.ZLabel_19.Tooltip = {'Rayleigh length'};
            app.ZLabel_19.Text = 'Z :';
            app.EditField_RL_CA_4 = uieditfield(app.GridLayout6_4, 'numeric');
            app.EditField_RL_CA_4.Editable = 'off';
            app.EditField_RL_CA_4.Tooltip = {'Rayleigh length'};
            app.Label_83 = uilabel(app.GridLayout6_4);
            app.Label_83.Tooltip = {'Beam Waist'};
            app.Label_83.Text = 'w0 :';
            app.EditField_W0_CA_4 = uieditfield(app.GridLayout6_4, 'numeric');
            app.EditField_W0_CA_4.Editable = 'off';
            app.EditField_W0_CA_4.Tooltip = {'Beam Waist'};
            app.EditField_LAMBDA_CA_4 = uieditfield(app.GridLayout6_4, 'numeric');
            app.EditField_LAMBDA_CA_4.Editable = 'off';
            app.EditField_LAMBDA_CA_4.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_CA_4.Value = 532;
            app.CheckBoxCA6_4 = uicheckbox(app.GridLayout6_4);
            app.CheckBoxCA6_4.Text = '';
            app.CheckBoxCA4_4 = uicheckbox(app.GridLayout6_4);
            app.CheckBoxCA4_4.Text = '';
            app.CheckBoxCA2_4 = uicheckbox(app.GridLayout6_4);
            app.CheckBoxCA2_4.Text = '';
            app.TwoPhotonAbsorptionTab_4 = uitab(app.TabGroup2_4);
            app.TwoPhotonAbsorptionTab_4.Title = 'Two Photon Absorption';
            app.GridLayout7_4 = uigridlayout(app.TwoPhotonAbsorptionTab_4);
            app.CheckBoxTPA8_4 = uicheckbox(app.GridLayout7_4);
            app.CheckBoxTPA8_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA8ValueChanged, true);
            app.CheckBoxTPA8_4.Text = '';
            app.CheckBoxTPA7_4 = uicheckbox(app.GridLayout7_4);
            app.CheckBoxTPA7_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA7ValueChanged, true);
            app.CheckBoxTPA7_4.Text = '';
            app.CheckBoxTPA6_4 = uicheckbox(app.GridLayout7_4);
            app.CheckBoxTPA6_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA6ValueChanged, true);
            app.CheckBoxTPA6_4.Text = '';
            app.CheckBoxTPA5_4 = uicheckbox(app.GridLayout7_4);
            app.CheckBoxTPA5_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA5ValueChanged, true);
            app.CheckBoxTPA5_4.Text = '';
            app.CheckBoxTPA4_4 = uicheckbox(app.GridLayout7_4);
            app.CheckBoxTPA4_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA4ValueChanged, true);
            app.CheckBoxTPA4_4.Text = '';
            app.CheckBoxTPA3_4 = uicheckbox(app.GridLayout7_4);
            app.CheckBoxTPA3_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA3ValueChanged, true);
            app.CheckBoxTPA3_4.Text = '';
            app.CheckBoxTPA2_4 = uicheckbox(app.GridLayout7_4);
            app.CheckBoxTPA2_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA2ValueChanged, true);
            app.CheckBoxTPA2_4.Text = '';
            app.CheckBoxTPA1_4 = uicheckbox(app.GridLayout7_4);
            app.CheckBoxTPA1_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA1ValueChanged, true);
            app.CheckBoxTPA1_4.Text = '';
            app.EditField_NAC_TPA_4 = uieditfield(app.GridLayout7_4, 'numeric');
            app.EditField_NAC_TPA_4.Editable = 'off';
            app.EditField_NAC_TPA_4.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_51 = uilabel(app.GridLayout7_4);
            app.Label_51.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_51.Text = 'ß :';
            app.EditField_ITILB_TPA_4 = uieditfield(app.GridLayout7_4, 'numeric');
            app.EditField_ITILB_TPA_4.Editable = 'off';

            app.EditField_ITILB_TPA_4.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_11 = uilabel(app.GridLayout7_4);
            app.iLabel_11.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_11.Text = 'i :';
            app.Label_52 = uilabel(app.GridLayout7_4);
            app.Label_52.Tooltip = {'Effective Length of the Sample'};
            app.Label_52.Text = 'Leff :';
            app.EditField_Leff_TPA_4 = uieditfield(app.GridLayout7_4, 'numeric');
            app.EditField_Leff_TPA_4.Editable = 'off';
            app.EditField_Leff_TPA_4.Tooltip = {'Effective Length of the Sample'};
            app.Label_53 = uilabel(app.GridLayout7_4);
            app.Label_53.Tooltip = {'Linear absorption coefficient'};
            app.Label_53.Text = 'α :';
            app.EditField_alpha_TPA_4 = uieditfield(app.GridLayout7_4, 'numeric');
            app.EditField_alpha_TPA_4.Editable = 'off';
            app.EditField_alpha_TPA_4.Tooltip = {'Linear absorption coefficient'};
            app.EditField_RL_TPA_4 = uieditfield(app.GridLayout7_4, 'numeric');
            app.EditField_RL_TPA_4.Editable = 'off';
            app.EditField_RL_TPA_4.Tooltip = {'Rayleigh length'};
            app.ZLabel_11 = uilabel(app.GridLayout7_4);
            app.ZLabel_11.Tag = 'Rayleigh Range';
            app.ZLabel_11.Tooltip = {'Rayleigh length'};
            app.ZLabel_11.Text = 'Z :';
            app.EditField_W0_TPA_4 = uieditfield(app.GridLayout7_4, 'numeric');
            app.EditField_W0_TPA_4.Editable = 'off';
            app.EditField_W0_TPA_4.Tooltip = {'Beam Waist'};
            app.Label_54 = uilabel(app.GridLayout7_4);
            app.Label_54.Tooltip = {'Beam Waist'};
            app.Label_54.Text = 'w0 :';
            app.EditField_LAMBDA_TPA_4 = uieditfield(app.GridLayout7_4, 'numeric');
            app.EditField_LAMBDA_TPA_4.Editable = 'off';
            app.EditField_LAMBDA_TPA_4.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_TPA_4.Value = 532;
            app.Label_55 = uilabel(app.GridLayout7_4);
            app.Label_55.Tooltip = {'Wavelength of laser'};
            app.Label_55.Text = 'λ :';
            app.LLabel_11 = uilabel(app.GridLayout7_4);
            app.LLabel_11.Tooltip = {'Thickness of the material'};
            app.LLabel_11.Text = 'L :';
            app.EditField_TTM_TPA_4 = uieditfield(app.GridLayout7_4, 'numeric');
            app.EditField_TTM_TPA_4.Editable = 'off';
            app.EditField_TTM_TPA_4.Tooltip = {'Thickness of the material'};
            app.ThreePhotonAbsorptionTab_4 = uitab(app.TabGroup2_4);
            app.ThreePhotonAbsorptionTab_4.Title = 'Three Photon Absorption';
            app.GridLayout8_4 = uigridlayout(app.ThreePhotonAbsorptionTab_4);
            app.CheckBoxThPA8_4 = uicheckbox(app.GridLayout8_4);
            app.CheckBoxThPA8_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA8ValueChanged, true);
            app.CheckBoxThPA8_4.Text = '';
            app.CheckBoxThPA7_4 = uicheckbox(app.GridLayout8_4);
            app.CheckBoxThPA7_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA7ValueChanged, true);
            app.CheckBoxThPA7_4.Text = '';
            app.CheckBoxThPA6_4 = uicheckbox(app.GridLayout8_4);
            app.CheckBoxThPA6_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA6ValueChanged, true);
            app.CheckBoxThPA6_4.Text = '';
            app.CheckBoxThPA5_4 = uicheckbox(app.GridLayout8_4);
            app.CheckBoxThPA5_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA5ValueChanged, true);
            app.CheckBoxThPA5_4.Text = '';
            app.CheckBoxThPA4_4 = uicheckbox(app.GridLayout8_4);
            app.CheckBoxThPA4_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA4ValueChanged, true);
            app.CheckBoxThPA4_4.Text = '';
            app.CheckBoxThPA3_4 = uicheckbox(app.GridLayout8_4);
            app.CheckBoxThPA3_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA3ValueChanged, true);
            app.CheckBoxThPA3_4.Text = '';
            app.CheckBoxThPA2_4 = uicheckbox(app.GridLayout8_4);
            app.CheckBoxThPA2_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA2ValueChanged, true);
            app.CheckBoxThPA2_4.Text = '';
            app.CheckBoxThPA1_4 = uicheckbox(app.GridLayout8_4);
            app.CheckBoxThPA1_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA1ValueChanged, true);
            app.CheckBoxThPA1_4.Text = '';
            app.Label_56 = uilabel(app.GridLayout8_4);
            app.Label_56.Tooltip = {'Linear absorption coefficient'};
            app.Label_56.Text = 'α :';
            app.EditField_alpha_ThPA_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.EditField_alpha_ThPA_4.Editable = 'off';
            app.EditField_alpha_ThPA_4.Tooltip = {'Linear absorption coefficient'};
            app.Label_57 = uilabel(app.GridLayout8_4);
            app.Label_57.Tooltip = {'Effective Length of the Sample'};
            app.Label_57.Text = 'Leff :';
            app.EditField_Leff_ThPA_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.EditField_Leff_ThPA_4.Editable = 'off';
            app.EditField_Leff_ThPA_4.Tooltip = {'Effective Length of the Sample'};
            app.Label_58 = uilabel(app.GridLayout8_4);
            app.Label_58.Tooltip = {'Wavelength of laser'};
            app.Label_58.Text = 'λ :';
            app.EditField_LAMBDA_ThPA_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.EditField_LAMBDA_ThPA_4.Editable = 'off';
            app.EditField_LAMBDA_ThPA_4.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_ThPA_4.Value = 532;
            app.Label_59 = uilabel(app.GridLayout8_4);
            app.Label_59.Tooltip = {'Beam Waist'};
            app.Label_59.Text = 'w0 :';
            app.EditField_W0_ThPA_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.EditField_W0_ThPA_4.Editable = 'off';
            app.EditField_W0_ThPA_4.Tooltip = {'Beam Waist'};
            app.ZLabel_12 = uilabel(app.GridLayout8_4);
            app.ZLabel_12.Tag = 'Rayleigh Range';
            app.ZLabel_12.Tooltip = {'Rayleigh length'};
            app.ZLabel_12.Text = 'Z :';
            app.EditField_RL_ThPA_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.EditField_RL_ThPA_4.Editable = 'off';
            app.EditField_RL_ThPA_4.Tooltip = {'Rayleigh length'};
            app.iLabel_12 = uilabel(app.GridLayout8_4);
            app.iLabel_12.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_12.Text = 'i :';
            app.EditField_ITILB_ThPA_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.EditField_ITILB_ThPA_4.Editable = 'off';
            app.EditField_ITILB_ThPA_4.Tooltip = {'Intensity of the incident laser beam'};
            app.Label_60 = uilabel(app.GridLayout8_4);
            app.Label_60.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_60.Text = 'ß :';
            app.EditField_NAC_ThPA_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.EditField_NAC_ThPA_4.Editable = 'off';
            app.EditField_NAC_ThPA_4.Tooltip = {'Nonlinear absorption coefficient'};
            app.LLabel_12 = uilabel(app.GridLayout8_4);
            app.LLabel_12.Tooltip = {'Thickness of the material'};
            app.LLabel_12.Text = 'L :';
            app.EditField_TTM_ThPA_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.EditField_TTM_ThPA_4.Editable = 'off';
            app.EditField_TTM_ThPA_4.Tooltip = {'Thickness of the material'};
            app.UnitsoftheparametersListBoxLabel_4 = uilabel(app.GridLayout3_4);
            app.UnitsoftheparametersListBoxLabel_4.Text = 'Units of the parameters :';
            app.Units_ListBox_4 = uilistbox(app.GridLayout3_4);
            app.Units_ListBox_4.Items = {'millimeter', 'centimeter', 'meter'};
            app.Units_ListBox_4.Value = 'millimeter';
            app.EditedDataSetLabel_4 = uilabel(app.GridLayout_4);
            app.EditedDataSetLabel_4.Text = 'Edited Data Set';
            app.dataTableEdited_4 = uitable(app.GridLayout_4);
            app.dataTableEdited_4.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTableEdited_4.RowName = {};
            app.dataTableEdited_4.ColumnSortable = true;
            app.dataTableEdited_4.ColumnEditable = true;
            app.dataTableFit_4 = uitable(app.GridLayout_4);
            app.dataTableFit_4.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTableFit_4.RowName = {};
            app.dataTableFit_4.ColumnSortable = true;
            app.dataTableFit_4.ColumnEditable = true;
            app.dataTable_4 = uitable(app.GridLayout_4);
            app.dataTable_4.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTable_4.RowName = {};
            app.dataTable_4.ColumnSortable = true;
            app.dataTable_4.ColumnEditable = true;
            app.OriginalDataSetLabel_4 = uilabel(app.GridLayout_4);
            app.OriginalDataSetLabel_4.Text = 'Original Data Set';
            app.ExcellImportButton_4 = uibutton(app.GridLayout_4, 'push');
            app.ExcellImportButton_4.ButtonPushedFcn = createCallbackFcn(app, @ExcellImportButtonPushed, true);
            app.ExcellImportButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.ExcellImportButton_4.Text = 'Excell Import   ';
            app.DataSetterField1_4 = uieditfield(app.GridLayout_4, 'numeric');
            app.DataSetterField1_4.Value = -Inf;
            app.DataSetterField2_4 = uieditfield(app.GridLayout_4, 'numeric');
            app.DataSetterField2_4.Value = Inf;
            app.zLabel_4 = uilabel(app.GridLayout_4);
            app.zLabel_4.Text = '< z <';
            app.DatabetweenLabel_4 = uilabel(app.GridLayout_4);
            app.DatabetweenLabel_4.Text = 'Data between :';
            app.EditDataSetButton_4 = uibutton(app.GridLayout_4, 'push');
            app.EditDataSetButton_4.ButtonPushedFcn = createCallbackFcn(app, @EditDataSetButtonPushed, true);
            app.EditDataSetButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward.png');
            app.EditDataSetButton_4.Text = 'Edit Data Set';
            app.DataSetterField3_4 = uieditfield(app.GridLayout_4, 'numeric');
            app.XvalueforMLabel_4 = uilabel(app.GridLayout_4);
            app.XvalueforMLabel_4.Text = 'Shift on x-direction :';
            app.NormalisedLabel_4 = uilabel(app.GridLayout_4);
            app.NormalisedLabel_4.Text = 'Normalised :';
            app.NormalisedCheckBox_4 = uicheckbox(app.GridLayout_4);
            app.NormalisedCheckBox_4.Text = '';
            app.NormalisedCheckBox_4.Value = true;
            app.GetthescatteringandfittingLabel_4 = uilabel(app.GridLayout_4);
            app.GetthescatteringandfittingLabel_4.Text = 'Get the scattering and fitting :';
            app.ExcellExportButton_4 = uibutton(app.GridLayout_4, 'push');
            app.ExcellExportButton_4.ButtonPushedFcn = createCallbackFcn(app, @ExcellExportButtonPushed, true);
            app.ExcellExportButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'export.png');
            app.ExcellExportButton_4.Text = 'Excell Export';
            app.FitDataSetLabel_4 = uilabel(app.GridLayout_4);
            app.FitDataSetLabel_4.Text = 'Fit Data Set';
            app.FitCheckBox_4 = uicheckbox(app.GridLayout_4);
            app.FitCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @FitCheckBoxValueChanged, true);
            app.FitCheckBox_4.Text = '';
            app.Input5 = uitab(app.TabGroup);
            app.Input5.Title = 'Input 5';
            app.Input5.ButtonDownFcn = createCallbackFcn(app, @InputButtonDown, true);
            app.Input5.Tag = 'InputTab5';
            app.GridLayout_5 = uigridlayout(app.Input5);
            app.Panel_9 = uipanel(app.GridLayout_5);
            app.GridLayout4_5 = uigridlayout(app.Panel_9);
            app.graphEdited_5 = uiaxes(app.GridLayout4_5);
            title(app.graphEdited_5, 'Edited Data')
            xlabel(app.graphEdited_5, 'Z (mm)')
            ylabel(app.graphEdited_5, 'Intensity')
            app.graphOriginal_5 = uiaxes(app.GridLayout4_5);
            title(app.graphOriginal_5, 'Original Data')
            xlabel(app.graphOriginal_5, 'Z (mm)')
            ylabel(app.graphOriginal_5, 'Intensity')
            app.RsquareValueEditField_5Label = uilabel(app.GridLayout4_5);
            app.RsquareValueEditField_5Label.Text = 'R square Value :';
            app.RsquareValueEditField_5 = uieditfield(app.GridLayout4_5, 'numeric');
            app.RsquareValueEditField_5.Editable = 'off';
            app.Panel_10 = uipanel(app.GridLayout_5);
            app.GridLayout3_5 = uigridlayout(app.Panel_10);
            app.fittingMethods_5 = uilistbox(app.GridLayout3_5);
            app.fittingMethods_5.Items = {'', 'Open Aperture Z-Scan', 'Closed Aperture Z-Scan', 'Two Photon Absorption', 'Three Photon Absorption'};
            app.fittingMethods_5.ValueChangedFcn = createCallbackFcn(app, @fittingMethodsValueChanged, true);
            app.fittingMethods_5.Tag = 'fittingMethods_5';
            app.fittingMethods_5.Value = '';
            app.FittingmethodListBoxLabel_5 = uilabel(app.GridLayout3_5);
            app.FittingmethodListBoxLabel_5.Text = 'Fitting method';
            app.ParametersForFittingLabel_5 = uilabel(app.GridLayout3_5);
            app.ParametersForFittingLabel_5.Text = 'Parameters for Fitting';
            app.DevelopedbyMuhammedBektaolu2024Button_5 = uibutton(app.GridLayout3_5, 'push');
            app.DevelopedbyMuhammedBektaolu2024Button_5.ButtonPushedFcn = createCallbackFcn(app, @DevelopedbyButtonPushed, true);
            app.DevelopedbyMuhammedBektaolu2024Button_5.Text = {'Developed by Muhammed Bektaşoğlu'; '© 2024'};
            app.TabGroup2_5 = uitabgroup(app.GridLayout3_5);
            app.OpenApertureZScanTab_5 = uitab(app.TabGroup2_5);
            app.OpenApertureZScanTab_5.Title = 'Open Aperture Z-Scan';
            app.GridLayout5_5 = uigridlayout(app.OpenApertureZScanTab_5);
            app.LLabel_13 = uilabel(app.GridLayout5_5);
            app.LLabel_13.Tooltip = {'Thickness of the material'};
            app.LLabel_13.Text = 'L :';
            app.EditField_TTM_OA_5 = uieditfield(app.GridLayout5_5, 'numeric');
            app.EditField_TTM_OA_5.Editable = 'off';
            app.EditField_TTM_OA_5.Tooltip = {'Thickness of the material'};
            app.Label_61 = uilabel(app.GridLayout5_5);
            app.Label_61.Tooltip = {'Wavelength of laser'};
            app.Label_61.Text = 'λ :';
            app.EditField_LAMBDA_OA_5 = uieditfield(app.GridLayout5_5, 'numeric');
            app.EditField_LAMBDA_OA_5.Editable = 'off';
            app.EditField_LAMBDA_OA_5.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_OA_5.Value = 532;
            app.Label_62 = uilabel(app.GridLayout5_5);
            app.Label_62.Tooltip = {'Linear absorption coefficient'};
            app.Label_62.Text = 'α :';
            app.EditField_alpha_OA_5 = uieditfield(app.GridLayout5_5, 'numeric');
            app.EditField_alpha_OA_5.Editable = 'off';
            app.EditField_alpha_OA_5.Tooltip = {'Linear absorption coefficient'};
            app.Label_63 = uilabel(app.GridLayout5_5);
            app.Label_63.Tooltip = {'Beam Waist'};
            app.Label_63.Text = 'w0 :';
            app.EditField_W0_OA_5 = uieditfield(app.GridLayout5_5, 'numeric');
            app.EditField_W0_OA_5.Editable = 'off';
            app.EditField_W0_OA_5.Tooltip = {'Beam Waist'};
            app.Label_64 = uilabel(app.GridLayout5_5);
            app.Label_64.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_64.Text = 'ß :';
            app.EditField_NAC_OA_5 = uieditfield(app.GridLayout5_5, 'numeric');
            app.EditField_NAC_OA_5.Editable = 'off';
            app.EditField_NAC_OA_5.Tooltip = {'Nonlinear absorption coefficient'};
            app.ZLabel_13 = uilabel(app.GridLayout5_5);
            app.ZLabel_13.Tag = 'Rayleigh Range';
            app.ZLabel_13.Tooltip = {'Rayleigh length'};
            app.ZLabel_13.Text = 'Z :';
            app.EditField_RL_OA_5 = uieditfield(app.GridLayout5_5, 'numeric');
            app.EditField_RL_OA_5.Editable = 'off';
            app.EditField_RL_OA_5.Tooltip = {'Rayleigh length'};
            app.iLabel_13 = uilabel(app.GridLayout5_5);
            app.iLabel_13.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_13.Text = 'i :';
            app.EditField_ITILB_OA_5 = uieditfield(app.GridLayout5_5, 'numeric');
            app.EditField_ITILB_OA_5.Editable = 'off';
            app.EditField_ITILB_OA_5.Tooltip = {'Intensity of the incident laser beam'};
            app.Label_65 = uilabel(app.GridLayout5_5);
            app.Label_65.Tooltip = {'Effective Length of the Sample'};
            app.Label_65.Text = 'Leff :';
            app.EditField_Leff_OA_5 = uieditfield(app.GridLayout5_5, 'numeric');
            app.EditField_Leff_OA_5.Editable = 'off';
            app.EditField_Leff_OA_5.Tooltip = {'Effective Length of the Sample'};
            app.CheckBoxOA8_5 = uicheckbox(app.GridLayout5_5);
            app.CheckBoxOA8_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA8ValueChanged, true);
            app.CheckBoxOA8_5.Text = '';
            app.CheckBoxOA7_5 = uicheckbox(app.GridLayout5_5);
            app.CheckBoxOA7_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA7ValueChanged, true);
            app.CheckBoxOA7_5.Text = '';
            app.CheckBoxOA6_5 = uicheckbox(app.GridLayout5_5);
            app.CheckBoxOA6_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA6ValueChanged, true);
            app.CheckBoxOA6_5.Text = '';
            app.CheckBoxOA5_5 = uicheckbox(app.GridLayout5_5);
            app.CheckBoxOA5_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA5ValueChanged, true);
            app.CheckBoxOA5_5.Text = '';
            app.CheckBoxOA4_5 = uicheckbox(app.GridLayout5_5);
            app.CheckBoxOA4_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA4ValueChanged, true);
            app.CheckBoxOA4_5.Text = '';
            app.CheckBoxOA3_5 = uicheckbox(app.GridLayout5_5);
            app.CheckBoxOA3_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA3ValueChanged, true);
            app.CheckBoxOA3_5.Text = '';
            app.CheckBoxOA2_5 = uicheckbox(app.GridLayout5_5);
            app.CheckBoxOA2_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA2ValueChanged, true);
            app.CheckBoxOA2_5.Text = '';
            app.CheckBoxOA1_5 = uicheckbox(app.GridLayout5_5);
            app.CheckBoxOA1_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxOA1ValueChanged, true);
            app.CheckBoxOA1_5.Text = '';
            app.ClosedApertureZScanTab_5 = uitab(app.TabGroup2_5);
            app.ClosedApertureZScanTab_5.Title = 'Closed Aperture Z-Scan';
            app.GridLayout6_5 = uigridlayout(app.ClosedApertureZScanTab_5);
            app.ZLabel_20 = uilabel(app.GridLayout6_5);
            app.ZLabel_20.Tag = 'Rayleigh Range';
            app.ZLabel_20.Tooltip = {'Rayleigh length'};
            app.ZLabel_20.Text = 'Z :';
            app.EditField_RL_CA_5 = uieditfield(app.GridLayout6_5, 'numeric');
            app.EditField_RL_CA_5.Editable = 'off';
            app.EditField_RL_CA_5.Tooltip = {'Rayleigh length'};
            app.Label_84 = uilabel(app.GridLayout6_5);
            app.Label_84.Tooltip = {'Beam Waist'};
            app.Label_84.Text = 'w0 :';
            app.EditField_W0_CA_5 = uieditfield(app.GridLayout6_5, 'numeric');
            app.EditField_W0_CA_5.Editable = 'off';
            app.EditField_W0_CA_5.Tooltip = {'Beam Waist'};
            app.Label_85 = uilabel(app.GridLayout6_5);
            app.Label_85.Tooltip = {'Wavelength of laser'};
            app.Label_85.Text = 'λ :';
            app.EditField_LAMBDA_CA_5 = uieditfield(app.GridLayout6_5, 'numeric');
            app.EditField_LAMBDA_CA_5.Editable = 'off';
            app.EditField_LAMBDA_CA_5.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_CA_5.Value = 532;
            app.CheckBoxCA6_5 = uicheckbox(app.GridLayout6_5);
            app.CheckBoxCA6_5.Text = '';
            app.CheckBoxCA4_5 = uicheckbox(app.GridLayout6_5);
            app.CheckBoxCA4_5.Text = '';
            app.CheckBoxCA2_5 = uicheckbox(app.GridLayout6_5);
            app.CheckBoxCA2_5.Text = '';
            app.TwoPhotonAbsorptionTab_5 = uitab(app.TabGroup2_5);
            app.TwoPhotonAbsorptionTab_5.Title = 'Two Photon Absorption';
            app.GridLayout7_5 = uigridlayout(app.TwoPhotonAbsorptionTab_5);
            app.CheckBoxTPA8_5 = uicheckbox(app.GridLayout7_5);
            app.CheckBoxTPA8_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA8ValueChanged, true);
            app.CheckBoxTPA8_5.Text = '';
            app.CheckBoxTPA7_5 = uicheckbox(app.GridLayout7_5);
            app.CheckBoxTPA7_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA7ValueChanged, true);
            app.CheckBoxTPA7_5.Text = '';
            app.CheckBoxTPA6_5 = uicheckbox(app.GridLayout7_5);
            app.CheckBoxTPA6_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA6ValueChanged, true);
            app.CheckBoxTPA6_5.Text = '';
            app.CheckBoxTPA5_5 = uicheckbox(app.GridLayout7_5);
            app.CheckBoxTPA5_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA5ValueChanged, true);
            app.CheckBoxTPA5_5.Text = '';
            app.CheckBoxTPA4_5 = uicheckbox(app.GridLayout7_5);
            app.CheckBoxTPA4_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA4ValueChanged, true);
            app.CheckBoxTPA4_5.Text = '';
            app.CheckBoxTPA3_5 = uicheckbox(app.GridLayout7_5);
            app.CheckBoxTPA3_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA3ValueChanged, true);
            app.CheckBoxTPA3_5.Text = '';
            app.CheckBoxTPA2_5 = uicheckbox(app.GridLayout7_5);
            app.CheckBoxTPA2_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA2ValueChanged, true);
            app.CheckBoxTPA2_5.Text = '';
            app.CheckBoxTPA1_5 = uicheckbox(app.GridLayout7_5);
            app.CheckBoxTPA1_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTPA1ValueChanged, true);
            app.CheckBoxTPA1_5.Text = '';
            app.EditField_NAC_TPA_5 = uieditfield(app.GridLayout7_5, 'numeric');
            app.EditField_NAC_TPA_5.Editable = 'off';
            app.EditField_NAC_TPA_5.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_66 = uilabel(app.GridLayout7_5);
            app.Label_66.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_66.Text = 'ß :';
            app.EditField_ITILB_TPA_5 = uieditfield(app.GridLayout7_5, 'numeric');
            app.EditField_ITILB_TPA_5.Editable = 'off';
            app.EditField_ITILB_TPA_5.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_14 = uilabel(app.GridLayout7_5);
            app.iLabel_14.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_14.Text = 'i :';
            app.Label_67 = uilabel(app.GridLayout7_5);
            app.Label_67.Tooltip = {'Effective Length of the Sample'};
            app.Label_67.Text = 'Leff :';
            app.EditField_Leff_TPA_5 = uieditfield(app.GridLayout7_5, 'numeric');
            app.EditField_Leff_TPA_5.Editable = 'off';
            app.EditField_Leff_TPA_5.Tooltip = {'Effective Length of the Sample'};
            app.Label_68 = uilabel(app.GridLayout7_5);
            app.Label_68.Tooltip = {'Linear absorption coefficient'};
            app.Label_68.Text = 'α :';
            app.EditField_alpha_TPA_5 = uieditfield(app.GridLayout7_5, 'numeric');
            app.EditField_alpha_TPA_5.Editable = 'off';
            app.EditField_alpha_TPA_5.Tooltip = {'Linear absorption coefficient'};
            app.EditField_RL_TPA_5 = uieditfield(app.GridLayout7_5, 'numeric');
            app.EditField_RL_TPA_5.Editable = 'off';
            app.EditField_RL_TPA_5.Tooltip = {'Rayleigh length'};
            app.ZLabel_14 = uilabel(app.GridLayout7_5);
            app.ZLabel_14.Tag = 'Rayleigh Range';
            app.ZLabel_14.Tooltip = {'Rayleigh length'};
            app.ZLabel_14.Text = 'Z :';
            app.EditField_W0_TPA_5 = uieditfield(app.GridLayout7_5, 'numeric');
            app.EditField_W0_TPA_5.Editable = 'off';
            app.EditField_W0_TPA_5.Tooltip = {'Beam Waist'};
            app.Label_69 = uilabel(app.GridLayout7_5);
            app.Label_69.Tooltip = {'Beam Waist'};
            app.Label_69.Text = 'w0 :';
            app.EditField_LAMBDA_TPA_5 = uieditfield(app.GridLayout7_5, 'numeric');
            app.EditField_LAMBDA_TPA_5.Editable = 'off';
            app.EditField_LAMBDA_TPA_5.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_TPA_5.Value = 532;
            app.Label_70 = uilabel(app.GridLayout7_5);
            app.Label_70.Tooltip = {'Wavelength of laser'};
            app.Label_70.Text = 'λ :';
            app.LLabel_14 = uilabel(app.GridLayout7_5);
            app.LLabel_14.Tooltip = {'Thickness of the material'};
            app.LLabel_14.Text = 'L :';
            app.EditField_TTM_TPA_5 = uieditfield(app.GridLayout7_5, 'numeric');
            app.EditField_TTM_TPA_5.Editable = 'off';
            app.EditField_TTM_TPA_5.Tooltip = {'Thickness of the material'};
            app.ThreePhotonAbsorptionTab_5 = uitab(app.TabGroup2_5);
            app.ThreePhotonAbsorptionTab_5.Title = 'Three Photon Absorption';
            app.GridLayout8_5 = uigridlayout(app.ThreePhotonAbsorptionTab_5);
            app.CheckBoxThPA8_5 = uicheckbox(app.GridLayout8_5);
            app.CheckBoxThPA8_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA8ValueChanged, true);
            app.CheckBoxThPA8_5.Text = '';
            app.CheckBoxThPA7_5 = uicheckbox(app.GridLayout8_5);
            app.CheckBoxThPA7_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA7ValueChanged, true);
            app.CheckBoxThPA7_5.Text = '';
            app.CheckBoxThPA6_5 = uicheckbox(app.GridLayout8_5);
            app.CheckBoxThPA6_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA6ValueChanged, true);
            app.CheckBoxThPA6_5.Text = '';
            app.CheckBoxThPA5_5 = uicheckbox(app.GridLayout8_5);
            app.CheckBoxThPA5_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA5ValueChanged, true);
            app.CheckBoxThPA5_5.Text = '';
            app.CheckBoxThPA4_5 = uicheckbox(app.GridLayout8_5);
            app.CheckBoxThPA4_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA4ValueChanged, true);
            app.CheckBoxThPA4_5.Text = '';
            app.CheckBoxThPA3_5 = uicheckbox(app.GridLayout8_5);
            app.CheckBoxThPA3_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA3ValueChanged, true);
            app.CheckBoxThPA3_5.Text = '';
            app.CheckBoxThPA2_5 = uicheckbox(app.GridLayout8_5);
            app.CheckBoxThPA2_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA2ValueChanged, true);
            app.CheckBoxThPA2_5.Text = '';
            app.CheckBoxThPA1_5 = uicheckbox(app.GridLayout8_5);
            app.CheckBoxThPA1_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxThPA1ValueChanged, true);
            app.CheckBoxThPA1_5.Text = '';
            app.Label_71 = uilabel(app.GridLayout8_5);
            app.Label_71.Tooltip = {'Linear absorption coefficient'};
            app.Label_71.Text = 'α :';
            app.EditField_alpha_ThPA_5 = uieditfield(app.GridLayout8_5, 'numeric');
            app.EditField_alpha_ThPA_5.Editable = 'off';
            app.EditField_alpha_ThPA_5.Tooltip = {'Linear absorption coefficient'};
            app.Label_72 = uilabel(app.GridLayout8_5);
            app.Label_72.Tooltip = {'Effective Length of the Sample'};
            app.Label_72.Text = 'Leff :';
            app.EditField_Leff_ThPA_5 = uieditfield(app.GridLayout8_5, 'numeric');
            app.EditField_Leff_ThPA_5.Editable = 'off';
            app.EditField_Leff_ThPA_5.Tooltip = {'Effective Length of the Sample'};
            app.Label_73 = uilabel(app.GridLayout8_5);
            app.Label_73.Tooltip = {'Wavelength of laser'};
            app.Label_73.Text = 'λ :';
            app.EditField_LAMBDA_ThPA_5 = uieditfield(app.GridLayout8_5, 'numeric');
            app.EditField_LAMBDA_ThPA_5.Editable = 'off';
            app.EditField_LAMBDA_ThPA_5.Tooltip = {'Wavelength of laser'};
            app.EditField_LAMBDA_ThPA_5.Value = 532;
            app.Label_74 = uilabel(app.GridLayout8_5);
            app.Label_74.Tooltip = {'Beam Waist'};
            app.Label_74.Text = 'w0 :';
            app.EditField_W0_ThPA_5 = uieditfield(app.GridLayout8_5, 'numeric');
            app.EditField_W0_ThPA_5.Editable = 'off';
            app.EditField_W0_ThPA_5.Tooltip = {'Beam Waist'};
            app.ZLabel_15 = uilabel(app.GridLayout8_5);
            app.ZLabel_15.Tag = 'Rayleigh Range';
            app.ZLabel_15.Tooltip = {'Rayleigh length'};
            app.ZLabel_15.Text = 'Z :';
            app.EditField_RL_ThPA_5 = uieditfield(app.GridLayout8_5, 'numeric');
            app.EditField_RL_ThPA_5.Editable = 'off';
            app.EditField_RL_ThPA_5.Tooltip = {'Rayleigh length'};
            app.iLabel_15 = uilabel(app.GridLayout8_5);
            app.iLabel_15.Tooltip = {'Intensity of the incident laser beam'};
            app.iLabel_15.Text = 'i :';
            app.EditField_ITILB_ThPA_5 = uieditfield(app.GridLayout8_5, 'numeric');
            app.EditField_ITILB_ThPA_5.Editable = 'off';
            app.EditField_ITILB_ThPA_5.Tooltip = {'Intensity of the incident laser beam'};
            app.Label_75 = uilabel(app.GridLayout8_5);
            app.Label_75.Tooltip = {'Nonlinear absorption coefficient'};
            app.Label_75.Text = 'ß :';
            app.EditField_NAC_ThPA_5 = uieditfield(app.GridLayout8_5, 'numeric');
            app.EditField_NAC_ThPA_5.Editable = 'off';
            app.EditField_NAC_ThPA_5.Tooltip = {'Nonlinear absorption coefficient'};
            app.LLabel_15 = uilabel(app.GridLayout8_5);
            app.LLabel_15.Tooltip = {'Thickness of the material'};
            app.LLabel_15.Text = 'L :';
            app.EditField_TTM_ThPA_5 = uieditfield(app.GridLayout8_5, 'numeric');
            app.EditField_TTM_ThPA_5.Editable = 'off';
            app.EditField_TTM_ThPA_5.Tooltip = {'Thickness of the material'};
            app.UnitsoftheparametersListBoxLabel_5 = uilabel(app.GridLayout3_5);
            app.UnitsoftheparametersListBoxLabel_5.Text = 'Units of the parameters :';
            app.Units_ListBox_5 = uilistbox(app.GridLayout3_5);
            app.Units_ListBox_5.Items = {'millimeter', 'centimeter', 'meter'};
            app.Units_ListBox_5.Value = 'millimeter';
            app.EditedDataSetLabel_5 = uilabel(app.GridLayout_5);
            app.EditedDataSetLabel_5.Text = 'Edited Data Set';
            app.dataTableEdited_5 = uitable(app.GridLayout_5);
            app.dataTableEdited_5.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTableEdited_5.RowName = {};
            app.dataTableEdited_5.ColumnSortable = true;
            app.dataTableEdited_5.ColumnEditable = true;
            app.dataTableFit_5 = uitable(app.GridLayout_5);
            app.dataTableFit_5.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTableFit_5.RowName = {};
            app.dataTableFit_5.ColumnSortable = true;
            app.dataTableFit_5.ColumnEditable = true;
            app.dataTable_5 = uitable(app.GridLayout_5);
            app.dataTable_5.ColumnName = {'Z (mm)'; 'Intensity'};
            app.dataTable_5.RowName = {};
            app.dataTable_5.ColumnSortable = true;
            app.dataTable_5.ColumnEditable = true;
            app.OriginalDataSetLabel_5 = uilabel(app.GridLayout_5);
            app.OriginalDataSetLabel_5.Text = 'Original Data Set';
            app.ExcellImportButton_5 = uibutton(app.GridLayout_5, 'push');
            app.ExcellImportButton_5.ButtonPushedFcn = createCallbackFcn(app, @ExcellImportButtonPushed, true);
            app.ExcellImportButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.ExcellImportButton_5.Text = 'Excell Import   ';
            app.DataSetterField1_5 = uieditfield(app.GridLayout_5, 'numeric');
            app.DataSetterField1_5.Value = -Inf;
            app.DataSetterField2_5 = uieditfield(app.GridLayout_5, 'numeric');
            app.DataSetterField2_5.Value = Inf;
            app.zLabel_5 = uilabel(app.GridLayout_5);
            app.zLabel_5.Text = '< z <';
            app.DatabetweenLabel_5 = uilabel(app.GridLayout_5);
            app.DatabetweenLabel_5.Text = 'Data between :';
            app.EditDataSetButton_5 = uibutton(app.GridLayout_5, 'push');
            app.EditDataSetButton_5.ButtonPushedFcn = createCallbackFcn(app, @EditDataSetButtonPushed, true);
            app.EditDataSetButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward.png');
            app.EditDataSetButton_5.Text = 'Edit Data Set';
            app.DataSetterField3_5 = uieditfield(app.GridLayout_5, 'numeric');
            app.XvalueforMLabel_5 = uilabel(app.GridLayout_5);
            app.XvalueforMLabel_5.Text = 'Shift on x-direction :';
            app.NormalisedLabel_5 = uilabel(app.GridLayout_5);
            app.NormalisedLabel_5.Text = 'Normalised :';
            app.NormalisedCheckBox_5 = uicheckbox(app.GridLayout_5);
            app.NormalisedCheckBox_5.Text = '';
            app.NormalisedCheckBox_5.Value = true;
            app.GetthescatteringandfittingLabel_5 = uilabel(app.GridLayout_5);
            app.GetthescatteringandfittingLabel_5.Text = 'Get the scattering and fitting :';
            app.ExcellExportButton_5 = uibutton(app.GridLayout_5, 'push');
            app.ExcellExportButton_5.ButtonPushedFcn = createCallbackFcn(app, @ExcellExportButtonPushed, true);
            app.ExcellExportButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'export.png');
            app.ExcellExportButton_5.Text = 'Excell Export';
            app.FitDataSetLabel_5 = uilabel(app.GridLayout_5);
            app.FitDataSetLabel_5.Text = 'Fit Data Set';
            app.FitCheckBox_5 = uicheckbox(app.GridLayout_5);
            app.FitCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @FitCheckBoxValueChanged, true);
            app.FitCheckBox_5.Text = '';
            app.Outputs = uitab(app.TabGroup);
            app.Outputs.Title = 'Outputs';
            app.Outputs.ButtonDownFcn = createCallbackFcn(app, @OutputsButtonDown, true);
            app.GridLayout2 = uigridlayout(app.Outputs);
            app.graphOverall = uiaxes(app.GridLayout2);
            title(app.graphOverall, 'Overall Graph')
            xlabel(app.graphOverall, 'Z (mm)')
            ylabel(app.graphOverall, 'Intensity')
            app.ButtonLineSizeEdit = uibutton(app.GridLayout2, 'push');
            app.ButtonLineSizeEdit.ButtonPushedFcn = createCallbackFcn(app, @ButtonLineSizeEditPushed, true);
            app.ButtonLineSizeEdit.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward.png');
            app.ButtonLineSizeEdit.Text = '';
            app.ButtonDotsSizeEdit = uibutton(app.GridLayout2, 'push');
            app.ButtonDotsSizeEdit.ButtonPushedFcn = createCallbackFcn(app, @ButtonDotsSizeEditPushed, true);
            app.ButtonDotsSizeEdit.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward.png');
            app.ButtonDotsSizeEdit.Text = '';
            app.ButtonLineTypesEdit = uibutton(app.GridLayout2, 'push');
            app.ButtonLineTypesEdit.ButtonPushedFcn = createCallbackFcn(app, @ButtonLineTypesEditPushed, true);
            app.ButtonLineTypesEdit.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward.png');
            app.ButtonLineTypesEdit.Text = '';
            app.ButtonDotsSymbolsEdit = uibutton(app.GridLayout2, 'push');
            app.ButtonDotsSymbolsEdit.ButtonPushedFcn = createCallbackFcn(app, @ButtonDotsSymbolsEditPushed, true);
            app.ButtonDotsSymbolsEdit.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward.png');
            app.ButtonDotsSymbolsEdit.Text = '';
            app.ButtonColorEdit = uibutton(app.GridLayout2, 'push');
            app.ButtonColorEdit.ButtonPushedFcn = createCallbackFcn(app, @ButtonColorEditPushed, true);
            app.ButtonColorEdit.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward.png');
            app.ButtonColorEdit.Text = '';
            app.ButtonLegendClose = uibutton(app.GridLayout2, 'push');
            app.ButtonLegendClose.ButtonPushedFcn = createCallbackFcn(app, @ButtonLegendClosePushed, true);
            app.ButtonLegendClose.Icon = fullfile(pathToMLAPP, 'icons', 'close.png');
            app.ButtonLegendClose.Text = '';
            app.ButtonGroup = uibuttongroup(app.GridLayout2);
            app.ButtonGroup.Title = 'Button Group';
            app.Button5 = uiradiobutton(app.ButtonGroup);
            app.Button5.Tag = 'InputButton5';
            app.Button5.Text = 'Button5';
            app.Button4 = uiradiobutton(app.ButtonGroup);
            app.Button4.Tag = 'InputButton4';
            app.Button4.Text = 'Button4';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Tag = 'InputButton3';
            app.Button3.Text = 'Button3';
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Tag = 'InputButton2';
            app.Button2.Text = 'Button2';
            app.Button1 = uiradiobutton(app.ButtonGroup);
            app.Button1.Tag = 'InputButton1';
            app.Button1.Text = 'Button1';
            app.Button1.Value = true;
            app.OutputTree = uitree(app.GridLayout2, 'checkbox');
            app.InputTreeCheckBox1 = uitreenode(app.OutputTree);
            app.InputTreeCheckBox1.Tag = 'InputTreeCheckBox1';
            app.InputTreeCheckBox1.Text = 'Input1';
            app.InputTreeCheckBox2 = uitreenode(app.OutputTree);
            app.InputTreeCheckBox2.Tag = 'InputTreeCheckBox2';
            app.InputTreeCheckBox2.Text = 'Input2';
            app.InputTreeCheckBox3 = uitreenode(app.OutputTree);
            app.InputTreeCheckBox3.Tag = 'InputTreeCheckBox3';
            app.InputTreeCheckBox3.Text = 'Input3';
            app.InputTreeCheckBox4 = uitreenode(app.OutputTree);
            app.InputTreeCheckBox4.Tag = 'InputTreeCheckBox4';
            app.InputTreeCheckBox4.Text = 'Input4';
            app.InputTreeCheckBox5 = uitreenode(app.OutputTree);
            app.InputTreeCheckBox5.Tag = 'InputTreeCheckBox5';
            app.InputTreeCheckBox5.Text = 'Input5';
            % Assign Checked Nodes
            app.OutputTree.CheckedNodesChangedFcn = createCallbackFcn(app, @OutputTreeCheckedNodesChanged, true);
            app.DotsSizeLabel = uilabel(app.GridLayout2);
            app.DotsSizeLabel.Text = 'Dots Size :';
            app.DotsSizeSlider = uislider(app.GridLayout2);
            app.DotsSizeSlider.Limits = [5 25];
            app.DotsSizeSlider.Value = 15;
            app.LineSizeLabel = uilabel(app.GridLayout2);
            app.LineSizeLabel.Text = 'Line Size :';
            app.LineSizeSlider = uislider(app.GridLayout2);
            app.LineSizeSlider.Limits = [1 10];
            app.LineSizeSlider.Value = 3;
            app.ColorDropDown = uidropdown(app.GridLayout2);
            app.ColorDropDown.Items = {'Blue', 'Green', 'Red', 'Cyan', 'Magenta', 'Yellow', 'Black'};
            app.ColorDropDown.Value = 'Blue';
            app.ColorDropDownLabel = uilabel(app.GridLayout2);
            app.ColorDropDownLabel.Text = 'Color :';
            app.InputTabsLabel = uilabel(app.GridLayout2);
            app.InputTabsLabel.Text = 'Input Tabs';
            app.ShapeDropDown = uidropdown(app.GridLayout2);
            app.ShapeDropDown.Items = {'Point', 'Circle', 'X-Mark', 'Plus', 'Star', 'Square', 'Diamond', 'Triangle (down)', 'Triangle (up)', 'Pentagram', 'Hexagram'};
            app.ShapeDropDown.Value = 'Point';
            app.SymbolsLabel = uilabel(app.GridLayout2);
            app.SymbolsLabel.Text = 'Dots Symbols :';
            app.ShapeDropDown2 = uidropdown(app.GridLayout2);
            app.ShapeDropDown2.Items = {'Solid', 'Dotted', 'Dashdot', 'Dashed'};
            app.ShapeDropDown2.Value = 'Solid';
            app.SymbolsLabel_2 = uilabel(app.GridLayout2);
            app.SymbolsLabel_2.Text = 'Line Types :';
            app.LegendsCloseLabel = uilabel(app.GridLayout2);
            app.LegendsCloseLabel.Text = 'Legends Close :';
            app.figureExport = uibutton(app.GridLayout2, 'push');
            app.figureExport.ButtonPushedFcn = createCallbackFcn(app, @figureExportButtonPushed, true);
            app.figureExport.Icon = fullfile(pathToMLAPP, 'icons', 'saveFig.png');
            app.figureExport.Text = 'Figure Export';
            app.TabGroup3 = uitabgroup(app.GridLayout2);
            app.TabOutput1 = uitab(app.TabGroup3);
            app.TabOutput1.Title = 'Tab1';
            app.TabOutput1.Tag = 'OutputTab1';
            app.Parameters1 = uigridlayout(app.TabOutput1);
            app.LeffValueLabel = uilabel(app.Parameters1);
            app.LeffValueLabel.Text = 'Effective Length of the Sample (Leff)';
            app.NonlinearabsorptioncoefficientLabel = uilabel(app.Parameters1);
            app.NonlinearabsorptioncoefficientLabel.Text = 'Nonlinear absorption coefficient (ß)';
            app.IntensityoftheincidentlaserbeamLabel = uilabel(app.Parameters1);
            app.IntensityoftheincidentlaserbeamLabel.Text = 'Intensity of the incident laser beam (i)';
            app.RayleighlengthLabel = uilabel(app.Parameters1);
            app.RayleighlengthLabel.Text = 'Rayleigh length (Z)';
            app.ReducedChiSqLabel = uilabel(app.Parameters1);
            app.ReducedChiSqLabel.Text = 'Reduced Chi-Square';
            app.RSquareCODLabel = uilabel(app.Parameters1);
            app.RSquareCODLabel.Text = 'R-Square (COD)';
            app.AdjRSquareLabel = uilabel(app.Parameters1);
            app.AdjRSquareLabel.Text = 'Adjusted R-Square';
            app.Tab1_EditField_12 = uieditfield(app.Parameters1, 'numeric');
            app.Tab1_EditField_11 = uieditfield(app.Parameters1, 'numeric');
            app.Tab1_EditField_10 = uieditfield(app.Parameters1, 'numeric');
            app.NonlinearabsorptioncoefficientLabel_6 = uilabel(app.Parameters1);
            app.NonlinearabsorptioncoefficientLabel_6.Text = 'D';
            app.Tab1_EditField_9 = uieditfield(app.Parameters1, 'numeric');
            app.Tab1_EditField_8 = uieditfield(app.Parameters1, 'numeric');
            app.Tab1_EditField_7 = uieditfield(app.Parameters1, 'numeric');
            app.Tab1_EditField_6 = uieditfield(app.Parameters1, 'numeric');
            app.Tab1_EditField_5 = uieditfield(app.Parameters1, 'numeric');
            app.LeffValueLabel_6 = uilabel(app.Parameters1);
            app.LeffValueLabel_6.Text = 'Beam Waist (w0)';
            app.Tab1_EditField_4 = uieditfield(app.Parameters1, 'numeric');
            app.Tab1_EditField_3 = uieditfield(app.Parameters1, 'numeric');
            app.LeffValueLabel_8 = uilabel(app.Parameters1);
            app.LeffValueLabel_8.Text = 'Wavelength of laser (λ)';
            app.LeffValueLabel_9 = uilabel(app.Parameters1);
            app.LeffValueLabel_9.Text = 'Linear absorption coefficient (α)';
            app.LeffValueLabel_10 = uilabel(app.Parameters1);
            app.LeffValueLabel_10.Text = 'Thickness of the material (L)';
            app.Tab1_EditField_2 = uieditfield(app.Parameters1, 'numeric');
            app.Tab1_EditField_1 = uieditfield(app.Parameters1, 'numeric');
            app.UnitsoftheparametersListBoxLabel_6 = uilabel(app.Parameters1);
            app.UnitsoftheparametersListBoxLabel_6.Text = 'Units of the parameters :';
            app.Tab1_ListBox = uilistbox(app.Parameters1);
            app.Tab1_ListBox.Items = {'millimeter', 'centimeter', 'meter'};
            app.Tab1_ListBox.ValueChangedFcn = createCallbackFcn(app, @OutputListBoxValueChanged, true);
            app.Tab1_ListBox.Value = 'millimeter';
            app.TabOutput2 = uitab(app.TabGroup3);
            app.TabOutput2.Title = 'Tab2';
            app.TabOutput2.Tag = 'OutputTab2';
            app.Parameters2 = uigridlayout(app.TabOutput2);
            app.LeffValueLabel_11 = uilabel(app.Parameters2);
            app.LeffValueLabel_11.Text = 'Effective Length of the Sample (Leff)';
            app.NonlinearabsorptioncoefficientLabel_7 = uilabel(app.Parameters2);
            app.NonlinearabsorptioncoefficientLabel_7.Text = 'Nonlinear absorption coefficient (ß)';
            app.IntensityoftheincidentlaserbeamLabel_6 = uilabel(app.Parameters2);
            app.IntensityoftheincidentlaserbeamLabel_6.Text = 'Intensity of the incident laser beam (i)';
            app.RayleighlengthLabel_6 = uilabel(app.Parameters2);
            app.RayleighlengthLabel_6.Text = 'Rayleigh length (Z)';
            app.ReducedChiSqLabel_6 = uilabel(app.Parameters2);
            app.ReducedChiSqLabel_6.Text = 'Reduced Chi-Square';
            app.RSquareCODLabel_6 = uilabel(app.Parameters2);
            app.RSquareCODLabel_6.Text = 'R-Square (COD)';
            app.AdjRSquareLabel_6 = uilabel(app.Parameters2);
            app.AdjRSquareLabel_6.Text = 'Adjusted R-Square';
            app.Tab2_EditField_12 = uieditfield(app.Parameters2, 'numeric');
            app.Tab2_EditField_11 = uieditfield(app.Parameters2, 'numeric');
            app.Tab2_EditField_10 = uieditfield(app.Parameters2, 'numeric');
            app.NonlinearabsorptioncoefficientLabel_8 = uilabel(app.Parameters2);
            app.NonlinearabsorptioncoefficientLabel_8.Text = 'D';
            app.Tab2_EditField_9 = uieditfield(app.Parameters2, 'numeric');
            app.Tab2_EditField_8 = uieditfield(app.Parameters2, 'numeric');
            app.Tab2_EditField_7 = uieditfield(app.Parameters2, 'numeric');
            app.Tab2_EditField_6 = uieditfield(app.Parameters2, 'numeric');
            app.Tab2_EditField_5 = uieditfield(app.Parameters2, 'numeric');
            app.LeffValueLabel_12 = uilabel(app.Parameters2);
            app.LeffValueLabel_12.Text = 'Beam Waist (w0)';
            app.Tab2_EditField_4 = uieditfield(app.Parameters2, 'numeric');
            app.Tab2_EditField_3 = uieditfield(app.Parameters2, 'numeric');
            app.LeffValueLabel_13 = uilabel(app.Parameters2);
            app.LeffValueLabel_13.Text = 'Wavelength of laser (λ)';
            app.Tab2_EditField_2 = uieditfield(app.Parameters2, 'numeric');
            app.Tab2_EditField_1 = uieditfield(app.Parameters2, 'numeric');
            app.LeffValueLabel_14 = uilabel(app.Parameters2);
            app.LeffValueLabel_14.Text = 'Linear absorption coefficient (α)';
            app.LeffValueLabel_15 = uilabel(app.Parameters2);
            app.LeffValueLabel_15.Text = 'Thickness of the material (L)';
            app.UnitsoftheparametersListBoxLabel_7 = uilabel(app.Parameters2);
            app.UnitsoftheparametersListBoxLabel_7.Text = 'Units of the parameters :';
            app.Tab2_ListBox = uilistbox(app.Parameters2);
            app.Tab2_ListBox.Items = {'millimeter', 'centimeter', 'meter'};
            app.Tab2_ListBox.ValueChangedFcn = createCallbackFcn(app, @OutputListBoxValueChanged, true);
            app.Tab2_ListBox.Value = 'millimeter';
            app.TabOutput3 = uitab(app.TabGroup3);
            app.TabOutput3.Title = 'Tab3';
            app.TabOutput3.Tag = 'OutputTab3';
            app.Parameters3 = uigridlayout(app.TabOutput3);
            app.LeffValueLabel_16 = uilabel(app.Parameters3);
            app.LeffValueLabel_16.Text = 'Effective Length of the Sample (Leff)';
            app.NonlinearabsorptioncoefficientLabel_9 = uilabel(app.Parameters3);
            app.NonlinearabsorptioncoefficientLabel_9.Text = 'Nonlinear absorption coefficient (ß)';
            app.IntensityoftheincidentlaserbeamLabel_7 = uilabel(app.Parameters3);
            app.IntensityoftheincidentlaserbeamLabel_7.Text = 'Intensity of the incident laser beam (i)';
            app.RayleighlengthLabel_7 = uilabel(app.Parameters3);
            app.RayleighlengthLabel_7.Text = 'Rayleigh length (Z)';
            app.ReducedChiSqLabel_7 = uilabel(app.Parameters3);
            app.ReducedChiSqLabel_7.Text = 'Reduced Chi-Square';
            app.RSquareCODLabel_7 = uilabel(app.Parameters3);
            app.RSquareCODLabel_7.Text = 'R-Square (COD)';
            app.AdjRSquareLabel_7 = uilabel(app.Parameters3);
            app.AdjRSquareLabel_7.Text = 'Adjusted R-Square';
            app.Tab3_EditField_12 = uieditfield(app.Parameters3, 'numeric');
            app.Tab3_EditField_11 = uieditfield(app.Parameters3, 'numeric');
            app.Tab3_EditField_10 = uieditfield(app.Parameters3, 'numeric');
            app.NonlinearabsorptioncoefficientLabel_10 = uilabel(app.Parameters3);
            app.NonlinearabsorptioncoefficientLabel_10.Text = 'D';
            app.Tab3_EditField_9 = uieditfield(app.Parameters3, 'numeric');
            app.Tab3_EditField_8 = uieditfield(app.Parameters3, 'numeric');
            app.Tab3_EditField_7 = uieditfield(app.Parameters3, 'numeric');
            app.Tab3_EditField_6 = uieditfield(app.Parameters3, 'numeric');
            app.Tab3_EditField_5 = uieditfield(app.Parameters3, 'numeric');
            app.LeffValueLabel_17 = uilabel(app.Parameters3);
            app.LeffValueLabel_17.Text = 'Beam Waist (w0)';
            app.Tab3_EditField_4 = uieditfield(app.Parameters3, 'numeric');
            app.Tab3_EditField_3 = uieditfield(app.Parameters3, 'numeric');
            app.LeffValueLabel_18 = uilabel(app.Parameters3);
            app.LeffValueLabel_18.Text = 'Thickness of the material (L)';
            app.LeffValueLabel_19 = uilabel(app.Parameters3);
            app.LeffValueLabel_19.Text = 'Wavelength of laser (λ)';
            app.Tab3_EditField_2 = uieditfield(app.Parameters3, 'numeric');
            app.LeffValueLabel_20 = uilabel(app.Parameters3);
            app.LeffValueLabel_20.Text = 'Linear absorption coefficient (α)';
            app.Tab3_EditField_1 = uieditfield(app.Parameters3, 'numeric');
            app.UnitsoftheparametersListBoxLabel_8 = uilabel(app.Parameters3);
            app.UnitsoftheparametersListBoxLabel_8.Text = 'Units of the parameters :';
            app.Tab3_ListBox = uilistbox(app.Parameters3);
            app.Tab3_ListBox.Items = {'millimeter', 'centimeter', 'meter'};
            app.Tab3_ListBox.ValueChangedFcn = createCallbackFcn(app, @OutputListBoxValueChanged, true);
            app.Tab3_ListBox.Value = 'millimeter';
            app.TabOutput4 = uitab(app.TabGroup3);
            app.TabOutput4.Title = 'Tab4';
            app.TabOutput4.Tag = 'OutputTab4';
            app.Parameters4 = uigridlayout(app.TabOutput4);
            app.LeffValueLabel_21 = uilabel(app.Parameters4);
            app.LeffValueLabel_21.Text = 'Effective Length of the Sample (Leff)';
            app.NonlinearabsorptioncoefficientLabel_11 = uilabel(app.Parameters4);
            app.NonlinearabsorptioncoefficientLabel_11.Text = 'Nonlinear absorption coefficient (ß)';
            app.IntensityoftheincidentlaserbeamLabel_8 = uilabel(app.Parameters4);
            app.IntensityoftheincidentlaserbeamLabel_8.Text = 'Intensity of the incident laser beam (i)';
            app.RayleighlengthLabel_8 = uilabel(app.Parameters4);
            app.RayleighlengthLabel_8.Text = 'Rayleigh length (Z)';
            app.ReducedChiSqLabel_8 = uilabel(app.Parameters4);
            app.ReducedChiSqLabel_8.Text = 'Reduced Chi-Square';
            app.RSquareCODLabel_8 = uilabel(app.Parameters4);
            app.RSquareCODLabel_8.Text = 'R-Square (COD)';
            app.Tab4_EditField_12 = uieditfield(app.Parameters4, 'numeric');
            app.Tab4_EditField_11 = uieditfield(app.Parameters4, 'numeric');
            app.AdjRSquareLabel_8 = uilabel(app.Parameters4);
            app.AdjRSquareLabel_8.Text = 'Adjusted R-Square';
            app.Tab4_EditField_10 = uieditfield(app.Parameters4, 'numeric');
            app.NonlinearabsorptioncoefficientLabel_12 = uilabel(app.Parameters4);
            app.NonlinearabsorptioncoefficientLabel_12.Text = 'D';
            app.Tab4_EditField_9 = uieditfield(app.Parameters4, 'numeric');
            app.Tab4_EditField_8 = uieditfield(app.Parameters4, 'numeric');
            app.Tab4_EditField_7 = uieditfield(app.Parameters4, 'numeric');
            app.Tab4_EditField_6 = uieditfield(app.Parameters4, 'numeric');
            app.Tab4_EditField_5 = uieditfield(app.Parameters4, 'numeric');
            app.LeffValueLabel_22 = uilabel(app.Parameters4);
            app.LeffValueLabel_22.Text = 'Beam Waist (w0)';
            app.Tab4_EditField_4 = uieditfield(app.Parameters4, 'numeric');
            app.Tab4_EditField_3 = uieditfield(app.Parameters4, 'numeric');
            app.LeffValueLabel_23 = uilabel(app.Parameters4);
            app.LeffValueLabel_23.Text = 'Thickness of the material (L)';
            app.LeffValueLabel_24 = uilabel(app.Parameters4);
            app.LeffValueLabel_24.Text = 'Wavelength of laser (λ)';
            app.Tab4_EditField_2 = uieditfield(app.Parameters4, 'numeric');
            app.Tab4_EditField_1 = uieditfield(app.Parameters4, 'numeric');
            app.LeffValueLabel_25 = uilabel(app.Parameters4);
            app.LeffValueLabel_25.Text = 'Linear absorption coefficient (α)';
            app.UnitsoftheparametersListBoxLabel_9 = uilabel(app.Parameters4);
            app.UnitsoftheparametersListBoxLabel_9.Text = 'Units of the parameters :';
            app.Tab4_ListBox = uilistbox(app.Parameters4);
            app.Tab4_ListBox.Items = {'millimeter', 'centimeter', 'meter'};
            app.Tab4_ListBox.ValueChangedFcn = createCallbackFcn(app, @OutputListBoxValueChanged, true);
            app.Tab4_ListBox.Value = 'millimeter';
            app.TabOutput5 = uitab(app.TabGroup3);
            app.TabOutput5.Title = 'Tab5';
            app.TabOutput5.Tag = 'OutputTab5';
            app.Parameters5 = uigridlayout(app.TabOutput5);
            app.LeffValueLabel_26 = uilabel(app.Parameters5);
            app.LeffValueLabel_26.Text = 'Effective Length of the Sample (Leff)';
            app.NonlinearabsorptioncoefficientLabel_13 = uilabel(app.Parameters5);
            app.NonlinearabsorptioncoefficientLabel_13.Text = 'Nonlinear absorption coefficient (ß)';
            app.IntensityoftheincidentlaserbeamLabel_9 = uilabel(app.Parameters5);
            app.IntensityoftheincidentlaserbeamLabel_9.Text = 'Intensity of the incident laser beam (i)';
            app.RayleighlengthLabel_9 = uilabel(app.Parameters5);
            app.RayleighlengthLabel_9.Text = 'Rayleigh length (Z)';
            app.ReducedChiSqLabel_9 = uilabel(app.Parameters5);
            app.ReducedChiSqLabel_9.Text = 'Reduced Chi-Square';
            app.Tab5_EditField_12 = uieditfield(app.Parameters5, 'numeric');
            app.Tab5_EditField_11 = uieditfield(app.Parameters5, 'numeric');
            app.Tab5_EditField_10 = uieditfield(app.Parameters5, 'numeric');
            app.RSquareCODLabel_9 = uilabel(app.Parameters5);
            app.RSquareCODLabel_9.Text = 'R-Square (COD)';
            app.AdjRSquareLabel_9 = uilabel(app.Parameters5);
            app.AdjRSquareLabel_9.Text = 'Adjusted R-Square';
            app.NonlinearabsorptioncoefficientLabel_14 = uilabel(app.Parameters5);
            app.NonlinearabsorptioncoefficientLabel_14.Text = 'D';
            app.Tab5_EditField_9 = uieditfield(app.Parameters5, 'numeric');
            app.Tab5_EditField_8 = uieditfield(app.Parameters5, 'numeric');
            app.Tab5_EditField_7 = uieditfield(app.Parameters5, 'numeric');
            app.Tab5_EditField_6 = uieditfield(app.Parameters5, 'numeric');
            app.Tab5_EditField_5 = uieditfield(app.Parameters5, 'numeric');
            app.LeffValueLabel_27 = uilabel(app.Parameters5);
            app.LeffValueLabel_27.Text = 'Beam Waist (w0)';
            app.Tab5_EditField_4 = uieditfield(app.Parameters5, 'numeric');
            app.Tab5_EditField_3 = uieditfield(app.Parameters5, 'numeric');
            app.LeffValueLabel_28 = uilabel(app.Parameters5);
            app.LeffValueLabel_28.Text = 'Thickness of the material (L)';
            app.LeffValueLabel_29 = uilabel(app.Parameters5);
            app.LeffValueLabel_29.Text = 'Wavelength of laser (λ)';
            app.Tab5_EditField_2 = uieditfield(app.Parameters5, 'numeric');
            app.LeffValueLabel_30 = uilabel(app.Parameters5);
            app.LeffValueLabel_30.Text = 'Linear absorption coefficient (α)';
            app.Tab5_EditField_1 = uieditfield(app.Parameters5, 'numeric');
            app.UnitsoftheparametersListBoxLabel_10 = uilabel(app.Parameters5);
            app.UnitsoftheparametersListBoxLabel_10.Text = 'Units of the parameters :';
            app.Tab5_ListBox = uilistbox(app.Parameters5);
            app.Tab5_ListBox.Items = {'millimeter', 'centimeter', 'meter'};
            app.Tab5_ListBox.ValueChangedFcn = createCallbackFcn(app, @OutputListBoxValueChanged, true);
            app.Tab5_ListBox.Value = 'millimeter';
            app.AllDataExport = uibutton(app.GridLayout2, 'push');
            app.AllDataExport.ButtonPushedFcn = createCallbackFcn(app, @AllDataExportButtonPushed, true);
            app.AllDataExport.Icon = fullfile(pathToMLAPP, 'icons', 'export.png');
            app.AllDataExport.Text = 'Data Export';
            app.ContextMenuInput1 = uicontextmenu(app.NonlinearOpticalZScanDataAnalysisUIFigure);
            app.GraphLabelsMenu = uimenu(app.ContextMenuInput1);
            app.GraphLabelsMenu.Text = 'Graph Labels';
            app.XLabelEditMenu = uimenu(app.GraphLabelsMenu);
            app.XLabelEditMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelEditMenuSelected, true);
            app.XLabelEditMenu.Text = 'X Label Edit';
            app.YLabelEditMenu = uimenu(app.GraphLabelsMenu);
            app.YLabelEditMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelEditMenuSelected, true);
            app.YLabelEditMenu.Text = 'Y Label Edit';
            app.TitleEditMenu = uimenu(app.GraphLabelsMenu);
            app.TitleEditMenu.MenuSelectedFcn = createCallbackFcn(app, @TitleEditMenuSelected, true);
            app.TitleEditMenu.Text = 'Title Edit';
            % Assign app.ContextMenuInput1
            app.graphEdited_1.ContextMenu = app.ContextMenuInput1;
            app.graphEdited_2.ContextMenu = app.ContextMenuInput1;
            app.graphEdited_3.ContextMenu = app.ContextMenuInput1;
            app.graphEdited_4.ContextMenu = app.ContextMenuInput1;
            app.graphEdited_5.ContextMenu = app.ContextMenuInput1;
            app.ContextMenuOutputs = uicontextmenu(app.NonlinearOpticalZScanDataAnalysisUIFigure);
            app.GraphLabelsMenuOutputs = uimenu(app.ContextMenuOutputs);
            app.GraphLabelsMenuOutputs.Text = 'Graph Labels';
            app.XLabelEditMenuOutputs = uimenu(app.GraphLabelsMenuOutputs);
            app.XLabelEditMenuOutputs.MenuSelectedFcn = createCallbackFcn(app, @XLabelEditMenuOutputsSelected, true);
            app.XLabelEditMenuOutputs.Text = 'X Label Edit';
            app.YLabelEditMenuOutputs = uimenu(app.GraphLabelsMenuOutputs);
            app.YLabelEditMenuOutputs.MenuSelectedFcn = createCallbackFcn(app, @YLabelEditMenuOutputsSelected, true);
            app.YLabelEditMenuOutputs.Text = 'Y Label Edit';
            app.TitleEditMenuOutputs = uimenu(app.GraphLabelsMenuOutputs);
            app.TitleEditMenuOutputs.MenuSelectedFcn = createCallbackFcn(app, @TitleEditMenuOutputsSelected, true);
            app.TitleEditMenuOutputs.Text = 'Title Edit';
            % Assign app.ContextMenuOutputs
            app.graphOverall.ContextMenu = app.ContextMenuOutputs;
            % Show the figure after all components are created
            app.NonlinearOpticalZScanDataAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MuhammedAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NonlinearOpticalZScanDataAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NonlinearOpticalZScanDataAnalysisUIFigure)
        end
    end
end